classdef Typhoon_app_v02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Typhoon                    matlab.ui.Figure
        GeometryMenu               matlab.ui.container.Menu
        NewgeometryMenu            matlab.ui.container.Menu
        LoadgeometryMenu           matlab.ui.container.Menu
        EditgeometryMenu           matlab.ui.container.Menu
        StateMenu                  matlab.ui.container.Menu
        NewstateMenu               matlab.ui.container.Menu
        LoadstateMenu              matlab.ui.container.Menu
        EditstateMenu              matlab.ui.container.Menu
        MeshMenu                   matlab.ui.container.Menu
        GeneratemeshMenu           matlab.ui.container.Menu
        CalculateMenu              matlab.ui.container.Menu
        SavecalculationMenu        matlab.ui.container.Menu
        OpencalculationMenu        matlab.ui.container.Menu
        StaticcalculationMenu      matlab.ui.container.Menu
        StaticequilibriumNewtonRhapsonMenu  matlab.ui.container.Menu
        LongitudinalstabilityanalysisMenu  matlab.ui.container.Menu
        geometryPanel              matlab.ui.container.Panel
        NomeshgeneratedNotreadyforcalculationLabel  matlab.ui.control.Label
        ResultsPanel               matlab.ui.container.Panel
        EditField                  matlab.ui.control.NumericEditField
        ForcesNLabel               matlab.ui.control.Label
        XLabel                     matlab.ui.control.Label
        EditField_2                matlab.ui.control.NumericEditField
        YLabel                     matlab.ui.control.Label
        EditField_3                matlab.ui.control.NumericEditField
        ZLabel                     matlab.ui.control.Label
        EditField_4                matlab.ui.control.NumericEditField
        MomentsNmLabel             matlab.ui.control.Label
        EditField_5                matlab.ui.control.NumericEditField
        EditField_6                matlab.ui.control.NumericEditField
        C_LEditFieldLabel          matlab.ui.control.Label
        C_LEditField               matlab.ui.control.NumericEditField
        C_DEditFieldLabel          matlab.ui.control.Label
        C_DEditField               matlab.ui.control.NumericEditField
        CalculationPanel           matlab.ui.container.Panel
        NocalculationstartedLabel  matlab.ui.control.Label
        GeometryPanel              matlab.ui.container.Panel
        NogeometryloadedLabel      matlab.ui.control.Label
        StatePanel                 matlab.ui.container.Panel
        NostateloadedLabel         matlab.ui.control.Label
        trimdegEditFieldLabel      matlab.ui.control.Label
        trimdegEditField           matlab.ui.control.NumericEditField
        elavationmEditFieldLabel   matlab.ui.control.Label
        elavationmEditField        matlab.ui.control.NumericEditField
        STWmsEditFieldLabel        matlab.ui.control.Label
        STWmsEditField             matlab.ui.control.NumericEditField
        StabilitymatrixPanel       matlab.ui.container.Panel
        FreesurfacemethodThiartCheckBox  matlab.ui.control.CheckBox
        Label                      matlab.ui.control.Label
        wwwtyphoonugentbeLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        GeometryApp;
        geo = [];
        state = [];
        ref = [];
        lattice = [];
        settings = [];% Description
        results = [];
        stability = [];
    end


    methods (Access = public)
        
        function results = initialize(app)
             %% geometry values
   app.geo.fnx=0;				    %number of panels on flap chords (2d array)
   app.geo.ny=1;					%number of panels in span (2d array)
   app.geo.nx=1;					%number of panels on chord (2d array)
   app.geo.fsym=0;				    %flap deflection symmetry boolean bit  (2d array)
   app.geo.fc=0;					%flap chord in percent of wingchord (2d array)
   app.geo.flapped=[0];			    %flapped partition(wing part) boolean bit (2d array)
   app.geo.TW(:,:,1)=0;	    	%partition twist (3d array)<1 inboard, 2 outboard>
   app.geo.TW(:,:,2)=0;
   app.geo.foil(:,:,1)={'0'};		    %Partition airfoils (3d array)
   app.geo.foil(:,:,2)={'0'};		    %1 inboard, 2 outboard
   app.geo.T=1;					%Taper ratio (2d array)
   app.geo.SW=0;					%Sweep (2d array)
   app.geo.c=1;					%Root chord (2d array)
   app.geo.dihed=0;				%Dihedral (2d array)
   %app.geo.sub=0;					%parameter that contains value of -1 (below), 0 (partly below), +1(above)
   
   
   app.geo.curved=0;               %boolean value if partition is curved
   app.geo.curved(1,1)=0;
   app.geo.radius=0;               %curvature radius of partition
   
   app.geo.foiltheta=0;            %AoA of a particular foil


   app.geo.b=1;					%span(distance root->tip chord) (2d array)
   app.geo.symetric=0;			    %Wing symmetry boolean bit (2d array)
   app.geo.startx=0;		    	%Partition starting coordinate (2d array)
   app.geo.starty=0;		    	% ---"----
   app.geo.startz=0;		    	% ---"----
   app.geo.nwing=2;				%number of wings (scalar)
   app.geo.nelem=1;				%number of partitions on each wing (1d array)
   app.geo.flap_vector=0;          %Flap deflection vector
   app.geo.ref_point=[0 0 0];      %System reference point
   app.geo.CG=[0 0 0];             %System center of gravity (around which all rotations occur)
   
   app.geo.mass=0;
   app.geo.I=[0 0 0; 0 0 0; 0 0 0];
   
   
   %% state values
   app.state.STW=0;                %Speed Trough Water
   
   app.state.U=0;					%these variables are created for the stability derivatives
   app.state.V=0;
   app.state.W=0;
   
   app.state.theta=0;				%angle of attack [pitch angle of craft]
   app.state.psi=0;				%angle of sideslip
   app.state.phi=0;                %angle of roll (heel)
   
   app.state.P=0;					%roll angluar rate
   app.state.Q=0;					%pitch angular rate
   app.state.R=0;					%Yaw angular rate
   
   app.state.pdot=0;           %Angle of attack time derivative
   app.state.qdot=0;           %Angle of sidesliptime derivative
   app.state.rdot=0;			%Angle of roll derivative
   
   app.state.ELA=0;                %elavation, meters.
   app.state.rho=997;              %Water density, kg/m^3.
   
   app.state.pgcorr=0;             %Prandtl-Glauert compressibillity correction.
   
   app.state.wavemethod = 0;
   
   app.lattice.XYZ=0;				%panel corner matrix (2d array)
   app.lattice.COLLOC=0;           %collocation point matrix
   app.lattice.VORTEX=0;           %Vortex sling cornerpoint position matrix
   app.lattice.N=0;                %Airfoil collocation point normal direction matrix
   
   
   %% reference values
   
   app.ref.S_ref=0;                %reference area;
   app.ref.C_mac=0;                %mean aerodynamic choord
   app.ref.mac_pos=0;              %start position of mac
   app.ref.C_mgc=0;                %mean geometric chord
   app.ref.b_ref=0;                %reference span
   
   results.dwcond=0;           %computation result memory structure.
   
   %%
   
   loop=1;                     %program run bit
   
   stat=0;                     %Status variable, 0 if geometry and lattice is inconsistent
   
   app.stability = [];
   
   
   st = 'startup';
   app.settings=config(st);
        end
        
        function result = update_geo(app,geo)
            app.geo = geo;
        end
    
        function result = update_state(app,state)
            app.state = state;
            
           app.trimdegEditField.Value = app.state.theta*180/pi;
           app.elavationmEditField.Value = app.state.ELA;
           app.STWmsEditField.Value = app.state.STW;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             initialize(app);
             
            
        end

        % Menu selected function: NewgeometryMenu
        function NewgeometryMenuSelected(app, event)
           
            geometry_def3(app,app.geo,app.state,app.lattice,app.ref,app.settings);
            app.NogeometryloadedLabel.Text = 'New geometry created.';
        end

        % Menu selected function: LoadgeometryMenu
        function LoadgeometryMenuSelected(app, event)
            
            cd(app.settings.acdir)
            fileName = uigetfile;
            load(fileName,'geo')
            update_geo(app,geo)
            cd(app.settings.hdir)
            app.NogeometryloadedLabel.Text = strcat('Geometry loaded:',fileName);
        end

        % Menu selected function: EditgeometryMenu
        function EditgeometryMenuSelected(app, event)
            geometry_edit3(app, app.geo, app.state, app.lattice, app.ref, app.settings);
        end

        % Menu selected function: GeneratemeshMenu
        function GeneratemeshMenuSelected(app, event)
   [lattice,ref,geo] = fLattice_setup2(app.geo,app.state,0);
   
   try
       a=app.geometryPanel.Children;
       a.delete;
   catch
       
   end
   
   app.lattice = lattice;
   app.ref= ref;
   app.geo = geo;
   [h1,h2,h3]=geometryplot_app(app.lattice,app.geo,app.ref,app.state,1);
   
   ax_to_copy = h1.Children;  % works even with subplots!
   
   % and copy the loaded axes to the uipanel:
   copyobj(ax_to_copy, app.geometryPanel);
   
   app.NomeshgeneratedNotreadyforcalculationLabel = uilabel(app.geometryPanel);
            app.NomeshgeneratedNotreadyforcalculationLabel.Position = [12 623 252 22];
            app.NomeshgeneratedNotreadyforcalculationLabel.Text = 'mesh generated.';
   
        end

        % Menu selected function: NewstateMenu
        function NewstateMenuSelected(app, event)
            state_def2(app,app.geo,app.state,app.lattice,app.ref,app.settings);
            app.NostateloadedLabel.Text = 'New state created.'
            
            
        end

        % Menu selected function: LoadstateMenu
        function LoadstateMenuSelected(app, event)
            cd(app.settings.sdir)
            fileName = uigetfile;
            load(fileName,'state')
            update_state(app, state);
            cd(app.settings.hdir)
            app.NostateloadedLabel.Text = strcat('State loaded:',fileName);


            %this update works
        end

        % Menu selected function: EditstateMenu
        function EditstateMenuSelected(app, event)
            state_edit(app, app.geo, app.state, app.lattice, app.ref, app.settings);
            
            app.trimdegEditField.Value = app.state.theta*180/pi;
            app.elavationmEditField.Value = app.state.ELA;
        end

        % Menu selected function: StaticcalculationMenu
        function StaticcalculationMenuSelected(app, event)
            app.NocalculationstartedLabel.Text = 'Calculation busy. This can take a couple of minutes.';
            
            f = waitbar(0,'Please wait... This can take a couple of minutes');
            
            
            try
            [results1,~]=solver10(app.results,app.state,app.geo,app.lattice,app.ref);
            [app.results]=coeff_create3(results1,app.lattice,app.state,app.ref,app.geo); 
            
            FOR = app.results.FORCE;
            MOM = app.results.MOMENTS;
           
            
            app.EditField.Value = FOR(1);
            app.EditField_2.Value = FOR(2);
            app.EditField_3.Value = FOR(3);
            
            app.EditField_4.Value = MOM(1);
            app.EditField_5.Value = MOM(2);
            app.EditField_6.Value = MOM(3);
            
            app.C_DEditField.Value = app.results.CD;
            app.C_LEditField.Value = app.results.CL;
            
            app.NocalculationstartedLabel.Text = 'Calculation completed.';
            catch
                app.NocalculationstartedLabel.Text = 'Calculation FAILED. please check if the mesh was generated correctly';
            end


            close(f);
        end

        % Menu selected function: SavecalculationMenu
        function SavecalculationMenuSelected(app, event)
            
  cd(app.settings.odir)
  uisave({'app.results','app.geo','app.lattice','app.state','app.ref'},'calc1')
  cd(app.settings.hdir)
  
  
        end

        % Menu selected function: OpencalculationMenu
        function OpencalculationMenuSelected(app, event)
            cd(app.settings.odir)
            uiopen
           
            cd(app.settings.hdir)
        end

        % Menu selected function: StaticequilibriumNewtonRhapsonMenu
        function StaticequilibriumNewtonRhapsonMenuSelected(app, event)
            app.NocalculationstartedLabel.Text = 'Calculation busy. This can take a couple of minutes.'
            
            f = waitbar(0,'Calculating static equilibrium... This can take a couple of minutes');
            
            try
            [app.results,app.lattice]=equilibrium1(app.results,app.state,app.geo,app.lattice,app.ref);
            
            
            app.NostateloadedLabel.Text = 'Current state is at an approximate static equilibrium. (To store this state, save via edit state)';
            app.state.theta = app.results.stat_eq(1);
            app.state.ELA = app.results.stat_eq(2);
            app.trimdegEditField.Value = app.state.theta*180/pi;
            app.elavationmEditField.Value = app.state.ELA;
            
            app.NocalculationstartedLabel.Text = 'Calculation completed.';
            
            close(f);
            
            GeneratemeshMenuSelected(app, event);
            
            FOR = app.results.FORCE;
            MOM = app.results.MOMENTS;
            MOMs = app.state.Ms;
            
            app.EditField.Value = FOR(1);
            app.EditField_2.Value = FOR(2);
            app.EditField_3.Value = FOR(3) - app.geo.mass*9.81;
            
            app.EditField_4.Value = MOM(1) + MOMs(1,1);
            app.EditField_5.Value = MOM(2) + MOMs(2,1);
            app.EditField_6.Value = MOM(3) + MOMs(3,1);
            catch
               app.NocalculationstartedLabel.Text = 'Calculation FAILED';
            end
            
            
        end

        % Menu selected function: LongitudinalstabilityanalysisMenu
        function LongitudinalstabilityanalysisMenuSelected(app, event)
            app.NocalculationstartedLabel.Text = 'Calculation busy. This can take a couple of minutes.'
            
            f = waitbar(0,'Calculating stability matrix... This can take a couple of minutes');
            
            try
            [app.results,app.stability] = LongStab1(app.results,'1',app.lattice,app.state,app.geo,app.ref);
            EigenVisual(app.stability);
            
            figure(1)
            uitable(app.StabilitymatrixPanel,'Data',app.stability.StabAlec)
            
            app.NocalculationstartedLabel.Text = 'Calculation completed.'
            close(f);
            catch 
                app.NocalculationstartedLabel.Text = 'Calculation FAILED. Please make sure to run Typhoon as administrator!!'
                close(f);
            end
        end

        % Value changed function: FreesurfacemethodThiartCheckBox
        function FreesurfacemethodThiartCheckBoxValueChanged(app, event)
            value = app.FreesurfacemethodThiartCheckBox.Value;
            app.state.wavemethod = value;
        end

        % Close request function: Typhoon
        function TyphoonCloseRequest(app, event)
            delete(app);
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Typhoon = uifigure;
            app.Typhoon.Name = 'Typhoon';
            app.Typhoon.CloseRequestFcn = createCallbackFcn(app, @TyphoonCloseRequest, true);
            app.GeometryMenu = uimenu(app.Typhoon);
            app.GeometryMenu.Text = 'Geometry';
            app.NewgeometryMenu = uimenu(app.GeometryMenu);
            app.NewgeometryMenu.MenuSelectedFcn = createCallbackFcn(app, @NewgeometryMenuSelected, true);
            app.NewgeometryMenu.Tooltip = {'Create a new geometry from scratch. This will replace the current geometry.'};
            app.NewgeometryMenu.Text = 'New geometry';
            app.LoadgeometryMenu = uimenu(app.GeometryMenu);
            app.LoadgeometryMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadgeometryMenuSelected, true);
            app.LoadgeometryMenu.Tooltip = {'Load an existing geometry. Will replace any current geometry.'};
            app.LoadgeometryMenu.Text = 'Load geometry';
            app.EditgeometryMenu = uimenu(app.GeometryMenu);
            app.EditgeometryMenu.MenuSelectedFcn = createCallbackFcn(app, @EditgeometryMenuSelected, true);
            app.EditgeometryMenu.Tooltip = {'Edit the geometry which is currently loaded.'};
            app.EditgeometryMenu.Text = 'Edit geometry';
            app.StateMenu = uimenu(app.Typhoon);
            app.StateMenu.Text = 'State';
            app.NewstateMenu = uimenu(app.StateMenu);
            app.NewstateMenu.MenuSelectedFcn = createCallbackFcn(app, @NewstateMenuSelected, true);
            app.NewstateMenu.Tooltip = {'Create a new state.'};
            app.NewstateMenu.Text = 'New state';
            app.LoadstateMenu = uimenu(app.StateMenu);
            app.LoadstateMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadstateMenuSelected, true);
            app.LoadstateMenu.Tooltip = {'Load an existing state.'};
            app.LoadstateMenu.Text = 'Load state';
            app.EditstateMenu = uimenu(app.StateMenu);
            app.EditstateMenu.MenuSelectedFcn = createCallbackFcn(app, @EditstateMenuSelected, true);
            app.EditstateMenu.Tooltip = {'Edit the state which is currently loaded.'};
            app.EditstateMenu.Text = 'Edit state';
            app.MeshMenu = uimenu(app.Typhoon);
            app.MeshMenu.Text = 'Mesh';
            app.GeneratemeshMenu = uimenu(app.MeshMenu);
            app.GeneratemeshMenu.MenuSelectedFcn = createCallbackFcn(app, @GeneratemeshMenuSelected, true);
            app.GeneratemeshMenu.Text = 'Generate mesh';
            app.CalculateMenu = uimenu(app.Typhoon);
            app.CalculateMenu.Text = 'Calculate';
            app.SavecalculationMenu = uimenu(app.CalculateMenu);
            app.SavecalculationMenu.MenuSelectedFcn = createCallbackFcn(app, @SavecalculationMenuSelected, true);
            app.SavecalculationMenu.Text = 'Save calculation';
            app.OpencalculationMenu = uimenu(app.CalculateMenu);
            app.OpencalculationMenu.MenuSelectedFcn = createCallbackFcn(app, @OpencalculationMenuSelected, true);
            app.OpencalculationMenu.Text = 'Open calculation';
            app.StaticcalculationMenu = uimenu(app.CalculateMenu);
            app.StaticcalculationMenu.MenuSelectedFcn = createCallbackFcn(app, @StaticcalculationMenuSelected, true);
            app.StaticcalculationMenu.Tooltip = {'Force and moment calculation in the current state. (displayed values do not take mass and external moment into account)'};
            app.StaticcalculationMenu.Separator = 'on';
            app.StaticcalculationMenu.Text = 'Static calculation';
            app.StaticequilibriumNewtonRhapsonMenu = uimenu(app.CalculateMenu);
            app.StaticequilibriumNewtonRhapsonMenu.MenuSelectedFcn = createCallbackFcn(app, @StaticequilibriumNewtonRhapsonMenuSelected, true);
            app.StaticequilibriumNewtonRhapsonMenu.Tooltip = {'This calculation will change trim and elavation in an attempt to balance forces and moments with the mass and external moment.'};
            app.StaticequilibriumNewtonRhapsonMenu.Separator = 'on';
            app.StaticequilibriumNewtonRhapsonMenu.Text = 'Static equilibrium (Newton-Rhapson)';
            app.LongitudinalstabilityanalysisMenu = uimenu(app.CalculateMenu);
            app.LongitudinalstabilityanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @LongitudinalstabilityanalysisMenuSelected, true);
            app.LongitudinalstabilityanalysisMenu.Separator = 'on';
            app.LongitudinalstabilityanalysisMenu.Text = 'Longitudinal stability analysis';
            app.geometryPanel = uipanel(app.Typhoon);
            app.geometryPanel.Title = 'geometry';
            app.NomeshgeneratedNotreadyforcalculationLabel = uilabel(app.geometryPanel);
            app.NomeshgeneratedNotreadyforcalculationLabel.Text = 'No mesh generated. Not ready for calculation.';
            app.ResultsPanel = uipanel(app.Typhoon);
            app.ResultsPanel.Title = 'Results';
            app.EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.EditField.Editable = 'off';
            app.ForcesNLabel = uilabel(app.ResultsPanel);
            app.ForcesNLabel.Text = 'Forces: [N]';
            app.XLabel = uilabel(app.ResultsPanel);
            app.XLabel.Text = 'X';
            app.EditField_2 = uieditfield(app.ResultsPanel, 'numeric');
            app.EditField_2.Editable = 'off';
            app.YLabel = uilabel(app.ResultsPanel);
            app.YLabel.Text = 'Y';
            app.EditField_3 = uieditfield(app.ResultsPanel, 'numeric');
            app.EditField_3.Editable = 'off';
            app.ZLabel = uilabel(app.ResultsPanel);
            app.ZLabel.Text = 'Z';
            app.EditField_4 = uieditfield(app.ResultsPanel, 'numeric');
            app.EditField_4.Editable = 'off';
            app.MomentsNmLabel = uilabel(app.ResultsPanel);
            app.MomentsNmLabel.Text = 'Moments: [Nm]';
            app.EditField_5 = uieditfield(app.ResultsPanel, 'numeric');
            app.EditField_5.Editable = 'off';
            app.EditField_6 = uieditfield(app.ResultsPanel, 'numeric');
            app.EditField_6.Editable = 'off';
            app.C_LEditFieldLabel = uilabel(app.ResultsPanel);
            app.C_LEditFieldLabel.Text = 'C_L';
            app.C_LEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.C_LEditField.Editable = 'off';
            app.C_DEditFieldLabel = uilabel(app.ResultsPanel);
            app.C_DEditFieldLabel.Text = 'C_D';
            app.C_DEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.C_DEditField.Editable = 'off';
            app.CalculationPanel = uipanel(app.Typhoon);
            app.CalculationPanel.Title = 'Calculation';
            app.NocalculationstartedLabel = uilabel(app.CalculationPanel);
            app.NocalculationstartedLabel.Text = 'No calculation started';
            app.GeometryPanel = uipanel(app.Typhoon);
            app.GeometryPanel.Title = 'Geometry';
            app.NogeometryloadedLabel = uilabel(app.GeometryPanel);
            app.NogeometryloadedLabel.Text = 'No geometry loaded';
            app.StatePanel = uipanel(app.Typhoon);
            app.StatePanel.Title = 'State';
            app.NostateloadedLabel = uilabel(app.StatePanel);
            app.NostateloadedLabel.Text = 'No state loaded';
            app.trimdegEditFieldLabel = uilabel(app.StatePanel);
            app.trimdegEditFieldLabel.Text = 'trim [deg.]';
            app.trimdegEditField = uieditfield(app.StatePanel, 'numeric');
            app.trimdegEditField.Editable = 'off';
            app.trimdegEditField.Tooltip = {'this displays the trim angle of the geometry in the current state.'};
            app.elavationmEditFieldLabel = uilabel(app.StatePanel);
            app.elavationmEditFieldLabel.Text = 'elavation [m]';
            app.elavationmEditField = uieditfield(app.StatePanel, 'numeric');
            app.elavationmEditField.Editable = 'off';
            app.STWmsEditFieldLabel = uilabel(app.StatePanel);
            app.STWmsEditFieldLabel.Text = 'STW [m/s]';
            app.STWmsEditField = uieditfield(app.StatePanel, 'numeric');
            app.STWmsEditField.Editable = 'off';
            app.StabilitymatrixPanel = uipanel(app.Typhoon);
            app.StabilitymatrixPanel.Tooltip = {'This field will display the numeric values of the Longitudinal  dynamic stability analysis.'};
            app.StabilitymatrixPanel.Title = 'Stability matrix';
            app.FreesurfacemethodThiartCheckBox = uicheckbox(app.Typhoon);
            app.FreesurfacemethodThiartCheckBox.ValueChangedFcn = createCallbackFcn(app, @FreesurfacemethodThiartCheckBoxValueChanged, true);
            app.FreesurfacemethodThiartCheckBox.Tooltip = {'Chech this box to include the free-surface effect (submergence and speed dependency). This will adversely effect calculation time.'};
            app.FreesurfacemethodThiartCheckBox.Text = 'Free-surface method (Thiart)';
            app.Label = uilabel(app.Typhoon);
            app.Label.Text = 'Product by Ghent University - issued under a GNU Public License - For more information visit:';
            app.wwwtyphoonugentbeLabel = uilabel(app.Typhoon);
            app.wwwtyphoonugentbeLabel.Text = 'www.typhoon.ugent.be';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Typhoon_app_v02

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Typhoon)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Typhoon)
        end
    end
end