classdef geometry_edit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        edit_geometry                   matlab.ui.Figure
        nooffoilsDropDownLabel          matlab.ui.control.Label
        nooffoilsDropDown               matlab.ui.control.DropDown
        Foil1Panel                      matlab.ui.container.Panel
        MirroredCheckBox                matlab.ui.control.CheckBox
        partition1Panel                 matlab.ui.container.Panel
        RootchordEditFieldLabel         matlab.ui.control.Label
        RootchordEditField              matlab.ui.control.EditField
        BasechordairfoilEditFieldLabel  matlab.ui.control.Label
        BasechordairfoilEditField       matlab.ui.control.EditField
        noofpanelschordwiseEditFieldLabel  matlab.ui.control.Label
        noofpanelschordwiseEditField    matlab.ui.control.EditField
        dihedralEditFieldLabel          matlab.ui.control.Label
        dihedralEditField               matlab.ui.control.EditField
        pitchEditFieldLabel             matlab.ui.control.Label
        pitchEditField                  matlab.ui.control.EditField
        basechordtwistEditFieldLabel    matlab.ui.control.Label
        basechordtwistEditField         matlab.ui.control.EditField
        noofpanelssemispanwiseEditFieldLabel  matlab.ui.control.Label
        noofpanelssemispanwiseEditField  matlab.ui.control.EditField
        spanEditFieldLabel              matlab.ui.control.Label
        spanEditField                   matlab.ui.control.EditField
        taperEditFieldLabel             matlab.ui.control.Label
        taperEditField                  matlab.ui.control.EditField
        tipchordairfoilEditFieldLabel   matlab.ui.control.Label
        tipchordairfoilEditField        matlab.ui.control.EditField
        sweepEditFieldLabel             matlab.ui.control.Label
        sweepEditField                  matlab.ui.control.EditField
        outboardtwistEditFieldLabel     matlab.ui.control.Label
        outboardtwistEditField          matlab.ui.control.EditField
        meshtypeDropDownLabel           matlab.ui.control.Label
        meshtypeDropDown                matlab.ui.control.DropDown
        radiusofcurvatureEditFieldLabel  matlab.ui.control.Label
        radiusofcurvatureEditField      matlab.ui.control.EditField
        curvedDropDownLabel             matlab.ui.control.Label
        curvedDropDown                  matlab.ui.control.DropDown
        partition2Panel                 matlab.ui.container.Panel
        dihedralEditField_2Label        matlab.ui.control.Label
        dihedralEditField_2             matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_2Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_2  matlab.ui.control.EditField
        spanEditField_2Label            matlab.ui.control.Label
        spanEditField_2                 matlab.ui.control.EditField
        taperEditField_2Label           matlab.ui.control.Label
        taperEditField_2                matlab.ui.control.EditField
        tipchordairfoilEditField_2Label  matlab.ui.control.Label
        tipchordairfoilEditField_2      matlab.ui.control.EditField
        sweepEditField_2Label           matlab.ui.control.Label
        sweepEditField_2                matlab.ui.control.EditField
        outboardtwistEditField_2Label   matlab.ui.control.Label
        outboardtwistEditField_2        matlab.ui.control.EditField
        meshtypeDropDown_2Label         matlab.ui.control.Label
        meshtypeDropDown_2              matlab.ui.control.DropDown
        radiusofcurvatureEditField_2Label  matlab.ui.control.Label
        radiusofcurvatureEditField_2    matlab.ui.control.EditField
        curvedDropDown_2Label           matlab.ui.control.Label
        curvedDropDown_2                matlab.ui.control.DropDown
        partition3Panel                 matlab.ui.container.Panel
        dihedralEditField_3Label        matlab.ui.control.Label
        dihedralEditField_3             matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_3Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_3  matlab.ui.control.EditField
        spanEditField_3Label            matlab.ui.control.Label
        spanEditField_3                 matlab.ui.control.EditField
        taperEditField_3Label           matlab.ui.control.Label
        taperEditField_3                matlab.ui.control.EditField
        tipchordairfoilEditField_3Label  matlab.ui.control.Label
        tipchordairfoilEditField_3      matlab.ui.control.EditField
        sweepEditField_3Label           matlab.ui.control.Label
        sweepEditField_3                matlab.ui.control.EditField
        outboardtwistEditField_3Label   matlab.ui.control.Label
        outboardtwistEditField_3        matlab.ui.control.EditField
        meshtypeDropDown_3Label         matlab.ui.control.Label
        meshtypeDropDown_3              matlab.ui.control.DropDown
        radiusofcurvatureEditField_3Label  matlab.ui.control.Label
        radiusofcurvatureEditField_3    matlab.ui.control.EditField
        curvedDropDown_3Label           matlab.ui.control.Label
        curvedDropDown_3                matlab.ui.control.DropDown
        APEXxEditFieldLabel             matlab.ui.control.Label
        APEXxEditField                  matlab.ui.control.NumericEditField
        yEditField_3Label               matlab.ui.control.Label
        yEditField_3                    matlab.ui.control.NumericEditField
        zEditField_3Label               matlab.ui.control.Label
        zEditField_3                    matlab.ui.control.NumericEditField
        noofpartitionsDropDownLabel     matlab.ui.control.Label
        noofpartitionsDropDown          matlab.ui.control.DropDown
        massEditFieldLabel              matlab.ui.control.Label
        massEditField                   matlab.ui.control.EditField
        IxxEditFieldLabel               matlab.ui.control.Label
        IxxEditField                    matlab.ui.control.EditField
        IyxEditFieldLabel               matlab.ui.control.Label
        IyxEditField                    matlab.ui.control.EditField
        IzxEditFieldLabel               matlab.ui.control.Label
        IzxEditField                    matlab.ui.control.EditField
        IxyEditFieldLabel               matlab.ui.control.Label
        IxyEditField                    matlab.ui.control.EditField
        IyyEditFieldLabel               matlab.ui.control.Label
        IyyEditField                    matlab.ui.control.EditField
        IzyEditFieldLabel               matlab.ui.control.Label
        IzyEditField                    matlab.ui.control.EditField
        IxzEditFieldLabel               matlab.ui.control.Label
        IxzEditField                    matlab.ui.control.EditField
        IyzEditFieldLabel               matlab.ui.control.Label
        IyzEditField                    matlab.ui.control.EditField
        IzzEditFieldLabel               matlab.ui.control.Label
        IzzEditField                    matlab.ui.control.EditField
        COGxEditFieldLabel              matlab.ui.control.Label
        COGxEditField                   matlab.ui.control.EditField
        yEditField_4Label               matlab.ui.control.Label
        yEditField_4                    matlab.ui.control.EditField
        zEditField_4Label               matlab.ui.control.Label
        zEditField_4                    matlab.ui.control.EditField
        REFxEditFieldLabel              matlab.ui.control.Label
        REFxEditField                   matlab.ui.control.EditField
        yEditField_5Label               matlab.ui.control.Label
        yEditField_5                    matlab.ui.control.EditField
        zEditField_5Label               matlab.ui.control.Label
        zEditField_5                    matlab.ui.control.EditField
        Foil2Panel                      matlab.ui.container.Panel
        MirroredCheckBox_2              matlab.ui.control.CheckBox
        partition1Panel_2               matlab.ui.container.Panel
        RootchordEditField_4Label       matlab.ui.control.Label
        RootchordEditField_4            matlab.ui.control.EditField
        BasechordairfoilEditField_4Label  matlab.ui.control.Label
        BasechordairfoilEditField_4     matlab.ui.control.EditField
        noofpanelschordwiseEditField_4Label  matlab.ui.control.Label
        noofpanelschordwiseEditField_4  matlab.ui.control.EditField
        dihedralEditField_4Label        matlab.ui.control.Label
        dihedralEditField_4             matlab.ui.control.EditField
        pitchEditField_4Label           matlab.ui.control.Label
        pitchEditField_4                matlab.ui.control.EditField
        basechordtwistEditField_4Label  matlab.ui.control.Label
        basechordtwistEditField_4       matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_4Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_4  matlab.ui.control.EditField
        spanEditField_4Label            matlab.ui.control.Label
        spanEditField_4                 matlab.ui.control.EditField
        taperEditField_4Label           matlab.ui.control.Label
        taperEditField_4                matlab.ui.control.EditField
        tipchordairfoilEditField_4Label  matlab.ui.control.Label
        tipchordairfoilEditField_4      matlab.ui.control.EditField
        sweepEditField_4Label           matlab.ui.control.Label
        sweepEditField_4                matlab.ui.control.EditField
        outboardtwistEditField_4Label   matlab.ui.control.Label
        outboardtwistEditField_4        matlab.ui.control.EditField
        meshtypeDropDown_4Label         matlab.ui.control.Label
        meshtypeDropDown_4              matlab.ui.control.DropDown
        radiusofcurvatureEditField_4Label  matlab.ui.control.Label
        radiusofcurvatureEditField_4    matlab.ui.control.EditField
        curvedDropDown_4Label           matlab.ui.control.Label
        curvedDropDown_4                matlab.ui.control.DropDown
        partition2Panel_2               matlab.ui.container.Panel
        RootchordEditField_5Label       matlab.ui.control.Label
        RootchordEditField_5            matlab.ui.control.EditField
        BasechordairfoilEditField_5Label  matlab.ui.control.Label
        BasechordairfoilEditField_5     matlab.ui.control.EditField
        noofpanelschordwiseEditField_5Label  matlab.ui.control.Label
        noofpanelschordwiseEditField_5  matlab.ui.control.EditField
        dihedralEditField_5Label        matlab.ui.control.Label
        dihedralEditField_5             matlab.ui.control.EditField
        pitchEditField_5Label           matlab.ui.control.Label
        pitchEditField_5                matlab.ui.control.EditField
        basechordtwistEditField_5Label  matlab.ui.control.Label
        basechordtwistEditField_5       matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_5Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_5  matlab.ui.control.EditField
        spanEditField_5Label            matlab.ui.control.Label
        spanEditField_5                 matlab.ui.control.EditField
        taperEditField_5Label           matlab.ui.control.Label
        taperEditField_5                matlab.ui.control.EditField
        tipchordairfoilEditField_5Label  matlab.ui.control.Label
        tipchordairfoilEditField_5      matlab.ui.control.EditField
        sweepEditField_5Label           matlab.ui.control.Label
        sweepEditField_5                matlab.ui.control.EditField
        outboardtwistEditField_5Label   matlab.ui.control.Label
        outboardtwistEditField_5        matlab.ui.control.EditField
        meshtypeDropDown_5Label         matlab.ui.control.Label
        meshtypeDropDown_5              matlab.ui.control.DropDown
        radiusofcurvatureEditField_5Label  matlab.ui.control.Label
        radiusofcurvatureEditField_5    matlab.ui.control.EditField
        curvedDropDown_5Label           matlab.ui.control.Label
        curvedDropDown_5                matlab.ui.control.DropDown
        partition3Panel_2               matlab.ui.container.Panel
        RootchordEditField_6Label       matlab.ui.control.Label
        RootchordEditField_6            matlab.ui.control.EditField
        BasechordairfoilEditField_6Label  matlab.ui.control.Label
        BasechordairfoilEditField_6     matlab.ui.control.EditField
        noofpanelschordwiseEditField_6Label  matlab.ui.control.Label
        noofpanelschordwiseEditField_6  matlab.ui.control.EditField
        dihedralEditField_6Label        matlab.ui.control.Label
        dihedralEditField_6             matlab.ui.control.EditField
        pitchEditField_6Label           matlab.ui.control.Label
        pitchEditField_6                matlab.ui.control.EditField
        basechordtwistEditField_6Label  matlab.ui.control.Label
        basechordtwistEditField_6       matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_6Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_6  matlab.ui.control.EditField
        spanEditField_6Label            matlab.ui.control.Label
        spanEditField_6                 matlab.ui.control.EditField
        taperEditField_6Label           matlab.ui.control.Label
        taperEditField_6                matlab.ui.control.EditField
        tipchordairfoilEditField_6Label  matlab.ui.control.Label
        tipchordairfoilEditField_6      matlab.ui.control.EditField
        sweepEditField_6Label           matlab.ui.control.Label
        sweepEditField_6                matlab.ui.control.EditField
        outboardtwistEditField_6Label   matlab.ui.control.Label
        outboardtwistEditField_6        matlab.ui.control.EditField
        meshtypeDropDown_6Label         matlab.ui.control.Label
        meshtypeDropDown_6              matlab.ui.control.DropDown
        radiusofcurvatureEditField_6Label  matlab.ui.control.Label
        radiusofcurvatureEditField_6    matlab.ui.control.EditField
        curvedDropDown_6Label           matlab.ui.control.Label
        curvedDropDown_6                matlab.ui.control.DropDown
        APEXxEditField_2Label           matlab.ui.control.Label
        APEXxEditField_2                matlab.ui.control.NumericEditField
        yEditField_6Label               matlab.ui.control.Label
        yEditField_6                    matlab.ui.control.NumericEditField
        zEditField_6Label               matlab.ui.control.Label
        zEditField_6                    matlab.ui.control.NumericEditField
        noofpartitionsDropDown_2Label   matlab.ui.control.Label
        noofpartitionsDropDown_2        matlab.ui.control.DropDown
        Foil3Panel                      matlab.ui.container.Panel
        MirroredCheckBox_3              matlab.ui.control.CheckBox
        partition1Panel_3               matlab.ui.container.Panel
        RootchordEditField_7Label       matlab.ui.control.Label
        RootchordEditField_7            matlab.ui.control.EditField
        BasechordairfoilEditField_7Label  matlab.ui.control.Label
        BasechordairfoilEditField_7     matlab.ui.control.EditField
        noofpanelschordwiseEditField_7Label  matlab.ui.control.Label
        noofpanelschordwiseEditField_7  matlab.ui.control.EditField
        dihedralEditField_7Label        matlab.ui.control.Label
        dihedralEditField_7             matlab.ui.control.EditField
        pitchEditField_7Label           matlab.ui.control.Label
        pitchEditField_7                matlab.ui.control.EditField
        basechordtwistEditField_7Label  matlab.ui.control.Label
        basechordtwistEditField_7       matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_7Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_7  matlab.ui.control.EditField
        spanEditField_7Label            matlab.ui.control.Label
        spanEditField_7                 matlab.ui.control.EditField
        taperEditField_7Label           matlab.ui.control.Label
        taperEditField_7                matlab.ui.control.EditField
        tipchordairfoilEditField_7Label  matlab.ui.control.Label
        tipchordairfoilEditField_7      matlab.ui.control.EditField
        sweepEditField_7Label           matlab.ui.control.Label
        sweepEditField_7                matlab.ui.control.EditField
        outboardtwistEditField_7Label   matlab.ui.control.Label
        outboardtwistEditField_7        matlab.ui.control.EditField
        meshtypeDropDown_7Label         matlab.ui.control.Label
        meshtypeDropDown_7              matlab.ui.control.DropDown
        radiusofcurvatureEditField_7Label  matlab.ui.control.Label
        radiusofcurvatureEditField_7    matlab.ui.control.EditField
        curvedDropDown_7Label           matlab.ui.control.Label
        curvedDropDown_7                matlab.ui.control.DropDown
        partition2Panel_3               matlab.ui.container.Panel
        RootchordEditField_8Label       matlab.ui.control.Label
        RootchordEditField_8            matlab.ui.control.EditField
        BasechordairfoilEditField_8Label  matlab.ui.control.Label
        BasechordairfoilEditField_8     matlab.ui.control.EditField
        noofpanelschordwiseEditField_8Label  matlab.ui.control.Label
        noofpanelschordwiseEditField_8  matlab.ui.control.EditField
        dihedralEditField_8Label        matlab.ui.control.Label
        dihedralEditField_8             matlab.ui.control.EditField
        pitchEditField_8Label           matlab.ui.control.Label
        pitchEditField_8                matlab.ui.control.EditField
        basechordtwistEditField_8Label  matlab.ui.control.Label
        basechordtwistEditField_8       matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_8Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_8  matlab.ui.control.EditField
        spanEditField_8Label            matlab.ui.control.Label
        spanEditField_8                 matlab.ui.control.EditField
        taperEditField_8Label           matlab.ui.control.Label
        taperEditField_8                matlab.ui.control.EditField
        tipchordairfoilEditField_8Label  matlab.ui.control.Label
        tipchordairfoilEditField_8      matlab.ui.control.EditField
        sweepEditField_8Label           matlab.ui.control.Label
        sweepEditField_8                matlab.ui.control.EditField
        outboardtwistEditField_8Label   matlab.ui.control.Label
        outboardtwistEditField_8        matlab.ui.control.EditField
        meshtypeDropDown_8Label         matlab.ui.control.Label
        meshtypeDropDown_8              matlab.ui.control.DropDown
        radiusofcurvatureEditField_8Label  matlab.ui.control.Label
        radiusofcurvatureEditField_8    matlab.ui.control.EditField
        curvedDropDown_8Label           matlab.ui.control.Label
        curvedDropDown_8                matlab.ui.control.DropDown
        partition3Panel_3               matlab.ui.container.Panel
        RootchordEditField_9Label       matlab.ui.control.Label
        RootchordEditField_9            matlab.ui.control.EditField
        BasechordairfoilEditField_9Label  matlab.ui.control.Label
        BasechordairfoilEditField_9     matlab.ui.control.EditField
        noofpanelschordwiseEditField_9Label  matlab.ui.control.Label
        noofpanelschordwiseEditField_9  matlab.ui.control.EditField
        dihedralEditField_9Label        matlab.ui.control.Label
        dihedralEditField_9             matlab.ui.control.EditField
        pitchEditField_9Label           matlab.ui.control.Label
        pitchEditField_9                matlab.ui.control.EditField
        basechordtwistEditField_9Label  matlab.ui.control.Label
        basechordtwistEditField_9       matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_9Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_9  matlab.ui.control.EditField
        spanEditField_9Label            matlab.ui.control.Label
        spanEditField_9                 matlab.ui.control.EditField
        taperEditField_9Label           matlab.ui.control.Label
        taperEditField_9                matlab.ui.control.EditField
        tipchordairfoilEditField_9Label  matlab.ui.control.Label
        tipchordairfoilEditField_9      matlab.ui.control.EditField
        sweepEditField_9Label           matlab.ui.control.Label
        sweepEditField_9                matlab.ui.control.EditField
        outboardtwistEditField_9Label   matlab.ui.control.Label
        outboardtwistEditField_9        matlab.ui.control.EditField
        meshtypeDropDown_9Label         matlab.ui.control.Label
        meshtypeDropDown_9              matlab.ui.control.DropDown
        radiusofcurvatureEditField_9Label  matlab.ui.control.Label
        radiusofcurvatureEditField_9    matlab.ui.control.EditField
        curvedDropDown_9Label           matlab.ui.control.Label
        curvedDropDown_9                matlab.ui.control.DropDown
        APEXxEditField_3Label           matlab.ui.control.Label
        APEXxEditField_3                matlab.ui.control.NumericEditField
        yEditField_7Label               matlab.ui.control.Label
        yEditField_7                    matlab.ui.control.NumericEditField
        zEditField_7Label               matlab.ui.control.Label
        zEditField_7                    matlab.ui.control.NumericEditField
        noofpartitionsDropDown_3Label   matlab.ui.control.Label
        noofpartitionsDropDown_3        matlab.ui.control.DropDown
        SaveasEditFieldLabel            matlab.ui.control.Label
        SaveasEditField                 matlab.ui.control.EditField
        SaveButton                      matlab.ui.control.Button
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp; % Description
        geo;
        state;
        lattice;
        ref;
        settings;
    end
    
    methods (Access = public)
        
        function results = update(app)
           app.massEditField.Value = num2str(app.geo.mass);
           
           app.IxxEditField.Value = num2str(app.geo.I(1,1));
           app.IxyEditField.Value = num2str(app.geo.I(1,2));
           app.IxzEditField.Value = num2str(app.geo.I(1,3));
           app.IyxEditField.Value = num2str(app.geo.I(2,1));
           app.IyyEditField.Value = num2str(app.geo.I(2,2));
           app.IyzEditField.Value = num2str(app.geo.I(2,3));
           app.IzxEditField.Value = num2str(app.geo.I(3,1));
           app.IzyEditField.Value = num2str(app.geo.I(3,2));
           app.IzzEditField.Value = num2str(app.geo.I(3,3));
           
           app.nooffoilsDropDown.Value = num2str(app.geo.nwing);
           
           app.COGxEditField.Value = num2str(app.geo.CG(1));
           app.yEditField_4.Value = num2str(app.geo.CG(2));
           app.zEditField_4.Value = num2str(app.geo.CG(3));
           
           app.REFxEditField.Value = num2str(app.geo.ref_point(1));
           app.yEditField_5.Value = num2str(app.geo.ref_point(2));
           app.zEditField_5.Value = num2str(app.geo.ref_point(3));
           
           nooffoils(app);
           
           app.mirroredCheckBox.Value = app.geo.mirrored;
           
        end
        
        function results = nooffoils(app)
            value = app.nooffoilsDropDown.Value;
            switch value
                case "1"
                    app.Foil1Panel.Visible = 'on';
                    app.Foil2Panel.Visible = 'off';
                    app.Foil3Panel.Visible = 'off';
                    app.geo.nwing = str2double(value);
                    
                case "2"
                    app.Foil1Panel.Visible = 'on';
                    app.Foil2Panel.Visible = 'on';
                    app.Foil3Panel.Visible = 'off';
                    app.geo.nwing = str2double(value);
                    
                case "3"
                    app.Foil1Panel.Visible = 'on';
                    app.Foil2Panel.Visible = 'on';
                    app.Foil3Panel.Visible = 'on';
                    app.geo.nwing = str2double(value);
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, geo, state, lattice, ref, settings)
            app.CallingApp = mainapp;
            app.geo = geo;
            app.state = state;
            app.lattice = lattice;
            app.ref = ref;
            app.settings = settings;
            
            update(app);
        end

        % Value changed function: nooffoilsDropDown
        function nooffoilsDropDownValueChanged(app, event)
            nooffoils(app);
        end

        % Value changed function: noofpartitionsDropDown
        function noofpartitionsDropDownValueChanged(app, event)
            value = app.noofpartitionsDropDown.Value;
            switch value
                case "1"
                    app.partition1Panel.Visible = 'on';
                    app.partition2Panel.Visible = 'off';
                    app.partition3Panel.Visible = 'off';
                    app.geo.nelem(1)=str2double(value);
                    app.geo.curved(1,1) = 0;
                    
                case "2"
                    app.partition1Panel.Visible = 'on';
                    app.partition2Panel.Visible = 'on';
                    app.partition3Panel.Visible = 'off';
                    app.geo.nelem(1)=str2double(value);
                    app.geo.curved(1,1) = 0;
                    app.geo.curved(1,2) = 0;
                case "3"
                    app.partition1Panel.Visible = 'on';
                    app.partition2Panel.Visible = 'on';
                    app.partition3Panel.Visible = 'on';
                    app.geo.nelem(1)=str2double(value);
                    
                    app.geo.curved(1,1) = 0;
                    app.geo.curved(1,2) = 0;
            end
        end

        % Value changed function: noofpartitionsDropDown_2
        function noofpartitionsDropDown_2ValueChanged(app, event)
            value = app.noofpartitionsDropDown_2.Value;
            switch value
                case "1"
                    app.partition1Panel_2.Visible = 'on';
                    app.partition2Panel_2.Visible = 'off';
                    app.partition3Panel_2.Visible = 'off';
                    app.geo.nelem(2)=str2double(value);
                    app.geo.curved(2,1) = 0;
                    
                case "2"
                    app.partition1Panel_2.Visible = 'on';
                    app.partition2Panel_2.Visible = 'on';
                    app.partition3Panel_2.Visible = 'off';
                    app.geo.nelem(2)=str2double(value);
                    app.geo.curved(2,1) = 0;
                    app.geo.curved(2,2) = 0;
                case "3"
                    app.partition1Panel_2.Visible = 'on';
                    app.partition2Panel_2.Visible = 'on';
                    app.partition3Panel_2.Visible = 'on';
                    app.geo.nelem(2)=str2double(value);
                    app.geo.curved(2,1) = 0;
                    app.geo.curved(2,2) = 0;
            end
        end

        % Value changed function: noofpartitionsDropDown_3
        function noofpartitionsDropDown_3ValueChanged(app, event)
            value = app.noofpartitionsDropDown_3.Value;
            switch value
                case "1"
                    app.partition1Panel_3.Visible = 'on';
                    app.partition2Panel_3.Visible = 'off';
                    app.partition3Panel_3.Visible = 'off';
                    app.geo.nelem(3)=str2double(value);
                    app.geo.curved(3,1) = 0;
                    
                case "2"
                    app.partition1Panel_3.Visible = 'on';
                    app.partition2Panel_3.Visible = 'on';
                    app.partition3Panel_3.Visible = 'off';
                    app.geo.nelem(3)=str2double(value);
                    app.geo.curved(3,1) = 0;
                    app.geo.curved(3,2) = 0;
                case "3"
                    app.partition1Panel_3.Visible = 'on';
                    app.partition2Panel_3.Visible = 'on';
                    app.partition3Panel_3.Visible = 'on';
                    app.geo.nelem(3)=str2double(value);
                    app.geo.curved(3,1) = 0;
                    app.geo.curved(3,2) = 0;
            end
        end

        % Value changed function: massEditField
        function massEditFieldValueChanged(app, event)
            value = app.massEditField.Value;
            app.geo.mass = str2double(value);
        end

        % Value changed function: IxxEditField
        function IxxEditFieldValueChanged(app, event)
            value = app.IxxEditField.Value;
            app.geo.I(1,1) = str2double(value);
        end

        % Value changed function: IyxEditField
        function IyxEditFieldValueChanged(app, event)
            value = app.IyxEditField.Value;
            app.geo.I(2,1) = str2double(value);
        end

        % Value changed function: IzxEditField
        function IzxEditFieldValueChanged(app, event)
            value = app.IzxEditField.Value;
            app.geo.I(3,1) = str2double(value);
        end

        % Value changed function: IxyEditField
        function IxyEditFieldValueChanged(app, event)
            value = app.IxyEditField.Value;
            app.geo.I(1,2) = str2double(value);
        end

        % Value changed function: IyyEditField
        function IyyEditFieldValueChanged(app, event)
            value = app.IyyEditField.Value;
            app.geo.I(2,2) = str2double(value);
        end

        % Value changed function: IzyEditField
        function IzyEditFieldValueChanged(app, event)
            value = app.IzyEditField.Value;
            app.geo.I(3,2) = str2double(value);
        end

        % Value changed function: IxzEditField
        function IxzEditFieldValueChanged(app, event)
            value = app.IxzEditField.Value;
            app.geo.I(1,3) = str2double(value);
        end

        % Value changed function: IyzEditField
        function IyzEditFieldValueChanged(app, event)
            value = app.IyzEditField.Value;
            app.geo.I(2,3) = str2double(value);
        end

        % Value changed function: IzzEditField
        function IzzEditFieldValueChanged(app, event)
            value = app.IzzEditField.Value;
            app.geo.I(3,3) = str2double(value);
        end

        % Value changed function: COGxEditField
        function COGxEditFieldValueChanged(app, event)
            value = app.COGxEditField.Value;
            app.geo.CG(1) = str2double(value);
        end

        % Value changed function: yEditField_4
        function yEditField_4ValueChanged(app, event)
            value = app.yEditField_4.Value;
            app.geo.CG(2) = str2double(value);
        end

        % Value changed function: zEditField_4
        function zEditField_4ValueChanged(app, event)
            value = app.zEditField_4.Value;
            app.geo.CG(3) = str2double(value);
        end

        % Value changed function: REFxEditField
        function REFxEditFieldValueChanged(app, event)
            value = app.REFxEditField.Value;
            app.geo.ref_point(1) = str2double(value);
        end

        % Value changed function: yEditField_5
        function yEditField_5ValueChanged(app, event)
            value = app.yEditField_5.Value;
            app.geo.ref_point(2) = str2double(value);
        end

        % Value changed function: zEditField_5
        function zEditField_5ValueChanged(app, event)
            value = app.zEditField_5.Value;
            app.geo.ref_point(3) = str2double(value);
        end

        % Value changed function: MirroredCheckBox
        function MirroredCheckBoxValueChanged(app, event)
            value = app.MirroredCheckBox.Value;
            app.geo.symetric(1) = value;
        end

        % Value changed function: APEXxEditField
        function APEXxEditFieldValueChanged(app, event)
            value = app.APEXxEditField.Value;
            app.geo.startx = str2double(value);
        end

        % Value changed function: yEditField_3
        function yEditField_3ValueChanged(app, event)
            value = app.yEditField_3.Value;
            app.geo.starty = str2double(value);
        end

        % Value changed function: zEditField_3
        function zEditField_3ValueChanged(app, event)
            value = app.zEditField_3.Value;
            app.geo.startz = str2double(value);
        end

        % Value changed function: RootchordEditField
        function RootchordEditFieldValueChanged(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            value = app.RootchordEditField.Value;
            app.geo.c(1) = str2double(value);
        end

        % Value changed function: BasechordairfoilEditField
        function BasechordairfoilEditFieldValueChanged(app, event)
            value = app.BasechordairfoilEditField.Value;
            app.geo.foil(1,1,1) = {value};
        end

        % Value changed function: noofpanelschordwiseEditField
        function noofpanelschordwiseEditFieldValueChanged(app, event)
            value = app.noofpanelschordwiseEditField.Value;
            app.geo.nx(1,1) = str2double(value);
        end

        % Value changed function: dihedralEditField
        function dihedralEditFieldValueChanged(app, event)
            value = app.dihedralEditField.Value;
            app.geo.dihed(1,1) = str2double(value)*pi/180;
        end

        % Value changed function: pitchEditField
        function pitchEditFieldValueChanged(app, event)
            value = app.pitchEditField.Value;
            app.geo.foiltheta(1,1) = str2double(value)*pi/180;
        end

        % Value changed function: basechordtwistEditField
        function basechordtwistEditFieldValueChanged(app, event)
            value = app.basechordtwistEditField.Value;
            app.geo.TW(1,1,1) = str2double(value)*pi/180;
            app.geo.BTW(1) = str2double(value)*pi/180;
        end

        % Value changed function: noofpanelssemispanwiseEditField
        function noofpanelssemispanwiseEditFieldValueChanged(app, event)
            value = app.noofpanelssemispanwiseEditField.Value;
            app.geo.ny(1,1) = str2double(value);
        end

        % Value changed function: spanEditField
        function spanEditFieldValueChanged(app, event)
            value = app.spanEditField.Value;
            app.geo.b(1,1) = str2double(value);
        end

        % Value changed function: taperEditField
        function taperEditFieldValueChanged(app, event)
            value = app.taperEditField.Value;
            app.geo.T(1,1) = str2double(value);
        end

        % Value changed function: tipchordairfoilEditField
        function tipchordairfoilEditFieldValueChanged(app, event)
            value = app.tipchordairfoilEditField.Value;
            app.geo.foil(1,1,2) = {value};
        end

        % Value changed function: sweepEditField
        function sweepEditFieldValueChanged(app, event)
            value = app.sweepEditField.Value;
            app.geo.SW(1,1) = str2double(value)*pi/180;
        end

        % Value changed function: outboardtwistEditField
        function outboardtwistEditFieldValueChanged(app, event)
            value = app.outboardtwistEditField.Value;
            app.geo.TW(1,1,2) = str2double(value)*pi/180;
        end

        % Value changed function: meshtypeDropDown
        function meshtypeDropDownValueChanged(app, event)
            value = app.meshtypeDropDown.Value;
            app.geo.meshtype(1,1) = str2double(value);
        end

        % Value changed function: curvedDropDown
        function curvedDropDownValueChanged(app, event)
            value = app.curvedDropDown.Value;
            switch value
                case 'yes'
                    app.geo.curved(1,1) = 1;
                    app.radiusofcurvatureEditField.Visible = 'on';
                case 'no'
                    app.geo.curved(1,1) = 0;
                    app.radiusofcurvatureEditField.Visible = 'off';
            end
        end

        % Value changed function: dihedralEditField_2
        function dihedralEditField_2ValueChanged(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            value = app.dihedralEditField_2.Value;
            if app.geo.curved(1,1)==0
                app.geo.dihed(1,2) = app.geo.dihed(1,1) + str2num(value)*pi/180;
            else
                app.geo.dihed(1,2) = app.geo.dihed(1,1)-app.geo.b(1,1)/app.geo.radius(1,1)+str2num(value)*pi/180;
            end
            
        end

        % Value changed function: noofpanelssemispanwiseEditField_2
        function noofpanelssemispanwiseEditField_2ValueChanged(app, event)
            value = app.noofpanelssemispanwiseEditField_2.Value;
            app.geo.ny(1,2) = str2double(value);
        end

        % Value changed function: spanEditField_2
        function spanEditField_2ValueChanged(app, event)
            value = app.spanEditField_2.Value;
            app.geo.b(1,2) = str2double(value);
        end

        % Value changed function: taperEditField_2
        function taperEditField_2ValueChanged(app, event)
            value = app.taperEditField_2.Value;
            app.geo.T(1,2) = str2double(value);
        end

        % Value changed function: tipchordairfoilEditField_2
        function tipchordairfoilEditField_2ValueChanged(app, event)
            value = app.tipchordairfoilEditField_2.Value;
            app.geo.foil(1,2,2) = {value};
        end

        % Value changed function: sweepEditField_2
        function sweepEditField_2ValueChanged(app, event)
            value = app.sweepEditField_2.Value;
            app.geo.SW(1,2) = str2double(value);
        end

        % Value changed function: outboardtwistEditField_2
        function outboardtwistEditField_2ValueChanged(app, event)
            value = app.outboardtwistEditField_2.Value;
            app.geo.TW(1,2,2) = str2double(value);
        end

        % Value changed function: meshtypeDropDown_2
        function meshtypeDropDown_2ValueChanged(app, event)
            value = app.meshtypeDropDown_2.Value;
            app.geo.meshtype(1,2) = str2double(value);
        end

        % Value changed function: curvedDropDown_2
        function curvedDropDown_2ValueChanged(app, event)
            value = app.curvedDropDown_2.Value;
            switch value
                case 'yes'
                    app.geo.curved(1,2) = 1;
                    app.radiusofcurvatureEditField_2.Visible = 'on';
                case 'no'
                    app.geo.curved(1,2) = 0;
                    app.radiusofcurvatureEditField_2.Visible = 'off';
            end
        end

        % Value changed function: radiusofcurvatureEditField
        function radiusofcurvatureEditFieldValueChanged(app, event)
            value = app.radiusofcurvatureEditField.Value;
            app.geo.radius(1,1) = str2double(value);
        end

        % Value changed function: radiusofcurvatureEditField_2
        function radiusofcurvatureEditField_2ValueChanged(app, event)
            value = app.radiusofcurvatureEditField_2.Value;
            app.geo.radius(1,2) = str2double(value);
        end

        % Value changed function: dihedralEditField_3
        function dihedralEditField_3ValueChanged(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            value = app.dihedralEditField_3.Value;
            if app.geo.curved(1,2)==0
                app.geo.dihed(1,3) = app.geo.dihed(1,2) + str2num(value)*pi/180;
            else
                app.geo.dihed(1,3) = app.geo.dihed(1,2)-app.geo.b(1,2)/app.geo.radius(1,2)+str2num(value)*pi/180;
            end
        end

        % Value changed function: noofpanelssemispanwiseEditField_3
        function noofpanelssemispanwiseEditField_3ValueChanged(app, event)
            value = app.noofpanelssemispanwiseEditField_3.Value;
            app.geo.ny(1,3) = str2double(value);
        end

        % Value changed function: spanEditField_3
        function spanEditField_3ValueChanged(app, event)
            value = app.spanEditField_3.Value;
            app.geo.b(1,3) = str2double(value);
        end

        % Value changed function: taperEditField_3
        function taperEditField_3ValueChanged(app, event)
            value = app.taperEditField_3.Value;
            app.geo.T(1,3) = str2double(value);
        end

        % Value changed function: tipchordairfoilEditField_3
        function tipchordairfoilEditField_3ValueChanged(app, event)
            value = app.tipchordairfoilEditField_3.Value;
            app.geo.foil(1,3,2) = {value};
        end

        % Value changed function: sweepEditField_3
        function sweepEditField_3ValueChanged(app, event)
            value = app.sweepEditField_3.Value;
            app.geo.SW(1,3) = str2double(value)*pi/180;
        end

        % Value changed function: outboardtwistEditField_3
        function outboardtwistEditField_3ValueChanged(app, event)
            value = app.outboardtwistEditField_3.Value;
            app.geo.TW(1,3,2) = str2double(value)*pi/180;
        end

        % Value changed function: meshtypeDropDown_3
        function meshtypeDropDown_3ValueChanged(app, event)
            value = app.meshtypeDropDown_3.Value;
            app.geo.meshtype(1,3) = str2double(value); 
        end

        % Value changed function: curvedDropDown_3
        function curvedDropDown_3ValueChanged(app, event)
            value = app.curvedDropDown_3.Value;
            switch value
                case 'yes'
                    app.geo.curved(1,3) = 1;
                    app.radiusofcurvatureEditField_2.Visible = 'on';
                case 'no'
                    app.geo.curved(1,3) = 0;
                    app.radiusofcurvatureEditField_2.Visible = 'off';
            end
        end

        % Value changed function: radiusofcurvatureEditField_3
        function radiusofcurvatureEditField_3ValueChanged(app, event)
            value = app.radiusofcurvatureEditField_3.Value;
            app.geo.radius(1,3) = str2double(value);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            cd(app.settings.acdir)
            tph_version=1;
            fname=app.SaveasEditField.Value;
            if isempty(fname)==1
                app.Label.Text = '+++ no name, not saved +++';
                cd(app.settings.hdir)
            else
                try
                    geo = app.geo;
                    save(fname,'geo');
                    cd(app.settings.hdir)
                catch
                    app.Label.Text = '+++ no geometry +++';
                    cd(app.settings.hdir)
                end
            end
                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.edit_geometry = uifigure;
            app.edit_geometry.Name = 'UI Figure';
            app.nooffoilsDropDownLabel = uilabel(app.edit_geometry);
            app.nooffoilsDropDownLabel.Text = 'no. of foils';
            app.nooffoilsDropDown = uidropdown(app.edit_geometry);
            app.nooffoilsDropDown.Items = {'1', '2', '3'};
            app.nooffoilsDropDown.ValueChangedFcn = createCallbackFcn(app, @nooffoilsDropDownValueChanged, true);
            app.nooffoilsDropDown.Value = '1';
            app.Foil1Panel = uipanel(app.edit_geometry);
            app.Foil1Panel.Title = 'Foil 1';
            app.MirroredCheckBox = uicheckbox(app.Foil1Panel);
            app.MirroredCheckBox.ValueChangedFcn = createCallbackFcn(app, @MirroredCheckBoxValueChanged, true);
            app.MirroredCheckBox.Text = 'Mirrored';
            app.partition1Panel = uipanel(app.Foil1Panel);
            app.partition1Panel.Title = 'partition 1';
            app.RootchordEditFieldLabel = uilabel(app.partition1Panel);
            app.RootchordEditFieldLabel.Text = 'Root chord';
            app.RootchordEditField = uieditfield(app.partition1Panel, 'text');
            app.RootchordEditField.ValueChangedFcn = createCallbackFcn(app, @RootchordEditFieldValueChanged, true);
            app.BasechordairfoilEditFieldLabel = uilabel(app.partition1Panel);
            app.BasechordairfoilEditFieldLabel.Text = 'Base chord airfoil';
            app.BasechordairfoilEditField = uieditfield(app.partition1Panel, 'text');
            app.BasechordairfoilEditField.ValueChangedFcn = createCallbackFcn(app, @BasechordairfoilEditFieldValueChanged, true);
            app.noofpanelschordwiseEditFieldLabel = uilabel(app.partition1Panel);
            app.noofpanelschordwiseEditFieldLabel.Text = 'no. of panels chord wise';
            app.noofpanelschordwiseEditField = uieditfield(app.partition1Panel, 'text');
            app.noofpanelschordwiseEditField.ValueChangedFcn = createCallbackFcn(app, @noofpanelschordwiseEditFieldValueChanged, true);
            app.dihedralEditFieldLabel = uilabel(app.partition1Panel);
            app.dihedralEditFieldLabel.Text = 'dihedral';
            app.dihedralEditField = uieditfield(app.partition1Panel, 'text');
            app.dihedralEditField.ValueChangedFcn = createCallbackFcn(app, @dihedralEditFieldValueChanged, true);
            app.pitchEditFieldLabel = uilabel(app.partition1Panel);
            app.pitchEditFieldLabel.Text = 'pitch';
            app.pitchEditField = uieditfield(app.partition1Panel, 'text');
            app.pitchEditField.ValueChangedFcn = createCallbackFcn(app, @pitchEditFieldValueChanged, true);
            app.basechordtwistEditFieldLabel = uilabel(app.partition1Panel);
            app.basechordtwistEditFieldLabel.Text = 'base chord twist';
            app.basechordtwistEditField = uieditfield(app.partition1Panel, 'text');
            app.basechordtwistEditField.ValueChangedFcn = createCallbackFcn(app, @basechordtwistEditFieldValueChanged, true);
            app.noofpanelssemispanwiseEditFieldLabel = uilabel(app.partition1Panel);
            app.noofpanelssemispanwiseEditFieldLabel.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField = uieditfield(app.partition1Panel, 'text');
            app.noofpanelssemispanwiseEditField.ValueChangedFcn = createCallbackFcn(app, @noofpanelssemispanwiseEditFieldValueChanged, true);
            app.spanEditFieldLabel = uilabel(app.partition1Panel);
            app.spanEditFieldLabel.Text = 'span';
            app.spanEditField = uieditfield(app.partition1Panel, 'text');
            app.spanEditField.ValueChangedFcn = createCallbackFcn(app, @spanEditFieldValueChanged, true);
            app.taperEditFieldLabel = uilabel(app.partition1Panel);
            app.taperEditFieldLabel.Text = 'taper';
            app.taperEditField = uieditfield(app.partition1Panel, 'text');
            app.taperEditField.ValueChangedFcn = createCallbackFcn(app, @taperEditFieldValueChanged, true);
            app.tipchordairfoilEditFieldLabel = uilabel(app.partition1Panel);
            app.tipchordairfoilEditFieldLabel.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField = uieditfield(app.partition1Panel, 'text');
            app.tipchordairfoilEditField.ValueChangedFcn = createCallbackFcn(app, @tipchordairfoilEditFieldValueChanged, true);
            app.sweepEditFieldLabel = uilabel(app.partition1Panel);
            app.sweepEditFieldLabel.Text = 'sweep';
            app.sweepEditField = uieditfield(app.partition1Panel, 'text');
            app.sweepEditField.ValueChangedFcn = createCallbackFcn(app, @sweepEditFieldValueChanged, true);
            app.outboardtwistEditFieldLabel = uilabel(app.partition1Panel);
            app.outboardtwistEditFieldLabel.Text = 'outboard twist';
            app.outboardtwistEditField = uieditfield(app.partition1Panel, 'text');
            app.outboardtwistEditField.ValueChangedFcn = createCallbackFcn(app, @outboardtwistEditFieldValueChanged, true);
            app.meshtypeDropDownLabel = uilabel(app.partition1Panel);
            app.meshtypeDropDownLabel.Text = 'mesh type';
            app.meshtypeDropDown = uidropdown(app.partition1Panel);
            app.meshtypeDropDown.Items = {'linear', 'span-wise half cosine', 'spanwise half-cosine, chordwise', 'spanwise cosine', 'chordwise cosine'};
            app.meshtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @meshtypeDropDownValueChanged, true);
            app.meshtypeDropDown.Value = 'linear';
            app.radiusofcurvatureEditFieldLabel = uilabel(app.partition1Panel);
            app.radiusofcurvatureEditFieldLabel.Text = 'radius of curvature';
            app.radiusofcurvatureEditField = uieditfield(app.partition1Panel, 'text');
            app.radiusofcurvatureEditField.ValueChangedFcn = createCallbackFcn(app, @radiusofcurvatureEditFieldValueChanged, true);
            app.radiusofcurvatureEditField.Visible = 'off';
            app.curvedDropDownLabel = uilabel(app.partition1Panel);
            app.curvedDropDownLabel.Text = 'curved';
            app.curvedDropDown = uidropdown(app.partition1Panel);
            app.curvedDropDown.Items = {'no', 'yes'};
            app.curvedDropDown.ValueChangedFcn = createCallbackFcn(app, @curvedDropDownValueChanged, true);
            app.curvedDropDown.Value = 'no';
            app.partition2Panel = uipanel(app.Foil1Panel);
            app.partition2Panel.Title = 'partition 2';
            app.partition2Panel.Visible = 'off';
            app.dihedralEditField_2Label = uilabel(app.partition2Panel);
            app.dihedralEditField_2Label.Text = 'dihedral';
            app.dihedralEditField_2 = uieditfield(app.partition2Panel, 'text');
            app.dihedralEditField_2.ValueChangedFcn = createCallbackFcn(app, @dihedralEditField_2ValueChanged, true);
            app.noofpanelssemispanwiseEditField_2Label = uilabel(app.partition2Panel);
            app.noofpanelssemispanwiseEditField_2Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_2 = uieditfield(app.partition2Panel, 'text');
            app.noofpanelssemispanwiseEditField_2.ValueChangedFcn = createCallbackFcn(app, @noofpanelssemispanwiseEditField_2ValueChanged, true);
            app.spanEditField_2Label = uilabel(app.partition2Panel);
            app.spanEditField_2Label.Text = 'span';
            app.spanEditField_2 = uieditfield(app.partition2Panel, 'text');
            app.spanEditField_2.ValueChangedFcn = createCallbackFcn(app, @spanEditField_2ValueChanged, true);
            app.taperEditField_2Label = uilabel(app.partition2Panel);
            app.taperEditField_2Label.Text = 'taper';
            app.taperEditField_2 = uieditfield(app.partition2Panel, 'text');
            app.taperEditField_2.ValueChangedFcn = createCallbackFcn(app, @taperEditField_2ValueChanged, true);
            app.tipchordairfoilEditField_2Label = uilabel(app.partition2Panel);
            app.tipchordairfoilEditField_2Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_2 = uieditfield(app.partition2Panel, 'text');
            app.tipchordairfoilEditField_2.ValueChangedFcn = createCallbackFcn(app, @tipchordairfoilEditField_2ValueChanged, true);
            app.sweepEditField_2Label = uilabel(app.partition2Panel);
            app.sweepEditField_2Label.Text = 'sweep';
            app.sweepEditField_2 = uieditfield(app.partition2Panel, 'text');
            app.sweepEditField_2.ValueChangedFcn = createCallbackFcn(app, @sweepEditField_2ValueChanged, true);
            app.outboardtwistEditField_2Label = uilabel(app.partition2Panel);
            app.outboardtwistEditField_2Label.Text = 'outboard twist';
            app.outboardtwistEditField_2 = uieditfield(app.partition2Panel, 'text');
            app.outboardtwistEditField_2.ValueChangedFcn = createCallbackFcn(app, @outboardtwistEditField_2ValueChanged, true);
            app.meshtypeDropDown_2Label = uilabel(app.partition2Panel);
            app.meshtypeDropDown_2Label.Text = 'mesh type';
            app.meshtypeDropDown_2 = uidropdown(app.partition2Panel);
            app.meshtypeDropDown_2.Items = {'linear', 'span-wise half cosine', 'spanwise half-cosine, chordwise', 'spanwise cosine', 'chordwise cosine'};
            app.meshtypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @meshtypeDropDown_2ValueChanged, true);
            app.meshtypeDropDown_2.Value = 'linear';
            app.radiusofcurvatureEditField_2Label = uilabel(app.partition2Panel);
            app.radiusofcurvatureEditField_2Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_2 = uieditfield(app.partition2Panel, 'text');
            app.radiusofcurvatureEditField_2.ValueChangedFcn = createCallbackFcn(app, @radiusofcurvatureEditField_2ValueChanged, true);
            app.radiusofcurvatureEditField_2.Visible = 'off';
            app.curvedDropDown_2Label = uilabel(app.partition2Panel);
            app.curvedDropDown_2Label.Text = 'curved';
            app.curvedDropDown_2 = uidropdown(app.partition2Panel);
            app.curvedDropDown_2.Items = {'no', 'yes'};
            app.curvedDropDown_2.ValueChangedFcn = createCallbackFcn(app, @curvedDropDown_2ValueChanged, true);
            app.curvedDropDown_2.Value = 'no';
            app.partition3Panel = uipanel(app.Foil1Panel);
            app.partition3Panel.Title = 'partition 3';
            app.partition3Panel.Visible = 'off';
            app.dihedralEditField_3Label = uilabel(app.partition3Panel);
            app.dihedralEditField_3Label.Text = 'dihedral';
            app.dihedralEditField_3 = uieditfield(app.partition3Panel, 'text');
            app.dihedralEditField_3.ValueChangedFcn = createCallbackFcn(app, @dihedralEditField_3ValueChanged, true);
            app.noofpanelssemispanwiseEditField_3Label = uilabel(app.partition3Panel);
            app.noofpanelssemispanwiseEditField_3Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_3 = uieditfield(app.partition3Panel, 'text');
            app.noofpanelssemispanwiseEditField_3.ValueChangedFcn = createCallbackFcn(app, @noofpanelssemispanwiseEditField_3ValueChanged, true);
            app.spanEditField_3Label = uilabel(app.partition3Panel);
            app.spanEditField_3Label.Text = 'span';
            app.spanEditField_3 = uieditfield(app.partition3Panel, 'text');
            app.spanEditField_3.ValueChangedFcn = createCallbackFcn(app, @spanEditField_3ValueChanged, true);
            app.taperEditField_3Label = uilabel(app.partition3Panel);
            app.taperEditField_3Label.Text = 'taper';
            app.taperEditField_3 = uieditfield(app.partition3Panel, 'text');
            app.taperEditField_3.ValueChangedFcn = createCallbackFcn(app, @taperEditField_3ValueChanged, true);
            app.tipchordairfoilEditField_3Label = uilabel(app.partition3Panel);
            app.tipchordairfoilEditField_3Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_3 = uieditfield(app.partition3Panel, 'text');
            app.tipchordairfoilEditField_3.ValueChangedFcn = createCallbackFcn(app, @tipchordairfoilEditField_3ValueChanged, true);
            app.sweepEditField_3Label = uilabel(app.partition3Panel);
            app.sweepEditField_3Label.Text = 'sweep';
            app.sweepEditField_3 = uieditfield(app.partition3Panel, 'text');
            app.sweepEditField_3.ValueChangedFcn = createCallbackFcn(app, @sweepEditField_3ValueChanged, true);
            app.outboardtwistEditField_3Label = uilabel(app.partition3Panel);
            app.outboardtwistEditField_3Label.Text = 'outboard twist';
            app.outboardtwistEditField_3 = uieditfield(app.partition3Panel, 'text');
            app.outboardtwistEditField_3.ValueChangedFcn = createCallbackFcn(app, @outboardtwistEditField_3ValueChanged, true);
            app.meshtypeDropDown_3Label = uilabel(app.partition3Panel);
            app.meshtypeDropDown_3Label.Text = 'mesh type';
            app.meshtypeDropDown_3 = uidropdown(app.partition3Panel);
            app.meshtypeDropDown_3.Items = {'linear', 'span-wise half cosine', 'spanwise half-cosine, chordwise', 'spanwise cosine', 'chordwise cosine'};
            app.meshtypeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @meshtypeDropDown_3ValueChanged, true);
            app.meshtypeDropDown_3.Value = 'linear';
            app.radiusofcurvatureEditField_3Label = uilabel(app.partition3Panel);
            app.radiusofcurvatureEditField_3Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_3 = uieditfield(app.partition3Panel, 'text');
            app.radiusofcurvatureEditField_3.ValueChangedFcn = createCallbackFcn(app, @radiusofcurvatureEditField_3ValueChanged, true);
            app.curvedDropDown_3Label = uilabel(app.partition3Panel);
            app.curvedDropDown_3Label.Text = 'curved';
            app.curvedDropDown_3 = uidropdown(app.partition3Panel);
            app.curvedDropDown_3.Items = {'no', 'yes'};
            app.curvedDropDown_3.ValueChangedFcn = createCallbackFcn(app, @curvedDropDown_3ValueChanged, true);
            app.curvedDropDown_3.Value = 'no';
            app.APEXxEditFieldLabel = uilabel(app.Foil1Panel);
            app.APEXxEditFieldLabel.Text = 'APEX x';
            app.APEXxEditField = uieditfield(app.Foil1Panel, 'numeric');
            app.APEXxEditField.ValueChangedFcn = createCallbackFcn(app, @APEXxEditFieldValueChanged, true);
            app.yEditField_3Label = uilabel(app.Foil1Panel);
            app.yEditField_3Label.Text = 'y';
            app.yEditField_3 = uieditfield(app.Foil1Panel, 'numeric');
            app.yEditField_3.ValueChangedFcn = createCallbackFcn(app, @yEditField_3ValueChanged, true);
            app.zEditField_3Label = uilabel(app.Foil1Panel);
            app.zEditField_3Label.Text = 'z';
            app.zEditField_3 = uieditfield(app.Foil1Panel, 'numeric');
            app.zEditField_3.ValueChangedFcn = createCallbackFcn(app, @zEditField_3ValueChanged, true);
            app.noofpartitionsDropDownLabel = uilabel(app.Foil1Panel);
            app.noofpartitionsDropDownLabel.Text = 'no. of partitions';
            app.noofpartitionsDropDown = uidropdown(app.Foil1Panel);
            app.noofpartitionsDropDown.Items = {'1', '2', '3'};
            app.noofpartitionsDropDown.ValueChangedFcn = createCallbackFcn(app, @noofpartitionsDropDownValueChanged, true);
            app.noofpartitionsDropDown.Value = '1';
            app.massEditFieldLabel = uilabel(app.edit_geometry);
            app.massEditFieldLabel.Text = 'mass';
            app.massEditField = uieditfield(app.edit_geometry, 'text');
            app.massEditField.ValueChangedFcn = createCallbackFcn(app, @massEditFieldValueChanged, true);
            app.IxxEditFieldLabel = uilabel(app.edit_geometry);
            app.IxxEditFieldLabel.Text = 'Ixx';
            app.IxxEditField = uieditfield(app.edit_geometry, 'text');
            app.IxxEditField.ValueChangedFcn = createCallbackFcn(app, @IxxEditFieldValueChanged, true);
            app.IyxEditFieldLabel = uilabel(app.edit_geometry);
            app.IyxEditFieldLabel.Text = 'Iyx';
            app.IyxEditField = uieditfield(app.edit_geometry, 'text');
            app.IyxEditField.ValueChangedFcn = createCallbackFcn(app, @IyxEditFieldValueChanged, true);
            app.IzxEditFieldLabel = uilabel(app.edit_geometry);
            app.IzxEditFieldLabel.Text = 'Izx';
            app.IzxEditField = uieditfield(app.edit_geometry, 'text');
            app.IzxEditField.ValueChangedFcn = createCallbackFcn(app, @IzxEditFieldValueChanged, true);
            app.IxyEditFieldLabel = uilabel(app.edit_geometry);
            app.IxyEditFieldLabel.Text = 'Ixy';
            app.IxyEditField = uieditfield(app.edit_geometry, 'text');
            app.IxyEditField.ValueChangedFcn = createCallbackFcn(app, @IxyEditFieldValueChanged, true);
            app.IyyEditFieldLabel = uilabel(app.edit_geometry);
            app.IyyEditFieldLabel.Text = 'Iyy';
            app.IyyEditField = uieditfield(app.edit_geometry, 'text');
            app.IyyEditField.ValueChangedFcn = createCallbackFcn(app, @IyyEditFieldValueChanged, true);
            app.IzyEditFieldLabel = uilabel(app.edit_geometry);
            app.IzyEditFieldLabel.Text = 'Izy';
            app.IzyEditField = uieditfield(app.edit_geometry, 'text');
            app.IzyEditField.ValueChangedFcn = createCallbackFcn(app, @IzyEditFieldValueChanged, true);
            app.IxzEditFieldLabel = uilabel(app.edit_geometry);
            app.IxzEditFieldLabel.Text = 'Ixz';
            app.IxzEditField = uieditfield(app.edit_geometry, 'text');
            app.IxzEditField.ValueChangedFcn = createCallbackFcn(app, @IxzEditFieldValueChanged, true);
            app.IyzEditFieldLabel = uilabel(app.edit_geometry);
            app.IyzEditFieldLabel.Text = 'Iyz';
            app.IyzEditField = uieditfield(app.edit_geometry, 'text');
            app.IyzEditField.ValueChangedFcn = createCallbackFcn(app, @IyzEditFieldValueChanged, true);
            app.IzzEditFieldLabel = uilabel(app.edit_geometry);
            app.IzzEditFieldLabel.Text = 'Izz';
            app.IzzEditField = uieditfield(app.edit_geometry, 'text');
            app.IzzEditField.ValueChangedFcn = createCallbackFcn(app, @IzzEditFieldValueChanged, true);
            app.COGxEditFieldLabel = uilabel(app.edit_geometry);
            app.COGxEditFieldLabel.Text = 'COG x';
            app.COGxEditField = uieditfield(app.edit_geometry, 'text');
            app.COGxEditField.ValueChangedFcn = createCallbackFcn(app, @COGxEditFieldValueChanged, true);
            app.yEditField_4Label = uilabel(app.edit_geometry);
            app.yEditField_4Label.Text = 'y';
            app.yEditField_4 = uieditfield(app.edit_geometry, 'text');
            app.yEditField_4.ValueChangedFcn = createCallbackFcn(app, @yEditField_4ValueChanged, true);
            app.zEditField_4Label = uilabel(app.edit_geometry);
            app.zEditField_4Label.Text = 'z';
            app.zEditField_4 = uieditfield(app.edit_geometry, 'text');
            app.zEditField_4.ValueChangedFcn = createCallbackFcn(app, @zEditField_4ValueChanged, true);
            app.REFxEditFieldLabel = uilabel(app.edit_geometry);
            app.REFxEditFieldLabel.Text = 'REF x';
            app.REFxEditField = uieditfield(app.edit_geometry, 'text');
            app.REFxEditField.ValueChangedFcn = createCallbackFcn(app, @REFxEditFieldValueChanged, true);
            app.yEditField_5Label = uilabel(app.edit_geometry);
            app.yEditField_5Label.Text = 'y';
            app.yEditField_5 = uieditfield(app.edit_geometry, 'text');
            app.yEditField_5.ValueChangedFcn = createCallbackFcn(app, @yEditField_5ValueChanged, true);
            app.zEditField_5Label = uilabel(app.edit_geometry);
            app.zEditField_5Label.Text = 'z';
            app.zEditField_5 = uieditfield(app.edit_geometry, 'text');
            app.zEditField_5.ValueChangedFcn = createCallbackFcn(app, @zEditField_5ValueChanged, true);
            app.Foil2Panel = uipanel(app.edit_geometry);
            app.Foil2Panel.Title = 'Foil 2';
            app.Foil2Panel.Visible = 'off';
            app.MirroredCheckBox_2 = uicheckbox(app.Foil2Panel);
            app.MirroredCheckBox_2.Text = 'Mirrored';
            app.partition1Panel_2 = uipanel(app.Foil2Panel);
            app.partition1Panel_2.Title = 'partition 1';
            app.RootchordEditField_4Label = uilabel(app.partition1Panel_2);
            app.RootchordEditField_4Label.Text = 'Root chord';
            app.RootchordEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.BasechordairfoilEditField_4Label = uilabel(app.partition1Panel_2);
            app.BasechordairfoilEditField_4Label.Text = 'Base chord airfoil';
            app.BasechordairfoilEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.noofpanelschordwiseEditField_4Label = uilabel(app.partition1Panel_2);
            app.noofpanelschordwiseEditField_4Label.Text = 'no. of panels chord wise';
            app.noofpanelschordwiseEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.dihedralEditField_4Label = uilabel(app.partition1Panel_2);
            app.dihedralEditField_4Label.Text = 'dihedral';
            app.dihedralEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.pitchEditField_4Label = uilabel(app.partition1Panel_2);
            app.pitchEditField_4Label.Text = 'pitch';
            app.pitchEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.basechordtwistEditField_4Label = uilabel(app.partition1Panel_2);
            app.basechordtwistEditField_4Label.Text = 'base chord twist';
            app.basechordtwistEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.noofpanelssemispanwiseEditField_4Label = uilabel(app.partition1Panel_2);
            app.noofpanelssemispanwiseEditField_4Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.spanEditField_4Label = uilabel(app.partition1Panel_2);
            app.spanEditField_4Label.Text = 'span';
            app.spanEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.taperEditField_4Label = uilabel(app.partition1Panel_2);
            app.taperEditField_4Label.Text = 'taper';
            app.taperEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.tipchordairfoilEditField_4Label = uilabel(app.partition1Panel_2);
            app.tipchordairfoilEditField_4Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.sweepEditField_4Label = uilabel(app.partition1Panel_2);
            app.sweepEditField_4Label.Text = 'sweep';
            app.sweepEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.outboardtwistEditField_4Label = uilabel(app.partition1Panel_2);
            app.outboardtwistEditField_4Label.Text = 'outboard twist';
            app.outboardtwistEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.meshtypeDropDown_4Label = uilabel(app.partition1Panel_2);
            app.meshtypeDropDown_4Label.Text = 'mesh type';
            app.meshtypeDropDown_4 = uidropdown(app.partition1Panel_2);
            app.meshtypeDropDown_4.Items = {'linear', 'span-wise half cosine', 'spanwise half-cosine, chordwise', 'spanwise cosine', 'chordwise cosine'};
            app.meshtypeDropDown_4.Value = 'linear';
            app.radiusofcurvatureEditField_4Label = uilabel(app.partition1Panel_2);
            app.radiusofcurvatureEditField_4Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.curvedDropDown_4Label = uilabel(app.partition1Panel_2);
            app.curvedDropDown_4Label.Text = 'curved';
            app.curvedDropDown_4 = uidropdown(app.partition1Panel_2);
            app.curvedDropDown_4.Items = {'no', 'yes'};
            app.curvedDropDown_4.Value = 'no';
            app.partition2Panel_2 = uipanel(app.Foil2Panel);
            app.partition2Panel_2.Title = 'partition 2';
            app.partition2Panel_2.Visible = 'off';
            app.RootchordEditField_5Label = uilabel(app.partition2Panel_2);
            app.RootchordEditField_5Label.Text = 'Root chord';
            app.RootchordEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.BasechordairfoilEditField_5Label = uilabel(app.partition2Panel_2);
            app.BasechordairfoilEditField_5Label.Text = 'Base chord airfoil';
            app.BasechordairfoilEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.noofpanelschordwiseEditField_5Label = uilabel(app.partition2Panel_2);
            app.noofpanelschordwiseEditField_5Label.Text = 'no. of panels chord wise';
            app.noofpanelschordwiseEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.dihedralEditField_5Label = uilabel(app.partition2Panel_2);
            app.dihedralEditField_5Label.Text = 'dihedral';
            app.dihedralEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.pitchEditField_5Label = uilabel(app.partition2Panel_2);
            app.pitchEditField_5Label.Text = 'pitch';
            app.pitchEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.basechordtwistEditField_5Label = uilabel(app.partition2Panel_2);
            app.basechordtwistEditField_5Label.Text = 'base chord twist';
            app.basechordtwistEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.noofpanelssemispanwiseEditField_5Label = uilabel(app.partition2Panel_2);
            app.noofpanelssemispanwiseEditField_5Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.spanEditField_5Label = uilabel(app.partition2Panel_2);
            app.spanEditField_5Label.Text = 'span';
            app.spanEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.taperEditField_5Label = uilabel(app.partition2Panel_2);
            app.taperEditField_5Label.Text = 'taper';
            app.taperEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.tipchordairfoilEditField_5Label = uilabel(app.partition2Panel_2);
            app.tipchordairfoilEditField_5Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.sweepEditField_5Label = uilabel(app.partition2Panel_2);
            app.sweepEditField_5Label.Text = 'sweep';
            app.sweepEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.outboardtwistEditField_5Label = uilabel(app.partition2Panel_2);
            app.outboardtwistEditField_5Label.Text = 'outboard twist';
            app.outboardtwistEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.meshtypeDropDown_5Label = uilabel(app.partition2Panel_2);
            app.meshtypeDropDown_5Label.Text = 'mesh type';
            app.meshtypeDropDown_5 = uidropdown(app.partition2Panel_2);
            app.meshtypeDropDown_5.Items = {'linear', 'span-wise half cosine', 'spanwise half-cosine, chordwise', 'spanwise cosine', 'chordwise cosine'};
            app.meshtypeDropDown_5.Value = 'linear';
            app.radiusofcurvatureEditField_5Label = uilabel(app.partition2Panel_2);
            app.radiusofcurvatureEditField_5Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.curvedDropDown_5Label = uilabel(app.partition2Panel_2);
            app.curvedDropDown_5Label.Text = 'curved';
            app.curvedDropDown_5 = uidropdown(app.partition2Panel_2);
            app.curvedDropDown_5.Items = {'no', 'yes'};
            app.curvedDropDown_5.Value = 'no';
            app.partition3Panel_2 = uipanel(app.Foil2Panel);
            app.partition3Panel_2.Title = 'partition 3';
            app.partition3Panel_2.Visible = 'off';
            app.RootchordEditField_6Label = uilabel(app.partition3Panel_2);
            app.RootchordEditField_6Label.Text = 'Root chord';
            app.RootchordEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.BasechordairfoilEditField_6Label = uilabel(app.partition3Panel_2);
            app.BasechordairfoilEditField_6Label.Text = 'Base chord airfoil';
            app.BasechordairfoilEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.noofpanelschordwiseEditField_6Label = uilabel(app.partition3Panel_2);
            app.noofpanelschordwiseEditField_6Label.Text = 'no. of panels chord wise';
            app.noofpanelschordwiseEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.dihedralEditField_6Label = uilabel(app.partition3Panel_2);
            app.dihedralEditField_6Label.Text = 'dihedral';
            app.dihedralEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.pitchEditField_6Label = uilabel(app.partition3Panel_2);
            app.pitchEditField_6Label.Text = 'pitch';
            app.pitchEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.basechordtwistEditField_6Label = uilabel(app.partition3Panel_2);
            app.basechordtwistEditField_6Label.Text = 'base chord twist';
            app.basechordtwistEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.noofpanelssemispanwiseEditField_6Label = uilabel(app.partition3Panel_2);
            app.noofpanelssemispanwiseEditField_6Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.spanEditField_6Label = uilabel(app.partition3Panel_2);
            app.spanEditField_6Label.Text = 'span';
            app.spanEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.taperEditField_6Label = uilabel(app.partition3Panel_2);
            app.taperEditField_6Label.Text = 'taper';
            app.taperEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.tipchordairfoilEditField_6Label = uilabel(app.partition3Panel_2);
            app.tipchordairfoilEditField_6Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.sweepEditField_6Label = uilabel(app.partition3Panel_2);
            app.sweepEditField_6Label.Text = 'sweep';
            app.sweepEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.outboardtwistEditField_6Label = uilabel(app.partition3Panel_2);
            app.outboardtwistEditField_6Label.Text = 'outboard twist';
            app.outboardtwistEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.meshtypeDropDown_6Label = uilabel(app.partition3Panel_2);
            app.meshtypeDropDown_6Label.Text = 'mesh type';
            app.meshtypeDropDown_6 = uidropdown(app.partition3Panel_2);
            app.meshtypeDropDown_6.Items = {'linear', 'span-wise half cosine', 'spanwise half-cosine, chordwise', 'spanwise cosine', 'chordwise cosine'};
            app.meshtypeDropDown_6.Value = 'linear';
            app.radiusofcurvatureEditField_6Label = uilabel(app.partition3Panel_2);
            app.radiusofcurvatureEditField_6Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.curvedDropDown_6Label = uilabel(app.partition3Panel_2);
            app.curvedDropDown_6Label.Text = 'curved';
            app.curvedDropDown_6 = uidropdown(app.partition3Panel_2);
            app.curvedDropDown_6.Items = {'no', 'yes'};
            app.curvedDropDown_6.Value = 'no';
            app.APEXxEditField_2Label = uilabel(app.Foil2Panel);
            app.APEXxEditField_2Label.Text = 'APEX x';
            app.APEXxEditField_2 = uieditfield(app.Foil2Panel, 'numeric');
            app.yEditField_6Label = uilabel(app.Foil2Panel);
            app.yEditField_6Label.Text = 'y';
            app.yEditField_6 = uieditfield(app.Foil2Panel, 'numeric');
            app.zEditField_6Label = uilabel(app.Foil2Panel);
            app.zEditField_6Label.Text = 'z';
            app.zEditField_6 = uieditfield(app.Foil2Panel, 'numeric');
            app.noofpartitionsDropDown_2Label = uilabel(app.Foil2Panel);
            app.noofpartitionsDropDown_2Label.Text = 'no. of partitions';
            app.noofpartitionsDropDown_2 = uidropdown(app.Foil2Panel);
            app.noofpartitionsDropDown_2.Items = {'1', '2', '3'};
            app.noofpartitionsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @noofpartitionsDropDown_2ValueChanged, true);
            app.noofpartitionsDropDown_2.Value = '1';
            app.Foil3Panel = uipanel(app.edit_geometry);
            app.Foil3Panel.Title = 'Foil 3';
            app.Foil3Panel.Visible = 'off';
            app.MirroredCheckBox_3 = uicheckbox(app.Foil3Panel);
            app.MirroredCheckBox_3.Text = 'Mirrored';
            app.partition1Panel_3 = uipanel(app.Foil3Panel);
            app.partition1Panel_3.Title = 'partition 1';
            app.RootchordEditField_7Label = uilabel(app.partition1Panel_3);
            app.RootchordEditField_7Label.Text = 'Root chord';
            app.RootchordEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.BasechordairfoilEditField_7Label = uilabel(app.partition1Panel_3);
            app.BasechordairfoilEditField_7Label.Text = 'Base chord airfoil';
            app.BasechordairfoilEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.noofpanelschordwiseEditField_7Label = uilabel(app.partition1Panel_3);
            app.noofpanelschordwiseEditField_7Label.Text = 'no. of panels chord wise';
            app.noofpanelschordwiseEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.dihedralEditField_7Label = uilabel(app.partition1Panel_3);
            app.dihedralEditField_7Label.Text = 'dihedral';
            app.dihedralEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.pitchEditField_7Label = uilabel(app.partition1Panel_3);
            app.pitchEditField_7Label.Text = 'pitch';
            app.pitchEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.basechordtwistEditField_7Label = uilabel(app.partition1Panel_3);
            app.basechordtwistEditField_7Label.Text = 'base chord twist';
            app.basechordtwistEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.noofpanelssemispanwiseEditField_7Label = uilabel(app.partition1Panel_3);
            app.noofpanelssemispanwiseEditField_7Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.spanEditField_7Label = uilabel(app.partition1Panel_3);
            app.spanEditField_7Label.Text = 'span';
            app.spanEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.taperEditField_7Label = uilabel(app.partition1Panel_3);
            app.taperEditField_7Label.Text = 'taper';
            app.taperEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.tipchordairfoilEditField_7Label = uilabel(app.partition1Panel_3);
            app.tipchordairfoilEditField_7Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.sweepEditField_7Label = uilabel(app.partition1Panel_3);
            app.sweepEditField_7Label.Text = 'sweep';
            app.sweepEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.outboardtwistEditField_7Label = uilabel(app.partition1Panel_3);
            app.outboardtwistEditField_7Label.Text = 'outboard twist';
            app.outboardtwistEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.meshtypeDropDown_7Label = uilabel(app.partition1Panel_3);
            app.meshtypeDropDown_7Label.Text = 'mesh type';
            app.meshtypeDropDown_7 = uidropdown(app.partition1Panel_3);
            app.meshtypeDropDown_7.Items = {'linear', 'span-wise half cosine', 'spanwise half-cosine, chordwise', 'spanwise cosine', 'chordwise cosine'};
            app.meshtypeDropDown_7.Value = 'linear';
            app.radiusofcurvatureEditField_7Label = uilabel(app.partition1Panel_3);
            app.radiusofcurvatureEditField_7Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.curvedDropDown_7Label = uilabel(app.partition1Panel_3);
            app.curvedDropDown_7Label.Text = 'curved';
            app.curvedDropDown_7 = uidropdown(app.partition1Panel_3);
            app.curvedDropDown_7.Items = {'no', 'yes'};
            app.curvedDropDown_7.Value = 'no';
            app.partition2Panel_3 = uipanel(app.Foil3Panel);
            app.partition2Panel_3.Title = 'partition 2';
            app.partition2Panel_3.Visible = 'off';
            app.RootchordEditField_8Label = uilabel(app.partition2Panel_3);
            app.RootchordEditField_8Label.Text = 'Root chord';
            app.RootchordEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.BasechordairfoilEditField_8Label = uilabel(app.partition2Panel_3);
            app.BasechordairfoilEditField_8Label.Text = 'Base chord airfoil';
            app.BasechordairfoilEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.noofpanelschordwiseEditField_8Label = uilabel(app.partition2Panel_3);
            app.noofpanelschordwiseEditField_8Label.Text = 'no. of panels chord wise';
            app.noofpanelschordwiseEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.dihedralEditField_8Label = uilabel(app.partition2Panel_3);
            app.dihedralEditField_8Label.Text = 'dihedral';
            app.dihedralEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.pitchEditField_8Label = uilabel(app.partition2Panel_3);
            app.pitchEditField_8Label.Text = 'pitch';
            app.pitchEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.basechordtwistEditField_8Label = uilabel(app.partition2Panel_3);
            app.basechordtwistEditField_8Label.Text = 'base chord twist';
            app.basechordtwistEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.noofpanelssemispanwiseEditField_8Label = uilabel(app.partition2Panel_3);
            app.noofpanelssemispanwiseEditField_8Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.spanEditField_8Label = uilabel(app.partition2Panel_3);
            app.spanEditField_8Label.Text = 'span';
            app.spanEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.taperEditField_8Label = uilabel(app.partition2Panel_3);
            app.taperEditField_8Label.Text = 'taper';
            app.taperEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.tipchordairfoilEditField_8Label = uilabel(app.partition2Panel_3);
            app.tipchordairfoilEditField_8Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.sweepEditField_8Label = uilabel(app.partition2Panel_3);
            app.sweepEditField_8Label.Text = 'sweep';
            app.sweepEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.outboardtwistEditField_8Label = uilabel(app.partition2Panel_3);
            app.outboardtwistEditField_8Label.Text = 'outboard twist';
            app.outboardtwistEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.meshtypeDropDown_8Label = uilabel(app.partition2Panel_3);
            app.meshtypeDropDown_8Label.Text = 'mesh type';
            app.meshtypeDropDown_8 = uidropdown(app.partition2Panel_3);
            app.meshtypeDropDown_8.Items = {'linear', 'span-wise half cosine', 'spanwise half-cosine, chordwise', 'spanwise cosine', 'chordwise cosine'};
            app.meshtypeDropDown_8.Value = 'linear';
            app.radiusofcurvatureEditField_8Label = uilabel(app.partition2Panel_3);
            app.radiusofcurvatureEditField_8Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.curvedDropDown_8Label = uilabel(app.partition2Panel_3);
            app.curvedDropDown_8Label.Text = 'curved';
            app.curvedDropDown_8 = uidropdown(app.partition2Panel_3);
            app.curvedDropDown_8.Items = {'no', 'yes'};
            app.curvedDropDown_8.Value = 'no';
            app.partition3Panel_3 = uipanel(app.Foil3Panel);
            app.partition3Panel_3.Title = 'partition 3';
            app.partition3Panel_3.Visible = 'off';
            app.RootchordEditField_9Label = uilabel(app.partition3Panel_3);
            app.RootchordEditField_9Label.Text = 'Root chord';
            app.RootchordEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.BasechordairfoilEditField_9Label = uilabel(app.partition3Panel_3);
            app.BasechordairfoilEditField_9Label.Text = 'Base chord airfoil';
            app.BasechordairfoilEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.noofpanelschordwiseEditField_9Label = uilabel(app.partition3Panel_3);
            app.noofpanelschordwiseEditField_9Label.Text = 'no. of panels chord wise';
            app.noofpanelschordwiseEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.dihedralEditField_9Label = uilabel(app.partition3Panel_3);
            app.dihedralEditField_9Label.Text = 'dihedral';
            app.dihedralEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.pitchEditField_9Label = uilabel(app.partition3Panel_3);
            app.pitchEditField_9Label.Text = 'pitch';
            app.pitchEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.basechordtwistEditField_9Label = uilabel(app.partition3Panel_3);
            app.basechordtwistEditField_9Label.Text = 'base chord twist';
            app.basechordtwistEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.noofpanelssemispanwiseEditField_9Label = uilabel(app.partition3Panel_3);
            app.noofpanelssemispanwiseEditField_9Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.spanEditField_9Label = uilabel(app.partition3Panel_3);
            app.spanEditField_9Label.Text = 'span';
            app.spanEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.taperEditField_9Label = uilabel(app.partition3Panel_3);
            app.taperEditField_9Label.Text = 'taper';
            app.taperEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.tipchordairfoilEditField_9Label = uilabel(app.partition3Panel_3);
            app.tipchordairfoilEditField_9Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.sweepEditField_9Label = uilabel(app.partition3Panel_3);
            app.sweepEditField_9Label.Text = 'sweep';
            app.sweepEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.outboardtwistEditField_9Label = uilabel(app.partition3Panel_3);
            app.outboardtwistEditField_9Label.Text = 'outboard twist';
            app.outboardtwistEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.meshtypeDropDown_9Label = uilabel(app.partition3Panel_3);
            app.meshtypeDropDown_9Label.Text = 'mesh type';
            app.meshtypeDropDown_9 = uidropdown(app.partition3Panel_3);
            app.meshtypeDropDown_9.Items = {'linear', 'span-wise half cosine', 'spanwise half-cosine, chordwise', 'spanwise cosine', 'chordwise cosine'};
            app.meshtypeDropDown_9.Value = 'linear';
            app.radiusofcurvatureEditField_9Label = uilabel(app.partition3Panel_3);
            app.radiusofcurvatureEditField_9Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.curvedDropDown_9Label = uilabel(app.partition3Panel_3);
            app.curvedDropDown_9Label.Text = 'curved';
            app.curvedDropDown_9 = uidropdown(app.partition3Panel_3);
            app.curvedDropDown_9.Items = {'no', 'yes'};
            app.curvedDropDown_9.Value = 'no';
            app.APEXxEditField_3Label = uilabel(app.Foil3Panel);
            app.APEXxEditField_3Label.Text = 'APEX x';
            app.APEXxEditField_3 = uieditfield(app.Foil3Panel, 'numeric');
            app.yEditField_7Label = uilabel(app.Foil3Panel);
            app.yEditField_7Label.Text = 'y';
            app.yEditField_7 = uieditfield(app.Foil3Panel, 'numeric');
            app.zEditField_7Label = uilabel(app.Foil3Panel);
            app.zEditField_7Label.Text = 'z';
            app.zEditField_7 = uieditfield(app.Foil3Panel, 'numeric');
            app.noofpartitionsDropDown_3Label = uilabel(app.Foil3Panel);
            app.noofpartitionsDropDown_3Label.Text = 'no. of partitions';
            app.noofpartitionsDropDown_3 = uidropdown(app.Foil3Panel);
            app.noofpartitionsDropDown_3.Items = {'1', '2', '3'};
            app.noofpartitionsDropDown_3.ValueChangedFcn = createCallbackFcn(app, @noofpartitionsDropDown_3ValueChanged, true);
            app.noofpartitionsDropDown_3.Value = '1';
            app.SaveasEditFieldLabel = uilabel(app.edit_geometry);
            app.SaveasEditFieldLabel.Text = 'Save as:';
            app.SaveasEditField = uieditfield(app.edit_geometry, 'text');
            app.SaveButton = uibutton(app.edit_geometry, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save!';
            app.Label = uilabel(app.edit_geometry);
        end
    end

    methods (Access = public)

        % Construct app
        function app = geometry_edit(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.edit_geometry)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.edit_geometry)
        end
    end
end