classdef geometry_edit3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeometryeditUIFigure            matlab.ui.Figure
        nooffoilsDropDownLabel          matlab.ui.control.Label
        nooffoilsDropDown               matlab.ui.control.DropDown
        Foil1Panel                      matlab.ui.container.Panel
        MirroredCheckBox                matlab.ui.control.CheckBox
        partition1Panel                 matlab.ui.container.Panel
        RootchordEditFieldLabel         matlab.ui.control.Label
        RootchordEditField              matlab.ui.control.EditField
        BasechordairfoilEditFieldLabel  matlab.ui.control.Label
        BasechordairfoilEditField       matlab.ui.control.EditField
        noofpanelschordwiseEditFieldLabel  matlab.ui.control.Label
        noofpanelschordwiseEditField    matlab.ui.control.EditField
        dihedralEditFieldLabel          matlab.ui.control.Label
        dihedralEditField               matlab.ui.control.EditField
        pitchEditFieldLabel             matlab.ui.control.Label
        pitchEditField                  matlab.ui.control.EditField
        basechordtwistEditFieldLabel    matlab.ui.control.Label
        basechordtwistEditField         matlab.ui.control.EditField
        noofpanelssemispanwiseEditFieldLabel  matlab.ui.control.Label
        noofpanelssemispanwiseEditField  matlab.ui.control.EditField
        spanEditFieldLabel              matlab.ui.control.Label
        spanEditField                   matlab.ui.control.EditField
        taperEditFieldLabel             matlab.ui.control.Label
        taperEditField                  matlab.ui.control.EditField
        tipchordairfoilEditFieldLabel   matlab.ui.control.Label
        tipchordairfoilEditField        matlab.ui.control.EditField
        sweepEditFieldLabel             matlab.ui.control.Label
        sweepEditField                  matlab.ui.control.EditField
        outboardtwistEditFieldLabel     matlab.ui.control.Label
        outboardtwistEditField          matlab.ui.control.EditField
        meshtypeDropDownLabel           matlab.ui.control.Label
        meshtypeDropDown                matlab.ui.control.DropDown
        radiusofcurvatureEditFieldLabel  matlab.ui.control.Label
        radiusofcurvatureEditField      matlab.ui.control.EditField
        curvedDropDownLabel             matlab.ui.control.Label
        curvedDropDown                  matlab.ui.control.DropDown
        partition2Panel                 matlab.ui.container.Panel
        dihedralEditField_2Label        matlab.ui.control.Label
        dihedralEditField_2             matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_2Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_2  matlab.ui.control.EditField
        spanEditField_2Label            matlab.ui.control.Label
        spanEditField_2                 matlab.ui.control.EditField
        taperEditField_2Label           matlab.ui.control.Label
        taperEditField_2                matlab.ui.control.EditField
        tipchordairfoilEditField_2Label  matlab.ui.control.Label
        tipchordairfoilEditField_2      matlab.ui.control.EditField
        sweepEditField_2Label           matlab.ui.control.Label
        sweepEditField_2                matlab.ui.control.EditField
        outboardtwistEditField_2Label   matlab.ui.control.Label
        outboardtwistEditField_2        matlab.ui.control.EditField
        meshtypeDropDown_2Label         matlab.ui.control.Label
        meshtypeDropDown_2              matlab.ui.control.DropDown
        radiusofcurvatureEditField_2Label  matlab.ui.control.Label
        radiusofcurvatureEditField_2    matlab.ui.control.EditField
        curvedDropDown_2Label           matlab.ui.control.Label
        curvedDropDown_2                matlab.ui.control.DropDown
        partition3Panel                 matlab.ui.container.Panel
        dihedralEditField_3Label        matlab.ui.control.Label
        dihedralEditField_3             matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_3Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_3  matlab.ui.control.EditField
        spanEditField_3Label            matlab.ui.control.Label
        spanEditField_3                 matlab.ui.control.EditField
        taperEditField_3Label           matlab.ui.control.Label
        taperEditField_3                matlab.ui.control.EditField
        tipchordairfoilEditField_3Label  matlab.ui.control.Label
        tipchordairfoilEditField_3      matlab.ui.control.EditField
        sweepEditField_3Label           matlab.ui.control.Label
        sweepEditField_3                matlab.ui.control.EditField
        outboardtwistEditField_3Label   matlab.ui.control.Label
        outboardtwistEditField_3        matlab.ui.control.EditField
        meshtypeDropDown_3Label         matlab.ui.control.Label
        meshtypeDropDown_3              matlab.ui.control.DropDown
        radiusofcurvatureEditField_3Label  matlab.ui.control.Label
        radiusofcurvatureEditField_3    matlab.ui.control.EditField
        curvedDropDown_3Label           matlab.ui.control.Label
        curvedDropDown_3                matlab.ui.control.DropDown
        APEXxEditFieldLabel             matlab.ui.control.Label
        APEXxEditField                  matlab.ui.control.NumericEditField
        yEditField_3Label               matlab.ui.control.Label
        yEditField_3                    matlab.ui.control.NumericEditField
        zEditField_3Label               matlab.ui.control.Label
        zEditField_3                    matlab.ui.control.NumericEditField
        noofpartitionsDropDownLabel     matlab.ui.control.Label
        noofpartitionsDropDown          matlab.ui.control.DropDown
        massEditFieldLabel              matlab.ui.control.Label
        massEditField                   matlab.ui.control.EditField
        IxxEditFieldLabel               matlab.ui.control.Label
        IxxEditField                    matlab.ui.control.EditField
        IyxEditFieldLabel               matlab.ui.control.Label
        IyxEditField                    matlab.ui.control.EditField
        IzxEditFieldLabel               matlab.ui.control.Label
        IzxEditField                    matlab.ui.control.EditField
        IxyEditFieldLabel               matlab.ui.control.Label
        IxyEditField                    matlab.ui.control.EditField
        IyyEditFieldLabel               matlab.ui.control.Label
        IyyEditField                    matlab.ui.control.EditField
        IzyEditFieldLabel               matlab.ui.control.Label
        IzyEditField                    matlab.ui.control.EditField
        IxzEditFieldLabel               matlab.ui.control.Label
        IxzEditField                    matlab.ui.control.EditField
        IyzEditFieldLabel               matlab.ui.control.Label
        IyzEditField                    matlab.ui.control.EditField
        IzzEditFieldLabel               matlab.ui.control.Label
        IzzEditField                    matlab.ui.control.EditField
        COGxEditFieldLabel              matlab.ui.control.Label
        COGxEditField                   matlab.ui.control.EditField
        yEditField_4Label               matlab.ui.control.Label
        yEditField_4                    matlab.ui.control.EditField
        zEditField_4Label               matlab.ui.control.Label
        zEditField_4                    matlab.ui.control.EditField
        REFxEditFieldLabel              matlab.ui.control.Label
        REFxEditField                   matlab.ui.control.EditField
        yEditField_5Label               matlab.ui.control.Label
        yEditField_5                    matlab.ui.control.EditField
        zEditField_5Label               matlab.ui.control.Label
        zEditField_5                    matlab.ui.control.EditField
        Foil2Panel                      matlab.ui.container.Panel
        MirroredCheckBox_2              matlab.ui.control.CheckBox
        partition1Panel_2               matlab.ui.container.Panel
        RootchordEditField_4Label       matlab.ui.control.Label
        RootchordEditField_4            matlab.ui.control.EditField
        BasechordairfoilEditField_4Label  matlab.ui.control.Label
        BasechordairfoilEditField_4     matlab.ui.control.EditField
        noofpanelschordwiseEditField_4Label  matlab.ui.control.Label
        noofpanelschordwiseEditField_4  matlab.ui.control.EditField
        dihedralEditField_4Label        matlab.ui.control.Label
        dihedralEditField_4             matlab.ui.control.EditField
        pitchEditField_4Label           matlab.ui.control.Label
        pitchEditField_4                matlab.ui.control.EditField
        basechordtwistEditField_4Label  matlab.ui.control.Label
        basechordtwistEditField_4       matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_4Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_4  matlab.ui.control.EditField
        spanEditField_4Label            matlab.ui.control.Label
        spanEditField_4                 matlab.ui.control.EditField
        taperEditField_4Label           matlab.ui.control.Label
        taperEditField_4                matlab.ui.control.EditField
        tipchordairfoilEditField_4Label  matlab.ui.control.Label
        tipchordairfoilEditField_4      matlab.ui.control.EditField
        sweepEditField_4Label           matlab.ui.control.Label
        sweepEditField_4                matlab.ui.control.EditField
        outboardtwistEditField_4Label   matlab.ui.control.Label
        outboardtwistEditField_4        matlab.ui.control.EditField
        meshtypeDropDown_4Label         matlab.ui.control.Label
        meshtypeDropDown_4              matlab.ui.control.DropDown
        radiusofcurvatureEditField_4Label  matlab.ui.control.Label
        radiusofcurvatureEditField_4    matlab.ui.control.EditField
        curvedDropDown_4Label           matlab.ui.control.Label
        curvedDropDown_4                matlab.ui.control.DropDown
        partition2Panel_2               matlab.ui.container.Panel
        RootchordEditField_5Label       matlab.ui.control.Label
        RootchordEditField_5            matlab.ui.control.EditField
        BasechordairfoilEditField_5Label  matlab.ui.control.Label
        BasechordairfoilEditField_5     matlab.ui.control.EditField
        noofpanelschordwiseEditField_5Label  matlab.ui.control.Label
        noofpanelschordwiseEditField_5  matlab.ui.control.EditField
        dihedralEditField_5Label        matlab.ui.control.Label
        dihedralEditField_5             matlab.ui.control.EditField
        pitchEditField_5Label           matlab.ui.control.Label
        pitchEditField_5                matlab.ui.control.EditField
        basechordtwistEditField_5Label  matlab.ui.control.Label
        basechordtwistEditField_5       matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_5Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_5  matlab.ui.control.EditField
        spanEditField_5Label            matlab.ui.control.Label
        spanEditField_5                 matlab.ui.control.EditField
        taperEditField_5Label           matlab.ui.control.Label
        taperEditField_5                matlab.ui.control.EditField
        tipchordairfoilEditField_5Label  matlab.ui.control.Label
        tipchordairfoilEditField_5      matlab.ui.control.EditField
        sweepEditField_5Label           matlab.ui.control.Label
        sweepEditField_5                matlab.ui.control.EditField
        outboardtwistEditField_5Label   matlab.ui.control.Label
        outboardtwistEditField_5        matlab.ui.control.EditField
        meshtypeDropDown_5Label         matlab.ui.control.Label
        meshtypeDropDown_5              matlab.ui.control.DropDown
        radiusofcurvatureEditField_5Label  matlab.ui.control.Label
        radiusofcurvatureEditField_5    matlab.ui.control.EditField
        curvedDropDown_5Label           matlab.ui.control.Label
        curvedDropDown_5                matlab.ui.control.DropDown
        partition3Panel_2               matlab.ui.container.Panel
        RootchordEditField_6Label       matlab.ui.control.Label
        RootchordEditField_6            matlab.ui.control.EditField
        BasechordairfoilEditField_6Label  matlab.ui.control.Label
        BasechordairfoilEditField_6     matlab.ui.control.EditField
        noofpanelschordwiseEditField_6Label  matlab.ui.control.Label
        noofpanelschordwiseEditField_6  matlab.ui.control.EditField
        dihedralEditField_6Label        matlab.ui.control.Label
        dihedralEditField_6             matlab.ui.control.EditField
        pitchEditField_6Label           matlab.ui.control.Label
        pitchEditField_6                matlab.ui.control.EditField
        basechordtwistEditField_6Label  matlab.ui.control.Label
        basechordtwistEditField_6       matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_6Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_6  matlab.ui.control.EditField
        spanEditField_6Label            matlab.ui.control.Label
        spanEditField_6                 matlab.ui.control.EditField
        taperEditField_6Label           matlab.ui.control.Label
        taperEditField_6                matlab.ui.control.EditField
        tipchordairfoilEditField_6Label  matlab.ui.control.Label
        tipchordairfoilEditField_6      matlab.ui.control.EditField
        sweepEditField_6Label           matlab.ui.control.Label
        sweepEditField_6                matlab.ui.control.EditField
        outboardtwistEditField_6Label   matlab.ui.control.Label
        outboardtwistEditField_6        matlab.ui.control.EditField
        meshtypeDropDown_6Label         matlab.ui.control.Label
        meshtypeDropDown_6              matlab.ui.control.DropDown
        radiusofcurvatureEditField_6Label  matlab.ui.control.Label
        radiusofcurvatureEditField_6    matlab.ui.control.EditField
        curvedDropDown_6Label           matlab.ui.control.Label
        curvedDropDown_6                matlab.ui.control.DropDown
        APEXxEditField_2Label           matlab.ui.control.Label
        APEXxEditField_2                matlab.ui.control.NumericEditField
        yEditField_6Label               matlab.ui.control.Label
        yEditField_6                    matlab.ui.control.NumericEditField
        zEditField_6Label               matlab.ui.control.Label
        zEditField_6                    matlab.ui.control.NumericEditField
        noofpartitionsDropDown_2Label   matlab.ui.control.Label
        noofpartitionsDropDown_2        matlab.ui.control.DropDown
        Foil3Panel                      matlab.ui.container.Panel
        MirroredCheckBox_3              matlab.ui.control.CheckBox
        partition1Panel_3               matlab.ui.container.Panel
        RootchordEditField_7Label       matlab.ui.control.Label
        RootchordEditField_7            matlab.ui.control.EditField
        BasechordairfoilEditField_7Label  matlab.ui.control.Label
        BasechordairfoilEditField_7     matlab.ui.control.EditField
        noofpanelschordwiseEditField_7Label  matlab.ui.control.Label
        noofpanelschordwiseEditField_7  matlab.ui.control.EditField
        dihedralEditField_7Label        matlab.ui.control.Label
        dihedralEditField_7             matlab.ui.control.EditField
        pitchEditField_7Label           matlab.ui.control.Label
        pitchEditField_7                matlab.ui.control.EditField
        basechordtwistEditField_7Label  matlab.ui.control.Label
        basechordtwistEditField_7       matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_7Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_7  matlab.ui.control.EditField
        spanEditField_7Label            matlab.ui.control.Label
        spanEditField_7                 matlab.ui.control.EditField
        taperEditField_7Label           matlab.ui.control.Label
        taperEditField_7                matlab.ui.control.EditField
        tipchordairfoilEditField_7Label  matlab.ui.control.Label
        tipchordairfoilEditField_7      matlab.ui.control.EditField
        sweepEditField_7Label           matlab.ui.control.Label
        sweepEditField_7                matlab.ui.control.EditField
        outboardtwistEditField_7Label   matlab.ui.control.Label
        outboardtwistEditField_7        matlab.ui.control.EditField
        meshtypeDropDown_7Label         matlab.ui.control.Label
        meshtypeDropDown_7              matlab.ui.control.DropDown
        radiusofcurvatureEditField_7Label  matlab.ui.control.Label
        radiusofcurvatureEditField_7    matlab.ui.control.EditField
        curvedDropDown_7Label           matlab.ui.control.Label
        curvedDropDown_7                matlab.ui.control.DropDown
        partition2Panel_3               matlab.ui.container.Panel
        RootchordEditField_8Label       matlab.ui.control.Label
        RootchordEditField_8            matlab.ui.control.EditField
        BasechordairfoilEditField_8Label  matlab.ui.control.Label
        BasechordairfoilEditField_8     matlab.ui.control.EditField
        noofpanelschordwiseEditField_8Label  matlab.ui.control.Label
        noofpanelschordwiseEditField_8  matlab.ui.control.EditField
        dihedralEditField_8Label        matlab.ui.control.Label
        dihedralEditField_8             matlab.ui.control.EditField
        pitchEditField_8Label           matlab.ui.control.Label
        pitchEditField_8                matlab.ui.control.EditField
        basechordtwistEditField_8Label  matlab.ui.control.Label
        basechordtwistEditField_8       matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_8Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_8  matlab.ui.control.EditField
        spanEditField_8Label            matlab.ui.control.Label
        spanEditField_8                 matlab.ui.control.EditField
        taperEditField_8Label           matlab.ui.control.Label
        taperEditField_8                matlab.ui.control.EditField
        tipchordairfoilEditField_8Label  matlab.ui.control.Label
        tipchordairfoilEditField_8      matlab.ui.control.EditField
        sweepEditField_8Label           matlab.ui.control.Label
        sweepEditField_8                matlab.ui.control.EditField
        outboardtwistEditField_8Label   matlab.ui.control.Label
        outboardtwistEditField_8        matlab.ui.control.EditField
        meshtypeDropDown_8Label         matlab.ui.control.Label
        meshtypeDropDown_8              matlab.ui.control.DropDown
        radiusofcurvatureEditField_8Label  matlab.ui.control.Label
        radiusofcurvatureEditField_8    matlab.ui.control.EditField
        curvedDropDown_8Label           matlab.ui.control.Label
        curvedDropDown_8                matlab.ui.control.DropDown
        partition3Panel_3               matlab.ui.container.Panel
        RootchordEditField_9Label       matlab.ui.control.Label
        RootchordEditField_9            matlab.ui.control.EditField
        BasechordairfoilEditField_9Label  matlab.ui.control.Label
        BasechordairfoilEditField_9     matlab.ui.control.EditField
        noofpanelschordwiseEditField_9Label  matlab.ui.control.Label
        noofpanelschordwiseEditField_9  matlab.ui.control.EditField
        dihedralEditField_9Label        matlab.ui.control.Label
        dihedralEditField_9             matlab.ui.control.EditField
        pitchEditField_9Label           matlab.ui.control.Label
        pitchEditField_9                matlab.ui.control.EditField
        basechordtwistEditField_9Label  matlab.ui.control.Label
        basechordtwistEditField_9       matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_9Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_9  matlab.ui.control.EditField
        spanEditField_9Label            matlab.ui.control.Label
        spanEditField_9                 matlab.ui.control.EditField
        taperEditField_9Label           matlab.ui.control.Label
        taperEditField_9                matlab.ui.control.EditField
        tipchordairfoilEditField_9Label  matlab.ui.control.Label
        tipchordairfoilEditField_9      matlab.ui.control.EditField
        sweepEditField_9Label           matlab.ui.control.Label
        sweepEditField_9                matlab.ui.control.EditField
        outboardtwistEditField_9Label   matlab.ui.control.Label
        outboardtwistEditField_9        matlab.ui.control.EditField
        meshtypeDropDown_9Label         matlab.ui.control.Label
        meshtypeDropDown_9              matlab.ui.control.DropDown
        radiusofcurvatureEditField_9Label  matlab.ui.control.Label
        radiusofcurvatureEditField_9    matlab.ui.control.EditField
        curvedDropDown_9Label           matlab.ui.control.Label
        curvedDropDown_9                matlab.ui.control.DropDown
        APEXxEditField_3Label           matlab.ui.control.Label
        APEXxEditField_3                matlab.ui.control.NumericEditField
        yEditField_7Label               matlab.ui.control.Label
        yEditField_7                    matlab.ui.control.NumericEditField
        zEditField_7Label               matlab.ui.control.Label
        zEditField_7                    matlab.ui.control.NumericEditField
        noofpartitionsDropDown_3Label   matlab.ui.control.Label
        noofpartitionsDropDown_3        matlab.ui.control.DropDown
        SaveasEditFieldLabel            matlab.ui.control.Label
        SaveasEditField                 matlab.ui.control.EditField
        SaveButton                      matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Foil4Panel                      matlab.ui.container.Panel
        MirroredCheckBox_4              matlab.ui.control.CheckBox
        partition1Panel_4               matlab.ui.container.Panel
        RootchordEditField_10Label      matlab.ui.control.Label
        RootchordEditField_10           matlab.ui.control.EditField
        BasechordairfoilEditField_10Label  matlab.ui.control.Label
        BasechordairfoilEditField_10    matlab.ui.control.EditField
        noofpanelschordwiseEditField_10Label  matlab.ui.control.Label
        noofpanelschordwiseEditField_10  matlab.ui.control.EditField
        dihedralEditField_10Label       matlab.ui.control.Label
        dihedralEditField_10            matlab.ui.control.EditField
        pitchEditField_10Label          matlab.ui.control.Label
        pitchEditField_10               matlab.ui.control.EditField
        basechordtwistEditField_10Label  matlab.ui.control.Label
        basechordtwistEditField_10      matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_10Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_10  matlab.ui.control.EditField
        spanEditField_10Label           matlab.ui.control.Label
        spanEditField_10                matlab.ui.control.EditField
        taperEditField_10Label          matlab.ui.control.Label
        taperEditField_10               matlab.ui.control.EditField
        tipchordairfoilEditField_10Label  matlab.ui.control.Label
        tipchordairfoilEditField_10     matlab.ui.control.EditField
        sweepEditField_10Label          matlab.ui.control.Label
        sweepEditField_10               matlab.ui.control.EditField
        outboardtwistEditField_10Label  matlab.ui.control.Label
        outboardtwistEditField_10       matlab.ui.control.EditField
        meshtypeDropDown_10Label        matlab.ui.control.Label
        meshtypeDropDown_10             matlab.ui.control.DropDown
        radiusofcurvatureEditField_10Label  matlab.ui.control.Label
        radiusofcurvatureEditField_10   matlab.ui.control.EditField
        curvedDropDown_10Label          matlab.ui.control.Label
        curvedDropDown_10               matlab.ui.control.DropDown
        partition2Panel_4               matlab.ui.container.Panel
        dihedralEditField_11Label       matlab.ui.control.Label
        dihedralEditField_11            matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_11Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_11  matlab.ui.control.EditField
        spanEditField_11Label           matlab.ui.control.Label
        spanEditField_11                matlab.ui.control.EditField
        taperEditField_11Label          matlab.ui.control.Label
        taperEditField_11               matlab.ui.control.EditField
        tipchordairfoilEditField_11Label  matlab.ui.control.Label
        tipchordairfoilEditField_11     matlab.ui.control.EditField
        sweepEditField_11Label          matlab.ui.control.Label
        sweepEditField_11               matlab.ui.control.EditField
        outboardtwistEditField_11Label  matlab.ui.control.Label
        outboardtwistEditField_11       matlab.ui.control.EditField
        meshtypeDropDown_11Label        matlab.ui.control.Label
        meshtypeDropDown_11             matlab.ui.control.DropDown
        radiusofcurvatureEditField_11Label  matlab.ui.control.Label
        radiusofcurvatureEditField_11   matlab.ui.control.EditField
        curvedDropDown_11Label          matlab.ui.control.Label
        curvedDropDown_11               matlab.ui.control.DropDown
        partition3Panel_4               matlab.ui.container.Panel
        dihedralEditField_12Label       matlab.ui.control.Label
        dihedralEditField_12            matlab.ui.control.EditField
        noofpanelssemispanwiseEditField_12Label  matlab.ui.control.Label
        noofpanelssemispanwiseEditField_12  matlab.ui.control.EditField
        spanEditField_12Label           matlab.ui.control.Label
        spanEditField_12                matlab.ui.control.EditField
        taperEditField_12Label          matlab.ui.control.Label
        taperEditField_12               matlab.ui.control.EditField
        tipchordairfoilEditField_12Label  matlab.ui.control.Label
        tipchordairfoilEditField_12     matlab.ui.control.EditField
        sweepEditField_12Label          matlab.ui.control.Label
        sweepEditField_12               matlab.ui.control.EditField
        outboardtwistEditField_12Label  matlab.ui.control.Label
        outboardtwistEditField_12       matlab.ui.control.EditField
        meshtypeDropDown_12Label        matlab.ui.control.Label
        meshtypeDropDown_12             matlab.ui.control.DropDown
        radiusofcurvatureEditField_12Label  matlab.ui.control.Label
        radiusofcurvatureEditField_12   matlab.ui.control.EditField
        curvedDropDown_12Label          matlab.ui.control.Label
        curvedDropDown_12               matlab.ui.control.DropDown
        APEXxEditField_4Label           matlab.ui.control.Label
        APEXxEditField_4                matlab.ui.control.NumericEditField
        yEditField_8Label               matlab.ui.control.Label
        yEditField_8                    matlab.ui.control.NumericEditField
        zEditField_8Label               matlab.ui.control.Label
        zEditField_8                    matlab.ui.control.NumericEditField
        noofpartitionsDropDown_4Label   matlab.ui.control.Label
        noofpartitionsDropDown_4        matlab.ui.control.DropDown
        kgLabel                         matlab.ui.control.Label
        kgm2Label                       matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp; % Description
        geo;
        state;
        lattice;
        ref;
        settings;
    end
    
    methods (Access = private)
        
        function results = update(app)
           app.massEditField.Value = num2str(app.geo.mass);
           
           app.IxxEditField.Value = num2str(app.geo.I(1,1));
           app.IxyEditField.Value = num2str(app.geo.I(1,2));
           app.IxzEditField.Value = num2str(app.geo.I(1,3));
           app.IyxEditField.Value = num2str(app.geo.I(2,1));
           app.IyyEditField.Value = num2str(app.geo.I(2,2));
           app.IyzEditField.Value = num2str(app.geo.I(2,3));
           app.IzxEditField.Value = num2str(app.geo.I(3,1));
           app.IzyEditField.Value = num2str(app.geo.I(3,2));
           app.IzzEditField.Value = num2str(app.geo.I(3,3));
           
           app.nooffoilsDropDown.Value = num2str(app.geo.nwing);
           
           app.COGxEditField.Value = num2str(app.geo.CG(1));
           app.yEditField_4.Value = num2str(app.geo.CG(2));
           app.zEditField_4.Value = num2str(app.geo.CG(3));
           
           app.REFxEditField.Value = num2str(app.geo.ref_point(1));
           app.yEditField_5.Value = num2str(app.geo.ref_point(2));
           app.zEditField_5.Value = num2str(app.geo.ref_point(3));
           
           nooffoils(app);
           
           %%foil 1
           if (app.geo.nwing >= 1)
           app.Foil1Panel.Visible = 'on';
           app.Foil2Panel.Visible = 'off';
           app.Foil3Panel.Visible = 'off';     
           app.Foil4Panel.Visible = 'off';
               
           app.MirroredCheckBox.Value = app.geo.symetric(1);
           
           app.noofpartitionsDropDown.Value = num2str(app.geo.nelem(1));
           
           app.APEXxEditField.Value = (app.geo.startx(1));
           app.yEditField_3.Value = (app.geo.starty(1));
           app.zEditField_3.Value = (app.geo.startz(1));
           
               %%partition 1
               if (app.geo.nelem(1) >= 1)
               app.partition1Panel.Visible = 'on';
               app.partition2Panel.Visible = 'off';
               app.partition3Panel.Visible = 'off';    
                   
               app.RootchordEditField.Value = num2str(app.geo.c(1));
               
               app.BasechordairfoilEditField.Value = string(app.geo.foil(1,1,1));
               
               app.noofpanelschordwiseEditField.Value = num2str(app.geo.nx(1,1));
               
               app.dihedralEditField.Value = num2str(app.geo.dihed(1,1)*180/pi);
               
               app.pitchEditField.Value = num2str(app.geo.foiltheta(1)*180/pi);
               
               app.basechordtwistEditField.Value = num2str(app.geo.BTW(1)*180/pi);
               
               app.noofpanelssemispanwiseEditField.Value = num2str(app.geo.ny(1,1));
               
               app.spanEditField.Value = num2str(app.geo.b(1,1));
               
               app.taperEditField.Value = num2str(app.geo.T(1,1));
               
               app.tipchordairfoilEditField.Value = string(app.geo.foil(1,1,2));
               
               app.sweepEditField.Value = num2str(app.geo.SW(1,1)*180/pi);
               
               app.outboardtwistEditField.Value = num2str(app.geo.TW(1,1,2)*180/pi);
               
               app.meshtypeDropDown.Value = num2str(app.geo.meshtype(1,1));
               
               v1 = app.geo.curved(1,1);
               switch v1
                   case 1
                        app.curvedDropDown.Value = 'yes';
                        app.radiusofcurvatureEditField.Visible = 'on';
                        app.radiusofcurvatureEditField.Value = num2str(app.geio.radius(1,1));
                   case 0
                        app.curvedDropDown.Value = 'no';
                        app.radiusofcurvatureEditField.Visible = 'off';
               end
               end
               %%partition 2
               if (app.geo.nelem(1)>=2)
               app.partition1Panel.Visible = 'on';
               app.partition2Panel.Visible = 'on';
               app.partition3Panel.Visible = 'off';  
               
%                app.RootchordEditField.Value = num2str(app.geo.c(1,1));
%                
%                app.BasechordairfoilEditField.Value = num2str(app.geo.foil(1,1,1));
%                
%                app.noofpanelschordwiseEditField.Value = num2str(app.geo.nx(1,1));
               
               app.dihedralEditField_2.Value = num2str(app.geo.dihed(1,2)*180/pi);
               
%                app.pitchEditField.Value = num2str(app.geo.foiltheta(1));
               
%                app.basechordtwistEditField.Value = num2str(app.geo.BTW(1));
               
               app.noofpanelssemispanwiseEditField_2.Value = num2str(app.geo.ny(1,2));
               
               app.spanEditField_2.Value = num2str(app.geo.b(1,2));
               
               app.taperEditField_2.Value = num2str(app.geo.T(1,2));
               
               app.tipchordairfoilEditField_2.Value = string(app.geo.foil(1,2,2));
               
               app.sweepEditField_2.Value = num2str(app.geo.SW(1,2)*180/pi);
               
               app.outboardtwistEditField_2.Value = num2str(app.geo.TW(1,2,2)*180/pi);
               
               app.meshtypeDropDown_2.Value = num2str(app.geo.meshtype(1,2));
               
               v1 = app.geo.curved(1,2);
               switch v1
                   case 1
                        app.curvedDropDown_2.Value = 'yes';
                        app.radiusofcurvatureEditField_2.Visible = 'on';
                        app.radiusofcurvatureEditField_2.Value = num2str(app.geio.radius(1,2));
                   case 0
                        app.curvedDropDown_2.Value = 'no';
                        app.radiusofcurvatureEditField_2.Visible = 'off';
               end
               end
               %partition 3
               if (app.geo.nelem(1)>=3)
               app.partition1Panel.Visible = 'on';
               app.partition2Panel.Visible = 'on';
               app.partition3Panel.Visible = 'on';  
               
%                app.RootchordEditField.Value = num2str(app.geo.c(1,1));
%                
%                app.BasechordairfoilEditField.Value = num2str(app.geo.foil(1,1,1));
%                
%                app.noofpanelschordwiseEditField.Value = num2str(app.geo.nx(1,1));
               
               app.dihedralEditField_3.Value = num2str(app.geo.dihed(1,3)*180/pi);
               
%                app.pitchEditField.Value = num2str(app.geo.foiltheta(1));
               
%                app.basechordtwistEditField.Value = num2str(app.geo.BTW(1));
               
               app.noofpanelssemispanwiseEditField_3.Value = num2str(app.geo.ny(1,3));
               
               app.spanEditField_3.Value = num2str(app.geo.b(1,3));
               
               app.taperEditField_3.Value = num2str(app.geo.T(1,3));
               
               app.tipchordairfoilEditField_3.Value = string(app.geo.foil(1,3,2));
               
               app.sweepEditField_3.Value = num2str(app.geo.SW(1,3)*180/pi);
               
               app.outboardtwistEditField_3.Value = num2str(app.geo.TW(1,3,2)*180/pi);
               
               app.meshtypeDropDown_3.Value = num2str(app.geo.meshtype(1,3));
               
               v1 = app.geo.curved(1,3);
               switch v1
                   case 1
                        app.curvedDropDown_3.Value = 'yes';
                        app.radiusofcurvatureEditField_3.Visible = 'on';
                        app.radiusofcurvatureEditField_3.Value = num2str(app.geio.radius(1,3));
                   case 0
                        app.curvedDropDown_3.Value = 'no';
                        app.radiusofcurvatureEditField_3.Visible = 'off';
               end
               end
           end
           %%foil 2
           if (app.geo.nwing >=2)
               app.Foil1Panel.Visible = 'on';
               app.Foil2Panel.Visible = 'on';
               app.Foil3Panel.Visible = 'off';  
               app.Foil4Panel.Visible = 'off';
               
               app.MirroredCheckBox_2.Value = app.geo.symetric(2);
           
               app.noofpartitionsDropDown_2.Value = num2str(app.geo.nelem(2));
           
               app.APEXxEditField_2.Value = (app.geo.startx(2));
               app.yEditField_6.Value = (app.geo.starty(2));
               app.zEditField_6.Value = (app.geo.startz(2));
               %%partition 1
               if (app.geo.nelem(2)>=1)
               app.partition1Panel_2.Visible = 'on';
               app.partition2Panel_2.Visible = 'off';
               app.partition3Panel_2.Visible = 'off';    
                   
               app.RootchordEditField_4.Value = num2str(app.geo.c(2));
               
               app.BasechordairfoilEditField_4.Value = string(app.geo.foil(2,1,1));
               
               app.noofpanelschordwiseEditField_4.Value = num2str(app.geo.nx(2,1));
               
               app.dihedralEditField_4.Value = num2str(app.geo.dihed(2,1)*180/pi);
               
               app.pitchEditField_4.Value = num2str(app.geo.foiltheta(2)*180/pi);
               
               app.basechordtwistEditField_4.Value = num2str(app.geo.BTW(2)*180/pi);
               
               app.noofpanelssemispanwiseEditField_4.Value = num2str(app.geo.ny(2,1));
               
               app.spanEditField_4.Value = num2str(app.geo.b(2,1));
               
               app.taperEditField_4.Value = num2str(app.geo.T(2,1));
               
               app.tipchordairfoilEditField_4.Value = string(app.geo.foil(2,1,2));
               
               app.sweepEditField_4.Value = num2str(app.geo.SW(2,1)*180/pi);
               
               app.outboardtwistEditField_4.Value = num2str(app.geo.TW(2,1,2)*180/pi);
               
               app.meshtypeDropDown_4.Value = num2str(app.geo.meshtype(2,1));
               
               v1 = app.geo.curved(2,1);
               switch v1
                   case 1
                        app.curvedDropDown_4.Value = 'yes';
                        app.radiusofcurvatureEditField_4.Visible = 'on';
                        app.radiusofcurvatureEditField_4.Value = num2str(app.geio.radius(2,1));
                   case 0
                        app.curvedDropDown_4.Value = 'no';
                        app.radiusofcurvatureEditField_4.Visible = 'off';
               end
               end
               %%partition 2
               if (app.geo.nelem(2)>=2)
               app.partition1Panel_2.Visible = 'on';
               app.partition2Panel_2.Visible = 'on';
               app.partition3Panel_2.Visible = 'off';  
               
%                app.RootchordEditField.Value = num2str(app.geo.c(1,1));
%                
%                app.BasechordairfoilEditField.Value = num2str(app.geo.foil(1,1,1));
%                
%                app.noofpanelschordwiseEditField.Value = num2str(app.geo.nx(1,1));
               
               app.dihedralEditField_5.Value = num2str(app.geo.dihed(2,2)*180/pi);
               
%                app.pitchEditField.Value = num2str(app.geo.foiltheta(1));
               
%                app.basechordtwistEditField.Value = num2str(app.geo.BTW(1));
               
               app.noofpanelssemispanwiseEditField_5.Value = num2str(app.geo.ny(2,2));
               
               app.spanEditField_5.Value = num2str(app.geo.b(2,2));
               
               app.taperEditField_5.Value = num2str(app.geo.T(2,2));
               
               app.tipchordairfoilEditField_5.Value = string(app.geo.foil(2,2,2));
               
               app.sweepEditField_5.Value = num2str(app.geo.SW(2,2)*180/pi);
               
               app.outboardtwistEditField_5.Value = num2str(app.geo.TW(2,2,2)*180/pi);
               
               app.meshtypeDropDown_5.Value = num2str(app.geo.meshtype(2,2));
               
               v1 = app.geo.curved(2,2);
               switch v1
                   case 1
                        app.curvedDropDown_5.Value = 'yes';
                        app.radiusofcurvatureEditField_5.Visible = 'on';
                        app.radiusofcurvatureEditField_5.Value = num2str(app.geio.radius(2,2));
                   case 0
                        app.curvedDropDown_5.Value = 'no';
                        app.radiusofcurvatureEditField_5.Visible = 'off';
               end
               end
               %partition 3
               if (app.geo.nelem(2)>=3)
               app.partition1Panel_2.Visible = 'on';
               app.partition2Panel_2.Visible = 'on';
               app.partition3Panel_2.Visible = 'on';  
               
%                app.RootchordEditField.Value = num2str(app.geo.c(1,1));
%                
%                app.BasechordairfoilEditField.Value = num2str(app.geo.foil(1,1,1));
%                
%                app.noofpanelschordwiseEditField.Value = num2str(app.geo.nx(1,1));
               
               app.dihedralEditField_6.Value = num2str(app.geo.dihed(2,3)*180/pi);
               
%                app.pitchEditField.Value = num2str(app.geo.foiltheta(1));
               
%                app.basechordtwistEditField.Value = num2str(app.geo.BTW(1));
               
               app.noofpanelssemispanwiseEditField_6.Value = num2str(app.geo.ny(2,3));
               
               app.spanEditField_6.Value = num2str(app.geo.b(2,3));
               
               app.taperEditField_6.Value = num2str(app.geo.T(2,3));
               
               app.tipchordairfoilEditField_6.Value = string(app.geo.foil(2,3,2));
               
               app.sweepEditField_6.Value = num2str(app.geo.SW(2,3)*180/pi);
               
               app.outboardtwistEditField_6.Value = num2str(app.geo.TW(2,3,2)*180/pi);
               
               app.meshtypeDropDown_6.Value = num2str(app.geo.meshtype(2,3));
               
               v1 = app.geo.curved(2,3);
               switch v1
                   case 1
                        app.curvedDropDown_6.Value = 'yes';
                        app.radiusofcurvatureEditField_6.Visible = 'on';
                        app.radiusofcurvatureEditField_6.Value = num2str(app.geio.radius(2,3));
                   case 0
                        app.curvedDropDown_6.Value = 'no';
                        app.radiusofcurvatureEditField_6.Visible = 'off';
               end    
               end
           end
           %%foil 3
           if (app.geo.nwing >= 3)
               app.Foil1Panel.Visible = 'on';
               app.Foil2Panel.Visible = 'on';
               app.Foil3Panel.Visible = 'on';
               app.Foil4Panel.Visible = 'off';
               
               app.MirroredCheckBox_3.Value = app.geo.symetric(3);
           
               app.noofpartitionsDropDown_3.Value = num2str(app.geo.nelem(3));
           
               app.APEXxEditField_3.Value = (app.geo.startx(3));
               app.yEditField_7.Value = (app.geo.starty(3));
               app.zEditField_7.Value = (app.geo.startz(3));
               
               %%partition 1
               if (app.geo.nelem(3)>=1)
               app.partition1Panel_3.Visible = 'on';
               app.partition2Panel_3.Visible = 'off';
               app.partition3Panel_3.Visible = 'off';  
               
               app.RootchordEditField_7.Value = num2str(app.geo.c(3));
                
               app.BasechordairfoilEditField_7.Value = string(app.geo.foil(3,1,1));
               
               app.noofpanelschordwiseEditField_7.Value = num2str(app.geo.nx(3,1));
              
               app.dihedralEditField_7.Value = num2str(app.geo.dihed(3,1)*180/pi);
               
               app.pitchEditField_7.Value = num2str(app.geo.foiltheta(3)*180/pi);
             
               app.basechordtwistEditField_7.Value = num2str(app.geo.BTW(3)*180/pi);
               
               app.noofpanelssemispanwiseEditField_7.Value = num2str(app.geo.ny(3,1));
               
               app.spanEditField_7.Value = num2str(app.geo.b(3,1));
               
               app.taperEditField_7.Value = num2str(app.geo.T(3,1));
               
               app.tipchordairfoilEditField_7.Value = string(app.geo.foil(3,1,2));
               
               app.sweepEditField_7.Value = num2str(app.geo.SW(3,1)*180/pi);
               
               app.outboardtwistEditField_7.Value = num2str(app.geo.TW(3,1,2)*180/pi);
               
               app.meshtypeDropDown_7.Value = num2str(app.geo.meshtype(3,1));
               
               v1 = app.geo.curved(3,1);
               switch v1
                   case 1
                        app.curvedDropDown_7.Value = 'yes';
                        app.radiusofcurvatureEditField_7.Visible = 'on';
                        app.radiusofcurvatureEditField_7.Value = num2str(app.geio.radius(3,1));
                   case 0
                        app.curvedDropDown_7.Value = 'no';
                        app.radiusofcurvatureEditField_7.Visible = 'off';
               end 
               end
               %%partition 2
               if (app.geo.nelem(3)>=2)
               app.partition1Panel_3.Visible = 'on';
               app.partition2Panel_3.Visible = 'on';
               app.partition3Panel_3.Visible = 'off';  
               
%                app.RootchordEditField_7.Value = num2str(app.geo.c(3,1));
%                 
%                app.BasechordairfoilEditField_7.Value = num2str(app.geo.foil(3,1,1));
%                
%                app.noofpanelschordwiseEditField_7.Value = num2str(app.geo.nx(3,1));
              
               app.dihedralEditField_8.Value = num2str(app.geo.dihed(3,2)*180/pi);
               
%                app.pitchEditField_7.Value = num2str(app.geo.foiltheta(3));
%              
%                app.basechordtwistEditField_7.Value = num2str(app.geo.BTW(3));
               
               app.noofpanelssemispanwiseEditField_8.Value = num2str(app.geo.ny(3,2));
               
               app.spanEditField_8.Value = num2str(app.geo.b(3,2));
               
               app.taperEditField_8.Value = num2str(app.geo.T(3,2));
               
               app.tipchordairfoilEditField_8.Value = string(app.geo.foil(3,2,2));
               
               app.sweepEditField_8.Value = num2str(app.geo.SW(3,2)*180/pi);
               
               app.outboardtwistEditField_8.Value = num2str(app.geo.TW(3,2,2)*180/pi);
               
               app.meshtypeDropDown_8.Value = num2str(app.geo.meshtype(3,2));
               
               v1 = app.geo.curved(3,2);
               switch v1
                   case 1
                        app.curvedDropDown_8.Value = 'yes';
                        app.radiusofcurvatureEditField_8.Visible = 'on';
                        app.radiusofcurvatureEditField_8.Value = num2str(app.geo.radius(3,2));
                   case 0
                        app.curvedDropDown_8.Value = 'no';
                        app.radiusofcurvatureEditField_8.Visible = 'off';
               end 
               end
               %partition 3
               if (app.geo.nelem(3)>=3)
               app.partition1Panel_3.Visible = 'on';
               app.partition2Panel_3.Visible = 'on';
               app.partition3Panel_3.Visible = 'on'; 
               
%                app.RootchordEditField_7.Value = num2str(app.geo.c(3,1));
%                 
%                app.BasechordairfoilEditField_7.Value = num2str(app.geo.foil(3,1,1));
%                
%                app.noofpanelschordwiseEditField_7.Value = num2str(app.geo.nx(3,1));
              
               app.dihedralEditField_9.Value = num2str(app.geo.dihed(3,3)*180/pi);
               
%                app.pitchEditField_7.Value = num2str(app.geo.foiltheta(3));
%              
%                app.basechordtwistEditField_7.Value = num2str(app.geo.BTW(3));
               
               app.noofpanelssemispanwiseEditField_9.Value = num2str(app.geo.ny(3,3));
               
               app.spanEditField_9.Value = num2str(app.geo.b(3,3));
               
               app.taperEditField_9.Value = num2str(app.geo.T(3,3));
               
               app.tipchordairfoilEditField_9.Value = string(app.geo.foil(3,3,2));
               
               app.sweepEditField_9.Value = num2str(app.geo.SW(3,3)*180/pi);
               
               app.outboardtwistEditField_9.Value = num2str(app.geo.TW(3,3,2)*180/pi);
               
               app.meshtypeDropDown_9.Value = num2str(app.geo.meshtype(3,3));
               
               v1 = app.geo.curved(3,3);
               switch v1
                   case 1
                        app.curvedDropDown_9.Value = 'yes';
                        app.radiusofcurvatureEditField_9.Visible = 'on';
                        app.radiusofcurvatureEditField_9.Value = num2str(app.geio.radius(3,3));
                   case 0
                        app.curvedDropDown_9.Value = 'no';
                        app.radiusofcurvatureEditField_9.Visible = 'off';
               end     
               end
           end
           %%foil 4
           if (app.geo.nwing >= 4)
               app.Foil1Panel.Visible = 'on';
               app.Foil2Panel.Visible = 'on';
               app.Foil3Panel.Visible = 'on';
               app.Foil4Panel.Visible = 'on';
               
               app.MirroredCheckBox_4.Value = app.geo.symetric(4);
           
               app.noofpartitionsDropDown_4.Value = num2str(app.geo.nelem(4));
           
               app.APEXxEditField_4.Value = (app.geo.startx(4));
               app.yEditField_8.Value = (app.geo.starty(4));
               app.zEditField_8.Value = (app.geo.startz(4));
               
               %%partition 1
               if (app.geo.nelem(4)>=1)
               app.partition1Panel_4.Visible = 'on';
               app.partition2Panel_4.Visible = 'off';
               app.partition3Panel_4.Visible = 'off';  
               
               app.RootchordEditField_10.Value = num2str(app.geo.c(4));
                
               app.BasechordairfoilEditField_10.Value = string(app.geo.foil(4,1,1));
               
               app.noofpanelschordwiseEditField_10.Value = num2str(app.geo.nx(4,1));
              
               app.dihedralEditField_10.Value = num2str(app.geo.dihed(4,1)*180/pi);
               
               app.pitchEditField_10.Value = num2str(app.geo.foiltheta(4)*180/pi);
             
               app.basechordtwistEditField_10.Value = num2str(app.geo.BTW(4)*180/pi);
               
               app.noofpanelssemispanwiseEditField_10.Value = num2str(app.geo.ny(4,1));
               
               app.spanEditField_10.Value = num2str(app.geo.b(4,1));
               
               app.taperEditField_10.Value = num2str(app.geo.T(4,1));
               
               app.tipchordairfoilEditField_10.Value = string(app.geo.foil(4,1,2));
               
               app.sweepEditField_10.Value = num2str(app.geo.SW(4,1)*180/pi);
               
               app.outboardtwistEditField_10.Value = num2str(app.geo.TW(4,1,2)*180/pi);
               
               app.meshtypeDropDown_10.Value = num2str(app.geo.meshtype(4,1));
               
               v1 = app.geo.curved(4,1);
               switch v1
                   case 1
                        app.curvedDropDown_10.Value = 'yes';
                        app.radiusofcurvatureEditField_10.Visible = 'on';
                        app.radiusofcurvatureEditField_10.Value = num2str(app.geo.radius(4,1));
                   case 0
                        app.curvedDropDown_10.Value = 'no';
                        app.radiusofcurvatureEditField_10.Visible = 'off';
               end 
               end
               %%partition 2
               if (app.geo.nelem(4)>=2)
               app.partition1Panel_4.Visible = 'on';
               app.partition2Panel_4.Visible = 'on';
               app.partition3Panel_4.Visible = 'off';  
               
%                app.RootchordEditField_7.Value = num2str(app.geo.c(3,1));
%                 
%                app.BasechordairfoilEditField_7.Value = num2str(app.geo.foil(3,1,1));
%                
%                app.noofpanelschordwiseEditField_7.Value = num2str(app.geo.nx(3,1));
              
               app.dihedralEditField_11.Value = num2str(app.geo.dihed(4,2)*180/pi);
               
%                app.pitchEditField_7.Value = num2str(app.geo.foiltheta(3));
%              
%                app.basechordtwistEditField_7.Value = num2str(app.geo.BTW(3));
               
               app.noofpanelssemispanwiseEditField_11.Value = num2str(app.geo.ny(4,2));
               
               app.spanEditField_11.Value = num2str(app.geo.b(4,2));
               
               app.taperEditField_11.Value = num2str(app.geo.T(4,2));
               
               app.tipchordairfoilEditField_11.Value = string(app.geo.foil(4,2,2));
               
               app.sweepEditField_11.Value = num2str(app.geo.SW(4,2)*180/pi);
               
               app.outboardtwistEditField_11.Value = num2str(app.geo.TW(4,2,2)*180/pi);
               
               app.meshtypeDropDown_11.Value = num2str(app.geo.meshtype(4,2));
               
               v1 = app.geo.curved(4,2);
               switch v1
                   case 1
                        app.curvedDropDown_11.Value = 'yes';
                        app.radiusofcurvatureEditField_11.Visible = 'on';
                        app.radiusofcurvatureEditField_11.Value = num2str(app.geo.radius(4,2));
                   case 0
                        app.curvedDropDown_11.Value = 'no';
                        app.radiusofcurvatureEditField_11.Visible = 'off';
               end 
               end
               %partition 3
               if (app.geo.nelem(4)>=3)
               app.partition1Panel_4.Visible = 'on';
               app.partition2Panel_4.Visible = 'on';
               app.partition3Panel_4.Visible = 'on'; 
               
%                app.RootchordEditField_7.Value = num2str(app.geo.c(3,1));
%                 
%                app.BasechordairfoilEditField_7.Value = num2str(app.geo.foil(3,1,1));
%                
%                app.noofpanelschordwiseEditField_7.Value = num2str(app.geo.nx(3,1));
              
               app.dihedralEditField_12.Value = num2str(app.geo.dihed(4,3)*180/pi);
               
%                app.pitchEditField_7.Value = num2str(app.geo.foiltheta(3));
%              
%                app.basechordtwistEditField_7.Value = num2str(app.geo.BTW(3));
               
               app.noofpanelssemispanwiseEditField_12.Value = num2str(app.geo.ny(4,3));
               
               app.spanEditField_12.Value = num2str(app.geo.b(4,3));
               
               app.taperEditField_12.Value = num2str(app.geo.T(4,3));
               
               app.tipchordairfoilEditField_12.Value = string(app.geo.foil(4,3,2));
               
               app.sweepEditField_12.Value = num2str(app.geo.SW(4,3)*180/pi);
               
               app.outboardtwistEditField_12.Value = num2str(app.geo.TW(4,3,2)*180/pi);
               
               app.meshtypeDropDown_12.Value = num2str(app.geo.meshtype(4,3));
               
               v1 = app.geo.curved(4,3);
               switch v1
                   case 1
                        app.curvedDropDown_12.Value = 'yes';
                        app.radiusofcurvatureEditField_12.Visible = 'on';
                        app.radiusofcurvatureEditField_12.Value = num2str(app.geio.radius(4,3));
                   case 0
                        app.curvedDropDown_12.Value = 'no';
                        app.radiusofcurvatureEditField_12.Visible = 'off';
               end     
               end
           end
           
           
        end
        
        function results = nooffoils(app)
            value = app.nooffoilsDropDown.Value;
            switch value
                case "1"
                    app.Foil1Panel.Visible = 'on';
                    app.Foil2Panel.Visible = 'off';
                    app.Foil3Panel.Visible = 'off';
                    app.Foil4Panel.Visible = 'off';
                    app.geo.nwing = str2double(value);
                    
                case "2"
                    app.Foil1Panel.Visible = 'on';
                    app.Foil2Panel.Visible = 'on';
                    app.Foil3Panel.Visible = 'off';
                    app.Foil4Panel.Visible = 'off';
                    app.geo.nwing = str2double(value);
                    
                case "3"
                    app.Foil1Panel.Visible = 'on';
                    app.Foil2Panel.Visible = 'on';
                    app.Foil3Panel.Visible = 'on';
                    app.Foil4Panel.Visible = 'off';
                    app.geo.nwing = str2double(value);
                case "4"
                    app.Foil1Panel.Visible = 'on';
                    app.Foil2Panel.Visible = 'on';
                    app.Foil3Panel.Visible = 'on';
                    app.Foil4Panel.Visible = 'on';
                    app.geo.nwing = str2double(value);
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, geo, state, lattice, ref, settings)
            app.CallingApp = mainapp;
            app.geo = geo;
            app.state = state;
            app.lattice = lattice;
            app.ref = ref;
            app.settings = settings;
            
            update(app);
        end

        % Value changed function: nooffoilsDropDown
        function nooffoilsDropDownValueChanged(app, event)
            nooffoils(app);
        end

        % Value changed function: noofpartitionsDropDown
        function noofpartitionsDropDownValueChanged(app, event)
            value = app.noofpartitionsDropDown.Value;
            switch value
                case "1"
                    app.partition1Panel.Visible = 'on';
                    app.partition2Panel.Visible = 'off';
                    app.partition3Panel.Visible = 'off';
                    app.geo.nelem(1)=str2double(value);
                    app.geo.curved(1,1) = 0;
                    
                case "2"
                    app.partition1Panel.Visible = 'on';
                    app.partition2Panel.Visible = 'on';
                    app.partition3Panel.Visible = 'off';
                    app.geo.nelem(1)=str2double(value);
                    app.geo.curved(1,1) = 0;
                    app.geo.curved(1,2) = 0;
                case "3"
                    app.partition1Panel.Visible = 'on';
                    app.partition2Panel.Visible = 'on';
                    app.partition3Panel.Visible = 'on';
                    app.geo.nelem(1)=str2double(value);
                    
                    app.geo.curved(1,1) = 0;
                    app.geo.curved(1,2) = 0;
            end
        end

        % Value changed function: noofpartitionsDropDown_2
        function noofpartitionsDropDown_2ValueChanged(app, event)
            value = app.noofpartitionsDropDown_2.Value;
            switch value
                case "1"
                    app.partition1Panel_2.Visible = 'on';
                    app.partition2Panel_2.Visible = 'off';
                    app.partition3Panel_2.Visible = 'off';
                    app.geo.nelem(2)=str2double(value);
                    app.geo.curved(2,1) = 0;
                    
                case "2"
                    app.partition1Panel_2.Visible = 'on';
                    app.partition2Panel_2.Visible = 'on';
                    app.partition3Panel_2.Visible = 'off';
                    app.geo.nelem(2)=str2double(value);
                    app.geo.curved(2,1) = 0;
                    app.geo.curved(2,2) = 0;
                case "3"
                    app.partition1Panel_2.Visible = 'on';
                    app.partition2Panel_2.Visible = 'on';
                    app.partition3Panel_2.Visible = 'on';
                    app.geo.nelem(2)=str2double(value);
                    app.geo.curved(2,1) = 0;
                    app.geo.curved(2,2) = 0;
            end
        end

        % Value changed function: noofpartitionsDropDown_3
        function noofpartitionsDropDown_3ValueChanged(app, event)
            value = app.noofpartitionsDropDown_3.Value;
            switch value
                case "1"
                    app.partition1Panel_3.Visible = 'on';
                    app.partition2Panel_3.Visible = 'off';
                    app.partition3Panel_3.Visible = 'off';
                    app.geo.nelem(3)=str2double(value);
                    app.geo.curved(3,1) = 0;
                    
                case "2"
                    app.partition1Panel_3.Visible = 'on';
                    app.partition2Panel_3.Visible = 'on';
                    app.partition3Panel_3.Visible = 'off';
                    app.geo.nelem(3)=str2double(value);
                    app.geo.curved(3,1) = 0;
                    app.geo.curved(3,2) = 0;
                case "3"
                    app.partition1Panel_3.Visible = 'on';
                    app.partition2Panel_3.Visible = 'on';
                    app.partition3Panel_3.Visible = 'on';
                    app.geo.nelem(3)=str2double(value);
                    app.geo.curved(3,1) = 0;
                    app.geo.curved(3,2) = 0;
            end
        end

        % Value changed function: massEditField
        function massEditFieldValueChanged(app, event)
            value = app.massEditField.Value;
            app.geo.mass = str2double(value);
        end

        % Value changed function: IxxEditField
        function IxxEditFieldValueChanged(app, event)
            value = app.IxxEditField.Value;
            app.geo.I(1,1) = str2double(value);
        end

        % Value changed function: IyxEditField
        function IyxEditFieldValueChanged(app, event)
            value = app.IyxEditField.Value;
            app.geo.I(2,1) = str2double(value);
        end

        % Value changed function: IzxEditField
        function IzxEditFieldValueChanged(app, event)
            value = app.IzxEditField.Value;
            app.geo.I(3,1) = str2double(value);
        end

        % Value changed function: IxyEditField
        function IxyEditFieldValueChanged(app, event)
            value = app.IxyEditField.Value;
            app.geo.I(1,2) = str2double(value);
        end

        % Value changed function: IyyEditField
        function IyyEditFieldValueChanged(app, event)
            value = app.IyyEditField.Value;
            app.geo.I(2,2) = str2double(value);
        end

        % Value changed function: IzyEditField
        function IzyEditFieldValueChanged(app, event)
            value = app.IzyEditField.Value;
            app.geo.I(3,2) = str2double(value);
        end

        % Value changed function: IxzEditField
        function IxzEditFieldValueChanged(app, event)
            value = app.IxzEditField.Value;
            app.geo.I(1,3) = str2double(value);
        end

        % Value changed function: IyzEditField
        function IyzEditFieldValueChanged(app, event)
            value = app.IyzEditField.Value;
            app.geo.I(2,3) = str2double(value);
        end

        % Value changed function: IzzEditField
        function IzzEditFieldValueChanged(app, event)
            value = app.IzzEditField.Value;
            app.geo.I(3,3) = str2double(value);
        end

        % Value changed function: COGxEditField
        function COGxEditFieldValueChanged(app, event)
            value = app.COGxEditField.Value;
            app.geo.CG(1) = str2double(value);
        end

        % Value changed function: yEditField_4
        function yEditField_4ValueChanged(app, event)
            value = app.yEditField_4.Value;
            app.geo.CG(2) = str2double(value);
        end

        % Value changed function: zEditField_4
        function zEditField_4ValueChanged(app, event)
            value = app.zEditField_4.Value;
            app.geo.CG(3) = str2double(value);
        end

        % Value changed function: REFxEditField
        function REFxEditFieldValueChanged(app, event)
            value = app.REFxEditField.Value;
            app.geo.ref_point(1) = str2double(value);
        end

        % Value changed function: yEditField_5
        function yEditField_5ValueChanged(app, event)
            value = app.yEditField_5.Value;
            app.geo.ref_point(2) = str2double(value);
        end

        % Value changed function: zEditField_5
        function zEditField_5ValueChanged(app, event)
            value = app.zEditField_5.Value;
            app.geo.ref_point(3) = str2double(value);
        end

        % Value changed function: MirroredCheckBox
        function MirroredCheckBoxValueChanged(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % begin foil 1
            value = app.MirroredCheckBox.Value;
            app.geo.symetric(1) = value;
        end

        % Value changed function: APEXxEditField
        function APEXxEditFieldValueChanged(app, event)
            value = app.APEXxEditField.Value;
            app.geo.startx = value;
        end

        % Value changed function: yEditField_3
        function yEditField_3ValueChanged(app, event)
            value = app.yEditField_3.Value;
            app.geo.starty = value;
        end

        % Value changed function: zEditField_3
        function zEditField_3ValueChanged(app, event)
            value = app.zEditField_3.Value;
            app.geo.startz = value;
        end

        % Value changed function: RootchordEditField
        function RootchordEditFieldValueChanged(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            value = app.RootchordEditField.Value;
            app.geo.c(1) = str2double(value);
        end

        % Value changed function: BasechordairfoilEditField
        function BasechordairfoilEditFieldValueChanged(app, event)
            value = app.BasechordairfoilEditField.Value;
            app.geo.foil(1,1,1) = {value};
        end

        % Value changed function: noofpanelschordwiseEditField
        function noofpanelschordwiseEditFieldValueChanged(app, event)
            value = app.noofpanelschordwiseEditField.Value;
            app.geo.nx(1,1) = str2double(value);
            
            switch (app.noofpartitionsDropDown.Value)
                case "1"
                    
                case "2"
                    app.geo.nx(1,2)=str2double(value);
                case "3"
                    app.geo.nx(1,2)=str2double(value);
                    app.geo.nx(1,3)=str2double(value);
            end
                   
        end

        % Value changed function: dihedralEditField
        function dihedralEditFieldValueChanged(app, event)
            value = app.dihedralEditField.Value;
            app.geo.dihed(1,1) = str2double(value)*pi/180;
        end

        % Value changed function: pitchEditField
        function pitchEditFieldValueChanged(app, event)
            value = app.pitchEditField.Value;
            app.geo.foiltheta(1,1) = str2double(value)*pi/180;
        end

        % Value changed function: basechordtwistEditField
        function basechordtwistEditFieldValueChanged(app, event)
            value = app.basechordtwistEditField.Value;
            app.geo.TW(1,1,1) = str2double(value)*pi/180;
            app.geo.BTW(1) = str2double(value)*pi/180;
        end

        % Value changed function: noofpanelssemispanwiseEditField
        function noofpanelssemispanwiseEditFieldValueChanged(app, event)
            value = app.noofpanelssemispanwiseEditField.Value;
            app.geo.ny(1,1) = str2double(value);
        end

        % Value changed function: spanEditField
        function spanEditFieldValueChanged(app, event)
            value = app.spanEditField.Value;
            app.geo.b(1,1) = str2double(value);
        end

        % Value changed function: taperEditField
        function taperEditFieldValueChanged(app, event)
            value = app.taperEditField.Value;
            app.geo.T(1,1) = str2double(value);
        end

        % Value changed function: tipchordairfoilEditField
        function tipchordairfoilEditFieldValueChanged(app, event)
            value = app.tipchordairfoilEditField.Value;
            app.geo.foil(1,1,2) = {value};
        end

        % Value changed function: sweepEditField
        function sweepEditFieldValueChanged(app, event)
            value = app.sweepEditField.Value;
            app.geo.SW(1,1) = str2double(value)*pi/180;
        end

        % Value changed function: outboardtwistEditField
        function outboardtwistEditFieldValueChanged(app, event)
            value = app.outboardtwistEditField.Value;
            app.geo.TW(1,1,2) = str2double(value)*pi/180;
        end

        % Value changed function: meshtypeDropDown
        function meshtypeDropDownValueChanged(app, event)
            value = app.meshtypeDropDown.Value;
            app.geo.meshtype(1,1) = str2double(value);
        end

        % Value changed function: curvedDropDown
        function curvedDropDownValueChanged(app, event)
            value = app.curvedDropDown.Value;
            switch value
                case 'yes'
                    app.geo.curved(1,1) = 1;
                    app.radiusofcurvatureEditField.Visible = 'on';
                case 'no'
                    app.geo.curved(1,1) = 0;
                    app.radiusofcurvatureEditField.Visible = 'off';
            end
        end

        % Value changed function: dihedralEditField_2
        function dihedralEditField_2ValueChanged(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            value = app.dihedralEditField_2.Value;
            if app.geo.curved(1,1)==0
                app.geo.dihed(1,2) = app.geo.dihed(1,1) + str2num(value)*pi/180;
            else
                app.geo.dihed(1,2) = app.geo.dihed(1,1)-app.geo.b(1,1)/app.geo.radius(1,1)+str2num(value)*pi/180;
            end
            
        end

        % Value changed function: noofpanelssemispanwiseEditField_2
        function noofpanelssemispanwiseEditField_2ValueChanged(app, event)
            value = app.noofpanelssemispanwiseEditField_2.Value;
            app.geo.ny(1,2) = str2double(value);
        end

        % Value changed function: spanEditField_2
        function spanEditField_2ValueChanged(app, event)
            value = app.spanEditField_2.Value;
            app.geo.b(1,2) = str2double(value);
        end

        % Value changed function: taperEditField_2
        function taperEditField_2ValueChanged(app, event)
            value = app.taperEditField_2.Value;
            app.geo.T(1,2) = str2double(value);
        end

        % Value changed function: tipchordairfoilEditField_2
        function tipchordairfoilEditField_2ValueChanged(app, event)
            value = app.tipchordairfoilEditField_2.Value;
            app.geo.foil(1,2,2) = {value};
            app.geo.foil(1,2,1) = app.geo.foil(1,1,2);
        end

        % Value changed function: sweepEditField_2
        function sweepEditField_2ValueChanged(app, event)
            value = app.sweepEditField_2.Value;
            app.geo.SW(1,2) = str2double(value);
        end

        % Value changed function: outboardtwistEditField_2
        function outboardtwistEditField_2ValueChanged(app, event)
            value = app.outboardtwistEditField_2.Value;
            app.geo.TW(1,2,2) = str2double(value);
        end

        % Value changed function: meshtypeDropDown_2
        function meshtypeDropDown_2ValueChanged(app, event)
            value = app.meshtypeDropDown_2.Value;
            app.geo.meshtype(1,2) = str2double(value);
        end

        % Value changed function: curvedDropDown_2
        function curvedDropDown_2ValueChanged(app, event)
            value = app.curvedDropDown_2.Value;
            switch value
                case 'yes'
                    app.geo.curved(1,2) = 1;
                    app.radiusofcurvatureEditField_2.Visible = 'on';
                case 'no'
                    app.geo.curved(1,2) = 0;
                    app.radiusofcurvatureEditField_2.Visible = 'off';
            end
        end

        % Value changed function: radiusofcurvatureEditField
        function radiusofcurvatureEditFieldValueChanged(app, event)
            value = app.radiusofcurvatureEditField.Value;
            app.geo.radius(1,1) = str2double(value);
        end

        % Value changed function: radiusofcurvatureEditField_2
        function radiusofcurvatureEditField_2ValueChanged(app, event)
            value = app.radiusofcurvatureEditField_2.Value;
            app.geo.radius(1,2) = str2double(value);
        end

        % Value changed function: dihedralEditField_3
        function dihedralEditField_3ValueChanged(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            value = app.dihedralEditField_3.Value;
            if app.geo.curved(1,2)==0
                app.geo.dihed(1,3) = app.geo.dihed(1,2) + str2num(value)*pi/180;
            else
                app.geo.dihed(1,3) = app.geo.dihed(1,2)-app.geo.b(1,2)/app.geo.radius(1,2)+str2num(value)*pi/180;
            end
        end

        % Value changed function: noofpanelssemispanwiseEditField_3
        function noofpanelssemispanwiseEditField_3ValueChanged(app, event)
            value = app.noofpanelssemispanwiseEditField_3.Value;
            app.geo.ny(1,3) = str2double(value);
        end

        % Value changed function: spanEditField_3
        function spanEditField_3ValueChanged(app, event)
            value = app.spanEditField_3.Value;
            app.geo.b(1,3) = str2double(value);
        end

        % Value changed function: taperEditField_3
        function taperEditField_3ValueChanged(app, event)
            value = app.taperEditField_3.Value;
            app.geo.T(1,3) = str2double(value);
        end

        % Value changed function: tipchordairfoilEditField_3
        function tipchordairfoilEditField_3ValueChanged(app, event)
            value = app.tipchordairfoilEditField_3.Value;
            app.geo.foil(1,3,2) = {value};
            app.geo.foil(1,3,1) = app.geo.foil(1,2,2);
        end

        % Value changed function: sweepEditField_3
        function sweepEditField_3ValueChanged(app, event)
            value = app.sweepEditField_3.Value;
            app.geo.SW(1,3) = str2double(value)*pi/180;
        end

        % Value changed function: outboardtwistEditField_3
        function outboardtwistEditField_3ValueChanged(app, event)
            value = app.outboardtwistEditField_3.Value;
            app.geo.TW(1,3,2) = str2double(value)*pi/180;
        end

        % Value changed function: meshtypeDropDown_3
        function meshtypeDropDown_3ValueChanged(app, event)
            value = app.meshtypeDropDown_3.Value;
            app.geo.meshtype(1,3) = str2double(value); 
        end

        % Value changed function: curvedDropDown_3
        function curvedDropDown_3ValueChanged(app, event)
            value = app.curvedDropDown_3.Value;
            switch value
                case 'yes'
                    app.geo.curved(1,3) = 1;
                    app.radiusofcurvatureEditField_3.Visible = 'on';
                case 'no'
                    app.geo.curved(1,3) = 0;
                    app.radiusofcurvatureEditField_3.Visible = 'off';
            end
        end

        % Value changed function: radiusofcurvatureEditField_3
        function radiusofcurvatureEditField_3ValueChanged(app, event)
            value = app.radiusofcurvatureEditField_3.Value;
            app.geo.radius(1,3) = str2double(value);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            cd(app.settings.acdir)
            
            fname=app.SaveasEditField.Value;
            if isempty(fname)==1
                app.Label.Visible = 'on';
                app.Label.Text = '+++ no name, not saved +++';
                cd(app.settings.hdir)
            else
                try
                    geo = app.geo;
                    save(fname,'geo');
                    cd(app.settings.hdir)
                    
                    update_geo(app.CallingApp,app.geo);
                    app.Label.Visible = 'on';
                    app.Label.Text = '+++ geometry saved +++';
                catch
                    app.Label.Visible = 'on';
                    app.Label.Text = '+++ no geometry, not saved +++';
                    cd(app.settings.hdir)
                end
            end
                
        end

        % Value changed function: MirroredCheckBox_2
        function MirroredCheckBox_2ValueChanged(app, event)
            value = app.MirroredCheckBox_2.Value;
            app.geo.symetric(2) = value;
        end

        % Value changed function: APEXxEditField_2
        function APEXxEditField_2ValueChanged(app, event)
            value = app.APEXxEditField_2.Value;
            app.geo.startx(2) = value;
        end

        % Value changed function: yEditField_6
        function yEditField_6ValueChanged(app, event)
            value = app.yEditField_6.Value;
            app.geo.starty(2) = value;
        end

        % Value changed function: zEditField_6
        function zEditField_6ValueChanged(app, event)
            value = app.zEditField_6.Value;
            app.geo.startz(2) = value;
        end

        % Value changed function: RootchordEditField_4
        function RootchordEditField_4ValueChanged(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            value = app.RootchordEditField_4.Value;
            app.geo.c(2) = str2double(value);
        end

        % Value changed function: BasechordairfoilEditField_4
        function BasechordairfoilEditField_4ValueChanged(app, event)
            value = app.BasechordairfoilEditField_4.Value;
            app.geo.foil(2,1,1) = {value};
        end

        % Value changed function: noofpanelschordwiseEditField_4
        function noofpanelschordwiseEditField_4ValueChanged(app, event)
            value = app.noofpanelschordwiseEditField_4.Value;
            app.geo.nx(2,1) = str2double(value);
            
            switch (app.noofpartitionsDropDown_2.Value)
                case "1"
                    
                case "2"
                    app.geo.nx(2,2)=str2double(value);
                case "3"
                    app.geo.nx(2,2)=str2double(value);
                    app.geo.nx(2,3)=str2double(value);
            end
        end

        % Value changed function: dihedralEditField_4
        function dihedralEditField_4ValueChanged(app, event)
            value = app.dihedralEditField_4.Value;
            app.geo.dihed(2,1) = str2double(value)*pi/180;
        end

        % Value changed function: pitchEditField_4
        function pitchEditField_4ValueChanged(app, event)
            value = app.pitchEditField_4.Value;
            app.geo.foiltheta(2,1) = str2double(value)*pi/180;
        end

        % Value changed function: basechordtwistEditField_4
        function basechordtwistEditField_4ValueChanged(app, event)
            value = app.basechordtwistEditField_4.Value;
            app.geo.TW(2,1,1) = str2double(value)*pi/180;
            app.geo.BTW(2) = str2double(value)*pi/180;
        end

        % Value changed function: noofpanelssemispanwiseEditField_4
        function noofpanelssemispanwiseEditField_4ValueChanged(app, event)
            value = app.noofpanelssemispanwiseEditField_4.Value;
            app.geo.ny(2,1) = str2double(value);
        end

        % Value changed function: spanEditField_4
        function spanEditField_4ValueChanged(app, event)
            value = app.spanEditField_4.Value;
             app.geo.b(2,1) = str2double(value);
        end

        % Value changing function: taperEditField_4
        function taperEditField_4ValueChanging(app, event)
            changingValue = event.Value;
            app.geo.T(2,1) = str2double(value);
        end

        % Value changed function: tipchordairfoilEditField_4
        function tipchordairfoilEditField_4ValueChanged(app, event)
            value = app.tipchordairfoilEditField_4.Value;
            app.geo.foil(2,1,2) = {value};
        end

        % Value changed function: sweepEditField_4
        function sweepEditField_4ValueChanged(app, event)
            value = app.sweepEditField_4.Value;
            app.geo.SW(2,1) = str2double(value)*pi/180;
        end

        % Value changed function: outboardtwistEditField_4
        function outboardtwistEditField_4ValueChanged(app, event)
            value = app.outboardtwistEditField_4.Value;
            app.geo.TW(2,1,2) = str2double(value)*pi/180;
        end

        % Value changed function: meshtypeDropDown_4
        function meshtypeDropDown_4ValueChanged(app, event)
            value = app.meshtypeDropDown_4.Value;
            app.geo.meshtype(2,1) = str2double(value);
        end

        % Value changed function: curvedDropDown_4
        function curvedDropDown_4ValueChanged(app, event)
            value = app.curvedDropDown_4.Value;
            switch value
                case 'yes'
                    app.geo.curved(2,1) = 1;
                    app.radiusofcurvatureEditField_4.Visible = 'on';
                case 'no'
                    app.geo.curved(2,1) = 0;
                    app.radiusofcurvatureEditField_4.Visible = 'off';
            end
        end

        % Value changed function: radiusofcurvatureEditField_4
        function radiusofcurvatureEditField_4ValueChanged(app, event)
            value = app.radiusofcurvatureEditField_4.Value;
            app.geo.radius(2,1) = str2double(value);
        end

        % Value changed function: dihedralEditField_5
        function dihedralEditField_5ValueChanged(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            value = app.dihedralEditField_5.Value;
            if app.geo.curved(2,1)==0
                app.geo.dihed(2,2) = app.geo.dihed(2,2) + str2num(value)*pi/180;
            else
                app.geo.dihed(2,2) = app.geo.dihed(2,2)-app.geo.b(2,2)/app.geo.radius(2,2)+str2num(value)*pi/180;
            end
        end

        % Value changed function: noofpanelssemispanwiseEditField_5
        function noofpanelssemispanwiseEditField_5ValueChanged(app, event)
            value = app.noofpanelssemispanwiseEditField_5.Value;
            app.geo.ny(2,2) = str2double(value);
        end

        % Value changed function: spanEditField_5
        function spanEditField_5ValueChanged(app, event)
            value = app.spanEditField_5.Value;
            app.geo.b(2,2) = str2double(value);
        end

        % Value changed function: taperEditField_5
        function taperEditField_5ValueChanged(app, event)
            value = app.taperEditField_5.Value;
            app.geo.T(2,2) = str2double(value);
        end

        % Value changed function: tipchordairfoilEditField_5
        function tipchordairfoilEditField_5ValueChanged(app, event)
            value = app.tipchordairfoilEditField_5.Value;
            app.geo.foil(2,2,2) = {value};
            app.geo.foil(2,2,1) = app.geo.foil(2,1,2);
        end

        % Value changed function: sweepEditField_5
        function sweepEditField_5ValueChanged(app, event)
            value = app.sweepEditField_5.Value;
            app.geo.SW(2,2) = str2double(value);
        end

        % Value changed function: outboardtwistEditField_5
        function outboardtwistEditField_5ValueChanged(app, event)
            value = app.outboardtwistEditField_5.Value;
            app.geo.TW(2,2,2) = str2double(value);
        end

        % Value changed function: meshtypeDropDown_5
        function meshtypeDropDown_5ValueChanged(app, event)
            value = app.meshtypeDropDown_5.Value;
            app.geo.meshtype(2,2) = str2double(value);
        end

        % Value changed function: curvedDropDown_5
        function curvedDropDown_5ValueChanged(app, event)
            
            value = app.curvedDropDown_5.Value;
            switch value
                case 'yes'
                    app.geo.curved(2,2) = 1;
                    app.radiusofcurvatureEditField_5.Visible = 'on';
                case 'no'
                    app.geo.curved(2,2) = 0;
                    app.radiusofcurvatureEditField_5.Visible = 'off';
            end
        end

        % Value changed function: radiusofcurvatureEditField_5
        function radiusofcurvatureEditField_5ValueChanged(app, event)
            value = app.radiusofcurvatureEditField_5.Value;
            app.geo.radius(2,1) = str2double(value);
        end

        % Value changed function: dihedralEditField_6
        function dihedralEditField_6ValueChanged(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            value = app.dihedralEditField_6.Value;
            if app.geo.curved(2,2)==0
                app.geo.dihed(2,3) = app.geo.dihed(2,2) + str2num(value)*pi/180;
            else
                app.geo.dihed(2,3) = app.geo.dihed(2,2)-app.geo.b(2,2)/app.geo.radius(2,2)+str2num(value)*pi/180;
            end
        end

        % Value changed function: noofpanelssemispanwiseEditField_6
        function noofpanelssemispanwiseEditField_6ValueChanged(app, event)
            value = app.noofpanelssemispanwiseEditField_6.Value;
            app.geo.ny(2,3) = str2double(value);
        end

        % Value changed function: spanEditField_6
        function spanEditField_6ValueChanged(app, event)
            value = app.spanEditField_6.Value;
            app.geo.b(2,3) = str2double(value);
        end

        % Value changed function: taperEditField_6
        function taperEditField_6ValueChanged(app, event)
            value = app.taperEditField_6.Value;
            app.geo.T(2,3) = str2double(value);
        end

        % Value changed function: tipchordairfoilEditField_6
        function tipchordairfoilEditField_6ValueChanged(app, event)
            value = app.tipchordairfoilEditField_6.Value;
            app.geo.foil(2,3,2) = {value};
            app.geo.foil(2,3,1) = app.geo.foil(2,2,2);
        end

        % Value changed function: sweepEditField_6
        function sweepEditField_6ValueChanged(app, event)
            value = app.sweepEditField_6.Value;
            app.geo.SW(2,3) = str2double(value)*pi/180;
        end

        % Value changed function: outboardtwistEditField_6
        function outboardtwistEditField_6ValueChanged(app, event)
            value = app.outboardtwistEditField_6.Value;
            app.geo.TW(2,3,2) = str2double(value)*pi/180;
        end

        % Value changed function: meshtypeDropDown_6
        function meshtypeDropDown_6ValueChanged(app, event)
            value = app.meshtypeDropDown_6.Value;
            app.geo.meshtype(2,3) = str2double(value); 
        end

        % Value changed function: curvedDropDown_6
        function curvedDropDown_6ValueChanged(app, event)
            value = app.curvedDropDown_6.Value;
            switch value
                case 'yes'
                    app.geo.curved(2,3) = 1;
                    app.radiusofcurvatureEditField_6.Visible = 'on';
                case 'no'
                    app.geo.curved(2,3) = 0;
                    app.radiusofcurvatureEditField_6.Visible = 'off';
            end
        end

        % Value changed function: radiusofcurvatureEditField_6
        function radiusofcurvatureEditField_6ValueChanged(app, event)
            value = app.radiusofcurvatureEditField_6.Value;
            app.geo.radius(2,3) = str2double(value);
        end

        % Value changed function: MirroredCheckBox_3
        function MirroredCheckBox_3ValueChanged(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            value = app.MirroredCheckBox_3.Value;
            app.geo.symetric(3) = value;
        end

        % Value changed function: APEXxEditField_3
        function APEXxEditField_3ValueChanged(app, event)
            value = app.APEXxEditField_3.Value;
            app.geo.startx(3) = value;
        end

        % Value changed function: yEditField_7
        function yEditField_7ValueChanged(app, event)
            value = app.yEditField_7.Value;
            app.geo.starty(3) = value;
        end

        % Value changed function: zEditField_7
        function zEditField_7ValueChanged(app, event)
            value = app.zEditField_7.Value;
            app.geo.startz(3) = value;
        end

        % Value changed function: RootchordEditField_7
        function RootchordEditField_7ValueChanged(app, event)
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            value = app.RootchordEditField_7.Value;
            app.geo.c(3) = str2double(value);
        end

        % Value changed function: BasechordairfoilEditField_7
        function BasechordairfoilEditField_7ValueChanged(app, event)
            value = app.BasechordairfoilEditField_7.Value;
            app.geo.foil(3,1,1) = {value};
        end

        % Value changed function: noofpanelschordwiseEditField_7
        function noofpanelschordwiseEditField_7ValueChanged(app, event)
            value = app.noofpanelschordwiseEditField_7.Value;
            app.geo.nx(3,1) = str2double(value);
            
            switch (app.noofpartitionsDropDown_3.Value)
                case "1"
                    
                case "2"
                    app.geo.nx(3,2)=str2double(value);
                case "3"
                    app.geo.nx(3,2)=str2double(value);
                    app.geo.nx(3,3)=str2double(value);
            end
        end

        % Value changed function: dihedralEditField_7
        function dihedralEditField_7ValueChanged(app, event)
            value = app.dihedralEditField_7.Value;
            app.geo.dihed(3,1) = str2double(value)*pi/180;
        end

        % Value changed function: pitchEditField_7
        function pitchEditField_7ValueChanged(app, event)
            value = app.pitchEditField_7.Value;
            app.geo.foiltheta(3,1) = str2double(value)*pi/180;
        end

        % Value changed function: basechordtwistEditField_7
        function basechordtwistEditField_7ValueChanged(app, event)
            value = app.basechordtwistEditField_7.Value;
            app.geo.TW(3,1,1) = str2double(value)*pi/180;
            app.geo.BTW(3) = str2double(value)*pi/180;
        end

        % Value changed function: noofpanelssemispanwiseEditField_7
        function noofpanelssemispanwiseEditField_7ValueChanged(app, event)
            value = app.noofpanelssemispanwiseEditField_7.Value;
            app.geo.ny(3,1) = str2double(value);
        end

        % Value changed function: spanEditField_7
        function spanEditField_7ValueChanged(app, event)
            value = app.spanEditField_7.Value;
            app.geo.b(3,1) = str2double(value);
        end

        % Value changed function: taperEditField_7
        function taperEditField_7ValueChanged(app, event)
            value = app.taperEditField_7.Value;
            app.geo.T(3,1) = str2double(value);
        end

        % Value changed function: tipchordairfoilEditField_7
        function tipchordairfoilEditField_7ValueChanged(app, event)
            value = app.tipchordairfoilEditField_7.Value;
            app.geo.foil(3,1,2) = {value};
        end

        % Value changed function: sweepEditField_7
        function sweepEditField_7ValueChanged(app, event)
            value = app.sweepEditField_7.Value;
            app.geo.SW(3,1) = str2double(value)*pi/180;
        end

        % Value changed function: outboardtwistEditField_7
        function outboardtwistEditField_7ValueChanged(app, event)
            value = app.outboardtwistEditField_7.Value;
            app.geo.TW(3,1,2) = str2double(value)*pi/180;
        end

        % Value changed function: meshtypeDropDown_7
        function meshtypeDropDown_7ValueChanged(app, event)
            value = app.meshtypeDropDown_7.Value;
            app.geo.meshtype(3,1) = str2double(value);
        end

        % Value changed function: curvedDropDown_7
        function curvedDropDown_7ValueChanged(app, event)
            value = app.curvedDropDown_7.Value;
            switch value
                case 'yes'
                    app.geo.curved(3,1) = 1;
                    app.radiusofcurvatureEditField_7.Visible = 'on';
                case 'no'
                    app.geo.curved(3,1) = 0;
                    app.radiusofcurvatureEditField_7.Visible = 'off';
            end
        end

        % Value changed function: radiusofcurvatureEditField_7
        function radiusofcurvatureEditField_7ValueChanged(app, event)
            value = app.radiusofcurvatureEditField_7.Value;
            app.geo.radius(3,1) = str2double(value);
        end

        % Value changed function: dihedralEditField_8
        function dihedralEditField_8ValueChanged(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            value = app.dihedralEditField_8.Value;
            if app.geo.curved(3,1)==0
                app.geo.dihed(3,2) = app.geo.dihed(3,1) + str2num(value)*pi/180;
            else
                app.geo.dihed(3,2) = app.geo.dihed(3,1)-app.geo.b(3,1)/app.geo.radius(3,1)+str2num(value)*pi/180;
            end
        end

        % Value changed function: noofpanelssemispanwiseEditField_8
        function noofpanelssemispanwiseEditField_8ValueChanged(app, event)
            value = app.noofpanelssemispanwiseEditField_8.Value;
            app.geo.ny(3,2) = str2double(value);
        end

        % Value changed function: spanEditField_8
        function spanEditField_8ValueChanged(app, event)
            value = app.spanEditField_8.Value;
            app.geo.b(3,2) = str2double(value);
        end

        % Value changed function: taperEditField_8
        function taperEditField_8ValueChanged(app, event)
            value = app.taperEditField_8.Value;
            app.geo.T(3,2) = str2double(value);
        end

        % Value changed function: tipchordairfoilEditField_8
        function tipchordairfoilEditField_8ValueChanged(app, event)
            value = app.tipchordairfoilEditField_8.Value;
            app.geo.foil(3,2,2) = {value};
            app.geo.foil(3,2,1) = app.geo.foil(3,1,2);
        end

        % Value changed function: sweepEditField_8
        function sweepEditField_8ValueChanged(app, event)
            value = app.sweepEditField_8.Value;
            app.geo.SW(3,2) = str2double(value);
        end

        % Value changed function: outboardtwistEditField_8
        function outboardtwistEditField_8ValueChanged(app, event)
            value = app.outboardtwistEditField_8.Value;
            app.geo.TW(3,2,2) = str2double(value);
        end

        % Value changed function: meshtypeDropDown_8
        function meshtypeDropDown_8ValueChanged(app, event)
            value = app.meshtypeDropDown_8.Value;
            app.geo.meshtype(3,2) = str2double(value);
        end

        % Value changed function: curvedDropDown_8
        function curvedDropDown_8ValueChanged(app, event)
            value = app.curvedDropDown_8.Value;
            switch value
                case 'yes'
                    app.geo.curved(3,2) = 1;
                    app.radiusofcurvatureEditField_8.Visible = 'on';
                case 'no'
                    app.geo.curved(3,2) = 0;
                    app.radiusofcurvatureEditField_8.Visible = 'off';
            end
        end

        % Value changed function: radiusofcurvatureEditField_8
        function radiusofcurvatureEditField_8ValueChanged(app, event)
            value = app.radiusofcurvatureEditField_8.Value;
            app.geo.radius(3,2) = str2double(value);
        end

        % Value changed function: dihedralEditField_9
        function dihedralEditField_9ValueChanged(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            value = app.dihedralEditField_9.Value;
            if app.geo.curved(3,2)==0
                app.geo.dihed(3,3) = app.geo.dihed(3,2) + str2num(value)*pi/180;
            else
                app.geo.dihed(3,3) = app.geo.dihed(3,2)-app.geo.b(3,2)/app.geo.radius(3,2)+str2num(value)*pi/180;
            end
        end

        % Value changed function: noofpanelssemispanwiseEditField_9
        function noofpanelssemispanwiseEditField_9ValueChanged(app, event)
            value = app.noofpanelssemispanwiseEditField_9.Value;
            app.geo.ny(3,3) = str2double(value);
        end

        % Value changed function: spanEditField_9
        function spanEditField_9ValueChanged(app, event)
            value = app.spanEditField_9.Value;
            app.geo.b(3,3) = str2double(value);
        end

        % Value changed function: taperEditField_9
        function taperEditField_9ValueChanged(app, event)
            value = app.taperEditField_9.Value;
            app.geo.T(3,3) = str2double(value);
        end

        % Value changed function: tipchordairfoilEditField_9
        function tipchordairfoilEditField_9ValueChanged(app, event)
            value = app.tipchordairfoilEditField_9.Value;
            app.geo.foil(3,3,2) = {value};
            app.geo.foil(3,3,1) = app.geo.foil(3,2,2);
        end

        % Value changed function: sweepEditField_9
        function sweepEditField_9ValueChanged(app, event)
            value = app.sweepEditField_9.Value;
            app.geo.SW(3,3) = str2double(value)*pi/180;
        end

        % Value changed function: outboardtwistEditField_9
        function outboardtwistEditField_9ValueChanged(app, event)
            value = app.outboardtwistEditField_9.Value;
            app.geo.TW(3,3,2) = str2double(value)*pi/180;
        end

        % Value changed function: meshtypeDropDown_9
        function meshtypeDropDown_9ValueChanged(app, event)
            value = app.meshtypeDropDown_9.Value;
            app.geo.meshtype(3,3) = str2double(value); 
        end

        % Value changed function: curvedDropDown_9
        function curvedDropDown_9ValueChanged(app, event)
            value = app.curvedDropDown_9.Value;
            switch value
                case 'yes'
                    app.geo.curved(3,3) = 1;
                    app.radiusofcurvatureEditField_9.Visible = 'on';
                case 'no'
                    app.geo.curved(3,3) = 0;
                    app.radiusofcurvatureEditField_9.Visible = 'off';
            end
        end

        % Value changed function: radiusofcurvatureEditField_9
        function radiusofcurvatureEditField_9ValueChanged(app, event)
            value = app.radiusofcurvatureEditField_9.Value;
            app.geo.radius(3,3) = str2double(value);
        end

        % Close request function: GeometryeditUIFigure
        function GeometryeditUIFigureCloseRequest(app, event)
            
            update_geo(app.CallingApp,app.geo);
            
            delete(app);
        end

        % Value changed function: noofpartitionsDropDown_4
        function noofpartitionsDropDown_4ValueChanged(app, event)
            value = app.noofpartitionsDropDown_4.Value;
            switch value
                case "1"
                    app.partition1Panel_4.Visible = 'on';
                    app.partition2Panel_4.Visible = 'off';
                    app.partition3Panel_4.Visible = 'off';
                    app.geo.nelem(4)=str2double(value);
                    app.geo.curved(4,1) = 0;
                    
                case "2"
                    app.partition1Panel_4.Visible = 'on';
                    app.partition2Panel_4.Visible = 'on';
                    app.partition3Panel_4.Visible = 'off';
                    app.geo.nelem(4)=str2double(value);
                    app.geo.curved(4,1) = 0;
                    app.geo.curved(4,2) = 0;
                case "3"
                    app.partition1Panel_4.Visible = 'on';
                    app.partition2Panel_4.Visible = 'on';
                    app.partition3Panel_4.Visible = 'on';
                    app.geo.nelem(4)=str2double(value);
                    app.geo.curved(4,1) = 0;
                    app.geo.curved(4,2) = 0;
            end
        end

        % Value changed function: MirroredCheckBox_4
        function MirroredCheckBox_4ValueChanged(app, event)
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            value = app.MirroredCheckBox_4.Value;
            app.geo.symetric(4) = value;
        end

        % Value changed function: APEXxEditField_4
        function APEXxEditField_4ValueChanged(app, event)
            value = app.APEXxEditField_4.Value;
            app.geo.startx(4) = value;
        end

        % Value changed function: yEditField_8
        function yEditField_8ValueChanged(app, event)
            value = app.yEditField_8.Value;
            app.geo.starty(4) = value;
        end

        % Value changed function: zEditField_8
        function zEditField_8ValueChanged(app, event)
            value = app.zEditField_8.Value;
            app.geo.startz(4) = value;
        end

        % Value changed function: RootchordEditField_10
        function RootchordEditField_10ValueChanged(app, event)
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            value = app.RootchordEditField_10.Value;
            app.geo.c(3) = str2double(value);
        end

        % Value changed function: BasechordairfoilEditField_10
        function BasechordairfoilEditField_10ValueChanged(app, event)
            value = app.BasechordairfoilEditField_10.Value;
            app.geo.foil(3,1,1) = {value};
        end

        % Value changed function: noofpanelschordwiseEditField_10
        function noofpanelschordwiseEditField_10ValueChanged(app, event)
            value = app.noofpanelschordwiseEditField_10.Value;
            app.geo.nx(4,1) = str2double(value);
            
            switch (app.noofpartitionsDropDown_4.Value)
                case "1"
                    
                case "2"
                    app.geo.nx(4,2)=str2double(value);
                case "3"
                    app.geo.nx(4,2)=str2double(value);
                    app.geo.nx(4,3)=str2double(value);
            end
            
        end

        % Value changed function: dihedralEditField_10
        function dihedralEditField_10ValueChanged(app, event)
            value = app.dihedralEditField_10.Value;
            app.geo.dihed(4,1) = str2double(value)*pi/180; 
        end

        % Value changed function: pitchEditField_10
        function pitchEditField_10ValueChanged(app, event)
            value = app.pitchEditField_10.Value;
            app.geo.foiltheta(4,1) = str2double(value)*pi/180;
        end

        % Value changed function: basechordtwistEditField_10
        function basechordtwistEditField_10ValueChanged(app, event)
            value = app.basechordtwistEditField_10.Value;
            app.geo.TW(4,1,1) = str2double(value)*pi/180;
            app.geo.BTW(4) = str2double(value)*pi/180;
        end

        % Value changed function: noofpanelssemispanwiseEditField_10
        function noofpanelssemispanwiseEditField_10ValueChanged(app, event)
            value = app.noofpanelssemispanwiseEditField_10.Value;
            app.geo.ny(4,1) = str2double(value);
        end

        % Value changed function: spanEditField_10
        function spanEditField_10ValueChanged(app, event)
            value = app.spanEditField_10.Value;
            app.geo.b(4,1) = str2double(value);
        end

        % Value changed function: taperEditField_10
        function taperEditField_10ValueChanged(app, event)
            value = app.taperEditField_10.Value;
            app.geo.T(4,1) = str2double(value);
        end

        % Value changed function: tipchordairfoilEditField_10
        function tipchordairfoilEditField_10ValueChanged(app, event)
            value = app.tipchordairfoilEditField_10.Value;
            app.geo.foil(4,1,2) = {value};
        end

        % Value changed function: sweepEditField_10
        function sweepEditField_10ValueChanged(app, event)
            value = app.sweepEditField_10.Value;
            app.geo.SW(4,1) = str2double(value)*pi/180;
        end

        % Value changed function: outboardtwistEditField_10
        function outboardtwistEditField_10ValueChanged(app, event)
            value = app.outboardtwistEditField_10.Value;
            app.geo.TW(4,1,2) = str2double(value)*pi/180;
        end

        % Value changed function: meshtypeDropDown_10
        function meshtypeDropDown_10ValueChanged(app, event)
            value = app.meshtypeDropDown_10.Value;
            app.geo.meshtype(4,1) = str2double(value);
        end

        % Value changed function: curvedDropDown_10
        function curvedDropDown_10ValueChanged(app, event)
            value = app.curvedDropDown_10.Value;
            switch value
                case 'yes'
                    app.geo.curved(4,1) = 1;
                    app.radiusofcurvatureEditField_10.Visible = 'on';
                case 'no'
                    app.geo.curved(4,1) = 0;
                    app.radiusofcurvatureEditField_10.Visible = 'off';
            end
        end

        % Value changed function: dihedralEditField_11
        function dihedralEditField_11ValueChanged(app, event)
            value = app.dihedralEditField_11.Value;
            if app.geo.curved(4,1)==0
                app.geo.dihed(4,2) = app.geo.dihed(4,1) + str2num(value)*pi/180;
            else
               app.geo.dihed(4,2) = app.geo.dihed(4,1) - app.geo.b(4,1)/app.geo.radius(4,1) + str2num(value*pi/180); 
            end
        end

        % Value changed function: noofpanelssemispanwiseEditField_11
        function noofpanelssemispanwiseEditField_11ValueChanged(app, event)
            value = app.noofpanelssemispanwiseEditField_11.Value;
            app.geo.ny(4,2) = str2double(value);
        end

        % Value changed function: spanEditField_11
        function spanEditField_11ValueChanged(app, event)
            value = app.spanEditField_11.Value;
            app.geo.b(4,2) = str2double(value);
        end

        % Value changed function: taperEditField_11
        function taperEditField_11ValueChanged(app, event)
            value = app.taperEditField_11.Value;
            app.geo.T(4,2) = str2double(value);
        end

        % Value changed function: tipchordairfoilEditField_11
        function tipchordairfoilEditField_11ValueChanged(app, event)
            value = app.tipchordairfoilEditField_11.Value;
            app.geo.foil(4,2,2) = {value};
            app.geo.foil(4,2,1) = app.geo.foil(4,1,2);
        end

        % Value changed function: sweepEditField_11
        function sweepEditField_11ValueChanged(app, event)
            value = app.sweepEditField_11.Value;
            app.geo.SW(4,2) = str2double(value);
        end

        % Value changed function: outboardtwistEditField_11
        function outboardtwistEditField_11ValueChanged(app, event)
            value = app.outboardtwistEditField_11.Value;
            app.geo.TW(4,2,2) = str2double(value);
        end

        % Value changed function: meshtypeDropDown_11
        function meshtypeDropDown_11ValueChanged(app, event)
            value = app.meshtypeDropDown_11.Value;
            app.geo.meshtype(4,2) = str2double(value);
        end

        % Value changed function: curvedDropDown_11
        function curvedDropDown_11ValueChanged(app, event)
            value = app.curvedDropDown_11.Value;
            switch value
                case 'yes'
                    app.geo.curved(4,2) = 1;
                    app.radiusofcurvatureEditField_11.Visible = 'on';
                case 'no'
                    app.geo.curved(4,2) = 0;
                    app.radiusofcurvatureEditField_11.Visible = 'off';
            end
        end

        % Value changed function: radiusofcurvatureEditField_11
        function radiusofcurvatureEditField_11ValueChanged(app, event)
            value = app.radiusofcurvatureEditField_11.Value;
            app.geo.radius(4,2) = str2double(value);
        end

        % Value changed function: dihedralEditField_12
        function dihedralEditField_12ValueChanged(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            value = app.dihedralEditField_12.Value;
             if app.geo.curved(4,2)==0
                app.geo.dihed(4,3) = app.geo.dihed(4,2) + str2num(value)*pi/180;
            else
                app.geo.dihed(4,3) = app.geo.dihed(4,2)-app.geo.b(4,2)/app.geo.radius(4,2)+str2num(value)*pi/180;
            end
        end

        % Value changed function: noofpanelssemispanwiseEditField_12
        function noofpanelssemispanwiseEditField_12ValueChanged(app, event)
            value = app.noofpanelssemispanwiseEditField_12.Value;
            app.geo.ny(4,3) = str2double(value);
        end

        % Value changed function: spanEditField_12
        function spanEditField_12ValueChanged(app, event)
            value = app.spanEditField_12.Value;
            app.geo.b(4,3) = str2double(value);
        end

        % Value changed function: taperEditField_12
        function taperEditField_12ValueChanged(app, event)
            value = app.taperEditField_12.Value;
            app.geo.T(4,3) = str2double(value);
        end

        % Value changed function: tipchordairfoilEditField_12
        function tipchordairfoilEditField_12ValueChanged(app, event)
            value = app.tipchordairfoilEditField_12.Value;
            app.geo.foil(4,3,2) = {value};
            app.geo.foil(4,3,1) = str2double(value)*pi/180;
        end

        % Value changed function: sweepEditField_12
        function sweepEditField_12ValueChanged(app, event)
            value = app.sweepEditField_12.Value;
            app.geo.SW(4,3) = str2double(value)*pi/180;
        end

        % Value changed function: outboardtwistEditField_12
        function outboardtwistEditField_12ValueChanged(app, event)
            value = app.outboardtwistEditField_12.Value;
            app.geo.TW(4,3,2) = str2double(value)*pi/180;
        end

        % Value changed function: meshtypeDropDown_12
        function meshtypeDropDown_12ValueChanged(app, event)
            value = app.meshtypeDropDown_12.Value;
            app.geo.meshtype(4,3) = str2double(value);
        end

        % Value changed function: curvedDropDown_12
        function curvedDropDown_12ValueChanged(app, event)
            value = app.curvedDropDown_12.Value;
            switch value
                case 'yes'
                    app.geo.curved(4,3) = 1;
                    app.radiusofcurvatureEditField_12.Visible = 'on';
                case 'no'
                    app.geo.curved(4,3) = 0;
                    app.radiusofcurvatureEditField_12.Visible = 'off';
            end
        end

        % Value changed function: radiusofcurvatureEditField_12
        function radiusofcurvatureEditField_12ValueChanged(app, event)
            value = app.radiusofcurvatureEditField_12.Value;
            app.geo.radius(4,3) = str2double(value); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeometryeditUIFigure = uifigure;
            app.GeometryeditUIFigure.Name = 'Geometry edit';
            app.GeometryeditUIFigure.CloseRequestFcn = createCallbackFcn(app, @GeometryeditUIFigureCloseRequest, true);
            app.nooffoilsDropDownLabel = uilabel(app.GeometryeditUIFigure);
            app.nooffoilsDropDownLabel.Text = 'no. of foils';
            app.nooffoilsDropDown = uidropdown(app.GeometryeditUIFigure);
            app.nooffoilsDropDown.Items = {'- choose an option -', '1', '2', '3', '4'};
            app.nooffoilsDropDown.ValueChangedFcn = createCallbackFcn(app, @nooffoilsDropDownValueChanged, true);
            app.nooffoilsDropDown.Value = '- choose an option -';
            app.Foil1Panel = uipanel(app.GeometryeditUIFigure);
            app.Foil1Panel.Title = 'Foil 1';
            app.MirroredCheckBox = uicheckbox(app.Foil1Panel);
            app.MirroredCheckBox.ValueChangedFcn = createCallbackFcn(app, @MirroredCheckBoxValueChanged, true);
            app.MirroredCheckBox.Tooltip = {'Checking this box will mirror the foil over the XZ-plane.'};
            app.MirroredCheckBox.Text = 'Mirrored';
            app.partition1Panel = uipanel(app.Foil1Panel);
            app.partition1Panel.Title = 'partition 1';
            app.RootchordEditFieldLabel = uilabel(app.partition1Panel);
            app.RootchordEditFieldLabel.Text = 'Root chord';
            app.RootchordEditField = uieditfield(app.partition1Panel, 'text');
            app.RootchordEditField.ValueChangedFcn = createCallbackFcn(app, @RootchordEditFieldValueChanged, true);
            app.RootchordEditField.Tooltip = {'This field defines the base chord length of the foil. Example: 0.2 [m]'};
            app.BasechordairfoilEditFieldLabel = uilabel(app.partition1Panel);
            app.BasechordairfoilEditFieldLabel.Text = 'Base chord airfoil';
            app.BasechordairfoilEditField = uieditfield(app.partition1Panel, 'text');
            app.BasechordairfoilEditField.ValueChangedFcn = createCallbackFcn(app, @BasechordairfoilEditFieldValueChanged, true);
            app.BasechordairfoilEditField.Tooltip = {'This field defines the foil shape at the base of the foil with a 4-digit NACAXXXX. Example: 2412'};
            app.noofpanelschordwiseEditFieldLabel = uilabel(app.partition1Panel);
            app.noofpanelschordwiseEditFieldLabel.Text = 'no. of panels chord wise';
            app.noofpanelschordwiseEditField = uieditfield(app.partition1Panel, 'text');
            app.noofpanelschordwiseEditField.ValueChangedFcn = createCallbackFcn(app, @noofpanelschordwiseEditFieldValueChanged, true);
            app.noofpanelschordwiseEditField.Tooltip = {'This field defines the number of panels in the mesh in the chordwise direction. Example: 10'};
            app.dihedralEditFieldLabel = uilabel(app.partition1Panel);
            app.dihedralEditFieldLabel.Text = 'dihedral';
            app.dihedralEditField = uieditfield(app.partition1Panel, 'text');
            app.dihedralEditField.ValueChangedFcn = createCallbackFcn(app, @dihedralEditFieldValueChanged, true);
            app.dihedralEditField.Tooltip = {'This defines the direction where the partition is directed. Example: 0 deg. results a sideways partition outwards along the y-axis'; ' -90 deg. results in a foil straight downwards.'};
            app.pitchEditFieldLabel = uilabel(app.partition1Panel);
            app.pitchEditFieldLabel.Text = 'pitch';
            app.pitchEditField = uieditfield(app.partition1Panel, 'text');
            app.pitchEditField.ValueChangedFcn = createCallbackFcn(app, @pitchEditFieldValueChanged, true);
            app.pitchEditField.Tooltip = {'This field defines the rake of the entire foil. Example: 10 deg. -> this will rotate the foil around the normal direction of  the first partition.'};
            app.basechordtwistEditFieldLabel = uilabel(app.partition1Panel);
            app.basechordtwistEditFieldLabel.Text = 'base chord twist';
            app.basechordtwistEditField = uieditfield(app.partition1Panel, 'text');
            app.basechordtwistEditField.ValueChangedFcn = createCallbackFcn(app, @basechordtwistEditFieldValueChanged, true);
            app.basechordtwistEditField.Tooltip = {'This field defines the twist of the base of the foil. Example: 5 deg.'};
            app.noofpanelssemispanwiseEditFieldLabel = uilabel(app.partition1Panel);
            app.noofpanelssemispanwiseEditFieldLabel.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField = uieditfield(app.partition1Panel, 'text');
            app.noofpanelssemispanwiseEditField.ValueChangedFcn = createCallbackFcn(app, @noofpanelssemispanwiseEditFieldValueChanged, true);
            app.noofpanelssemispanwiseEditField.Tooltip = {'This field defines the number of panels in the mesh along the spanwise direction. Example: 20'};
            app.spanEditFieldLabel = uilabel(app.partition1Panel);
            app.spanEditFieldLabel.Text = 'span';
            app.spanEditField = uieditfield(app.partition1Panel, 'text');
            app.spanEditField.ValueChangedFcn = createCallbackFcn(app, @spanEditFieldValueChanged, true);
            app.spanEditField.Tooltip = {'This field defines the span of the first partition and will consequently also define the starting position of the subsequent partition. Example: 0.8 m'};
            app.taperEditFieldLabel = uilabel(app.partition1Panel);
            app.taperEditFieldLabel.Text = 'taper';
            app.taperEditField = uieditfield(app.partition1Panel, 'text');
            app.taperEditField.ValueChangedFcn = createCallbackFcn(app, @taperEditFieldValueChanged, true);
            app.taperEditField.Tooltip = {'This field defines the chord length at the tip. Example: with a taper of 0.5 and base chord of 0.2 m we get a tip chord of 0.1 m'};
            app.tipchordairfoilEditFieldLabel = uilabel(app.partition1Panel);
            app.tipchordairfoilEditFieldLabel.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField = uieditfield(app.partition1Panel, 'text');
            app.tipchordairfoilEditField.ValueChangedFcn = createCallbackFcn(app, @tipchordairfoilEditFieldValueChanged, true);
            app.tipchordairfoilEditField.Tooltip = {'This field defines the used foil shape at the tip'; ' again with a 4-digit NACAXXXX. Together with the base chord airfoil this will define every foil shape in between.'};
            app.sweepEditFieldLabel = uilabel(app.partition1Panel);
            app.sweepEditFieldLabel.Text = 'sweep';
            app.sweepEditField = uieditfield(app.partition1Panel, 'text');
            app.sweepEditField.ValueChangedFcn = createCallbackFcn(app, @sweepEditFieldValueChanged, true);
            app.sweepEditField.Tooltip = {'This field defines how much the position of the tip is rotated relative to the position of the base. (different from pitch'; ' as sweep only moves the position of the tip longitudinally whereas pitch rotates the foil/partition as a whole)'};
            app.outboardtwistEditFieldLabel = uilabel(app.partition1Panel);
            app.outboardtwistEditFieldLabel.Text = 'outboard twist';
            app.outboardtwistEditField = uieditfield(app.partition1Panel, 'text');
            app.outboardtwistEditField.ValueChangedFcn = createCallbackFcn(app, @outboardtwistEditFieldValueChanged, true);
            app.outboardtwistEditField.Tooltip = {'This field defines the twist at the tip of the partition.'};
            app.meshtypeDropDownLabel = uilabel(app.partition1Panel);
            app.meshtypeDropDownLabel.Text = 'mesh type';
            app.meshtypeDropDown = uidropdown(app.partition1Panel);
            app.meshtypeDropDown.Items = {'1', '2', '3', '4', '5'};
            app.meshtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @meshtypeDropDownValueChanged, true);
            app.meshtypeDropDown.Tooltip = {'1: Linear'; '2: spanwise half-cosine'; '3: chordwise half-cos'; ' spanwise cos'; '4: chordwise cos'; ' spanwise cos'; '5: spanwise cos'; '6: chordwise cos'; '7: secret '; ')'; '8: chordwise cos'; ' spanwise half-cos'};
            app.meshtypeDropDown.Value = '1';
            app.radiusofcurvatureEditFieldLabel = uilabel(app.partition1Panel);
            app.radiusofcurvatureEditFieldLabel.Text = 'radius of curvature';
            app.radiusofcurvatureEditField = uieditfield(app.partition1Panel, 'text');
            app.radiusofcurvatureEditField.ValueChangedFcn = createCallbackFcn(app, @radiusofcurvatureEditFieldValueChanged, true);
            app.radiusofcurvatureEditField.Visible = 'off';
            app.radiusofcurvatureEditField.Tooltip = {'This field defines the radius of curvature of the curved foil. Can either be negative or positive. Example: 0.5 m. The curvature will also depend on the span of partition.'};
            app.curvedDropDownLabel = uilabel(app.partition1Panel);
            app.curvedDropDownLabel.Text = 'curved';
            app.curvedDropDown = uidropdown(app.partition1Panel);
            app.curvedDropDown.Items = {'no', 'yes'};
            app.curvedDropDown.ValueChangedFcn = createCallbackFcn(app, @curvedDropDownValueChanged, true);
            app.curvedDropDown.Value = 'no';
            app.partition2Panel = uipanel(app.Foil1Panel);
            app.partition2Panel.Title = 'partition 2';
            app.partition2Panel.Visible = 'off';
            app.dihedralEditField_2Label = uilabel(app.partition2Panel);
            app.dihedralEditField_2Label.Text = 'dihedral';
            app.dihedralEditField_2 = uieditfield(app.partition2Panel, 'text');
            app.dihedralEditField_2.ValueChangedFcn = createCallbackFcn(app, @dihedralEditField_2ValueChanged, true);
            app.noofpanelssemispanwiseEditField_2Label = uilabel(app.partition2Panel);
            app.noofpanelssemispanwiseEditField_2Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_2 = uieditfield(app.partition2Panel, 'text');
            app.noofpanelssemispanwiseEditField_2.ValueChangedFcn = createCallbackFcn(app, @noofpanelssemispanwiseEditField_2ValueChanged, true);
            app.spanEditField_2Label = uilabel(app.partition2Panel);
            app.spanEditField_2Label.Text = 'span';
            app.spanEditField_2 = uieditfield(app.partition2Panel, 'text');
            app.spanEditField_2.ValueChangedFcn = createCallbackFcn(app, @spanEditField_2ValueChanged, true);
            app.taperEditField_2Label = uilabel(app.partition2Panel);
            app.taperEditField_2Label.Text = 'taper';
            app.taperEditField_2 = uieditfield(app.partition2Panel, 'text');
            app.taperEditField_2.ValueChangedFcn = createCallbackFcn(app, @taperEditField_2ValueChanged, true);
            app.tipchordairfoilEditField_2Label = uilabel(app.partition2Panel);
            app.tipchordairfoilEditField_2Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_2 = uieditfield(app.partition2Panel, 'text');
            app.tipchordairfoilEditField_2.ValueChangedFcn = createCallbackFcn(app, @tipchordairfoilEditField_2ValueChanged, true);
            app.sweepEditField_2Label = uilabel(app.partition2Panel);
            app.sweepEditField_2Label.Text = 'sweep';
            app.sweepEditField_2 = uieditfield(app.partition2Panel, 'text');
            app.sweepEditField_2.ValueChangedFcn = createCallbackFcn(app, @sweepEditField_2ValueChanged, true);
            app.outboardtwistEditField_2Label = uilabel(app.partition2Panel);
            app.outboardtwistEditField_2Label.Text = 'outboard twist';
            app.outboardtwistEditField_2 = uieditfield(app.partition2Panel, 'text');
            app.outboardtwistEditField_2.ValueChangedFcn = createCallbackFcn(app, @outboardtwistEditField_2ValueChanged, true);
            app.meshtypeDropDown_2Label = uilabel(app.partition2Panel);
            app.meshtypeDropDown_2Label.Text = 'mesh type';
            app.meshtypeDropDown_2 = uidropdown(app.partition2Panel);
            app.meshtypeDropDown_2.Items = {'1', '2', '3', '4', '5'};
            app.meshtypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @meshtypeDropDown_2ValueChanged, true);
            app.meshtypeDropDown_2.Value = '1';
            app.radiusofcurvatureEditField_2Label = uilabel(app.partition2Panel);
            app.radiusofcurvatureEditField_2Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_2 = uieditfield(app.partition2Panel, 'text');
            app.radiusofcurvatureEditField_2.ValueChangedFcn = createCallbackFcn(app, @radiusofcurvatureEditField_2ValueChanged, true);
            app.radiusofcurvatureEditField_2.Visible = 'off';
            app.curvedDropDown_2Label = uilabel(app.partition2Panel);
            app.curvedDropDown_2Label.Text = 'curved';
            app.curvedDropDown_2 = uidropdown(app.partition2Panel);
            app.curvedDropDown_2.Items = {'no', 'yes'};
            app.curvedDropDown_2.ValueChangedFcn = createCallbackFcn(app, @curvedDropDown_2ValueChanged, true);
            app.curvedDropDown_2.Value = 'no';
            app.partition3Panel = uipanel(app.Foil1Panel);
            app.partition3Panel.Title = 'partition 3';
            app.partition3Panel.Visible = 'off';
            app.dihedralEditField_3Label = uilabel(app.partition3Panel);
            app.dihedralEditField_3Label.Text = 'dihedral';
            app.dihedralEditField_3 = uieditfield(app.partition3Panel, 'text');
            app.dihedralEditField_3.ValueChangedFcn = createCallbackFcn(app, @dihedralEditField_3ValueChanged, true);
            app.noofpanelssemispanwiseEditField_3Label = uilabel(app.partition3Panel);
            app.noofpanelssemispanwiseEditField_3Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_3 = uieditfield(app.partition3Panel, 'text');
            app.noofpanelssemispanwiseEditField_3.ValueChangedFcn = createCallbackFcn(app, @noofpanelssemispanwiseEditField_3ValueChanged, true);
            app.spanEditField_3Label = uilabel(app.partition3Panel);
            app.spanEditField_3Label.Text = 'span';
            app.spanEditField_3 = uieditfield(app.partition3Panel, 'text');
            app.spanEditField_3.ValueChangedFcn = createCallbackFcn(app, @spanEditField_3ValueChanged, true);
            app.taperEditField_3Label = uilabel(app.partition3Panel);
            app.taperEditField_3Label.Text = 'taper';
            app.taperEditField_3 = uieditfield(app.partition3Panel, 'text');
            app.taperEditField_3.ValueChangedFcn = createCallbackFcn(app, @taperEditField_3ValueChanged, true);
            app.tipchordairfoilEditField_3Label = uilabel(app.partition3Panel);
            app.tipchordairfoilEditField_3Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_3 = uieditfield(app.partition3Panel, 'text');
            app.tipchordairfoilEditField_3.ValueChangedFcn = createCallbackFcn(app, @tipchordairfoilEditField_3ValueChanged, true);
            app.sweepEditField_3Label = uilabel(app.partition3Panel);
            app.sweepEditField_3Label.Text = 'sweep';
            app.sweepEditField_3 = uieditfield(app.partition3Panel, 'text');
            app.sweepEditField_3.ValueChangedFcn = createCallbackFcn(app, @sweepEditField_3ValueChanged, true);
            app.outboardtwistEditField_3Label = uilabel(app.partition3Panel);
            app.outboardtwistEditField_3Label.Text = 'outboard twist';
            app.outboardtwistEditField_3 = uieditfield(app.partition3Panel, 'text');
            app.outboardtwistEditField_3.ValueChangedFcn = createCallbackFcn(app, @outboardtwistEditField_3ValueChanged, true);
            app.meshtypeDropDown_3Label = uilabel(app.partition3Panel);
            app.meshtypeDropDown_3Label.Text = 'mesh type';
            app.meshtypeDropDown_3 = uidropdown(app.partition3Panel);
            app.meshtypeDropDown_3.Items = {'1', '2', '3', '4', '5', '- make a choice -'};
            app.meshtypeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @meshtypeDropDown_3ValueChanged, true);
            app.meshtypeDropDown_3.Value = '- make a choice -';
            app.radiusofcurvatureEditField_3Label = uilabel(app.partition3Panel);
            app.radiusofcurvatureEditField_3Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_3 = uieditfield(app.partition3Panel, 'text');
            app.radiusofcurvatureEditField_3.ValueChangedFcn = createCallbackFcn(app, @radiusofcurvatureEditField_3ValueChanged, true);
            app.curvedDropDown_3Label = uilabel(app.partition3Panel);
            app.curvedDropDown_3Label.Text = 'curved';
            app.curvedDropDown_3 = uidropdown(app.partition3Panel);
            app.curvedDropDown_3.Items = {'no', 'yes'};
            app.curvedDropDown_3.ValueChangedFcn = createCallbackFcn(app, @curvedDropDown_3ValueChanged, true);
            app.curvedDropDown_3.Value = 'no';
            app.APEXxEditFieldLabel = uilabel(app.Foil1Panel);
            app.APEXxEditFieldLabel.Text = 'APEX x';
            app.APEXxEditField = uieditfield(app.Foil1Panel, 'numeric');
            app.APEXxEditField.ValueChangedFcn = createCallbackFcn(app, @APEXxEditFieldValueChanged, true);
            app.APEXxEditField.Tooltip = {'This field defines where the root position of the foil will be. All subsequent fields will build the foil from this starting position.'};
            app.APEXxEditField.Value = 999;
            app.yEditField_3Label = uilabel(app.Foil1Panel);
            app.yEditField_3Label.Text = 'y';
            app.yEditField_3 = uieditfield(app.Foil1Panel, 'numeric');
            app.yEditField_3.ValueChangedFcn = createCallbackFcn(app, @yEditField_3ValueChanged, true);
            app.yEditField_3.Value = 999;
            app.zEditField_3Label = uilabel(app.Foil1Panel);
            app.zEditField_3Label.Text = 'z';
            app.zEditField_3 = uieditfield(app.Foil1Panel, 'numeric');
            app.zEditField_3.ValueChangedFcn = createCallbackFcn(app, @zEditField_3ValueChanged, true);
            app.zEditField_3.Value = 999;
            app.noofpartitionsDropDownLabel = uilabel(app.Foil1Panel);
            app.noofpartitionsDropDownLabel.Text = 'no. of partitions';
            app.noofpartitionsDropDown = uidropdown(app.Foil1Panel);
            app.noofpartitionsDropDown.Items = {'- choose an option -', '1', '2', '3'};
            app.noofpartitionsDropDown.ValueChangedFcn = createCallbackFcn(app, @noofpartitionsDropDownValueChanged, true);
            app.noofpartitionsDropDown.Value = '- choose an option -';
            app.massEditFieldLabel = uilabel(app.GeometryeditUIFigure);
            app.massEditFieldLabel.Text = 'mass';
            app.massEditField = uieditfield(app.GeometryeditUIFigure, 'text');
            app.massEditField.ValueChangedFcn = createCallbackFcn(app, @massEditFieldValueChanged, true);
            app.IxxEditFieldLabel = uilabel(app.GeometryeditUIFigure);
            app.IxxEditFieldLabel.Text = 'Ixx';
            app.IxxEditField = uieditfield(app.GeometryeditUIFigure, 'text');
            app.IxxEditField.ValueChangedFcn = createCallbackFcn(app, @IxxEditFieldValueChanged, true);
            app.IxxEditField.Tooltip = {'Mass moment of inertia tensor'; ' this has to be a symetric matrix. For a longitudinal analysis'; ' Iyy is important.'};
            app.IyxEditFieldLabel = uilabel(app.GeometryeditUIFigure);
            app.IyxEditFieldLabel.Text = 'Iyx';
            app.IyxEditField = uieditfield(app.GeometryeditUIFigure, 'text');
            app.IyxEditField.ValueChangedFcn = createCallbackFcn(app, @IyxEditFieldValueChanged, true);
            app.IyxEditField.Tooltip = {'Mass moment of inertia tensor'; ' this has to be a symetric matrix. For a longitudinal analysis'; ' Iyy is important.'};
            app.IzxEditFieldLabel = uilabel(app.GeometryeditUIFigure);
            app.IzxEditFieldLabel.Text = 'Izx';
            app.IzxEditField = uieditfield(app.GeometryeditUIFigure, 'text');
            app.IzxEditField.ValueChangedFcn = createCallbackFcn(app, @IzxEditFieldValueChanged, true);
            app.IzxEditField.Tooltip = {'Mass moment of inertia tensor'; ' this has to be a symetric matrix. For a longitudinal analysis'; ' Iyy is important.'};
            app.IxyEditFieldLabel = uilabel(app.GeometryeditUIFigure);
            app.IxyEditFieldLabel.Text = 'Ixy';
            app.IxyEditField = uieditfield(app.GeometryeditUIFigure, 'text');
            app.IxyEditField.ValueChangedFcn = createCallbackFcn(app, @IxyEditFieldValueChanged, true);
            app.IxyEditField.Tooltip = {'Mass moment of inertia tensor'; ' this has to be a symetric matrix. For a longitudinal analysis'; ' Iyy is important.'};
            app.IyyEditFieldLabel = uilabel(app.GeometryeditUIFigure);
            app.IyyEditFieldLabel.Text = 'Iyy';
            app.IyyEditField = uieditfield(app.GeometryeditUIFigure, 'text');
            app.IyyEditField.ValueChangedFcn = createCallbackFcn(app, @IyyEditFieldValueChanged, true);
            app.IyyEditField.Tooltip = {'Mass moment of inertia tensor'; ' this has to be a symetric matrix. For a longitudinal analysis'; ' Iyy is important.'};
            app.IzyEditFieldLabel = uilabel(app.GeometryeditUIFigure);
            app.IzyEditFieldLabel.Text = 'Izy';
            app.IzyEditField = uieditfield(app.GeometryeditUIFigure, 'text');
            app.IzyEditField.ValueChangedFcn = createCallbackFcn(app, @IzyEditFieldValueChanged, true);
            app.IzyEditField.Tooltip = {'Mass moment of inertia tensor'; ' this has to be a symetric matrix. For a longitudinal analysis'; ' Iyy is important.'};
            app.IxzEditFieldLabel = uilabel(app.GeometryeditUIFigure);
            app.IxzEditFieldLabel.Text = 'Ixz';
            app.IxzEditField = uieditfield(app.GeometryeditUIFigure, 'text');
            app.IxzEditField.ValueChangedFcn = createCallbackFcn(app, @IxzEditFieldValueChanged, true);
            app.IxzEditField.Tooltip = {'Mass moment of inertia tensor'; ' this has to be a symetric matrix. For a longitudinal analysis'; ' Iyy is important.'};
            app.IyzEditFieldLabel = uilabel(app.GeometryeditUIFigure);
            app.IyzEditFieldLabel.Text = 'Iyz';
            app.IyzEditField = uieditfield(app.GeometryeditUIFigure, 'text');
            app.IyzEditField.ValueChangedFcn = createCallbackFcn(app, @IyzEditFieldValueChanged, true);
            app.IyzEditField.Tooltip = {'Mass moment of inertia tensor'; ' this has to be a symetric matrix. For a longitudinal analysis'; ' Iyy is important.'};
            app.IzzEditFieldLabel = uilabel(app.GeometryeditUIFigure);
            app.IzzEditFieldLabel.Text = 'Izz';
            app.IzzEditField = uieditfield(app.GeometryeditUIFigure, 'text');
            app.IzzEditField.ValueChangedFcn = createCallbackFcn(app, @IzzEditFieldValueChanged, true);
            app.IzzEditField.Tooltip = {'Mass moment of inertia tensor'; ' this has to be a symetric matrix. For a longitudinal analysis'; ' Iyy is important.'};
            app.COGxEditFieldLabel = uilabel(app.GeometryeditUIFigure);
            app.COGxEditFieldLabel.Text = 'COG x';
            app.COGxEditField = uieditfield(app.GeometryeditUIFigure, 'text');
            app.COGxEditField.ValueChangedFcn = createCallbackFcn(app, @COGxEditFieldValueChanged, true);
            app.yEditField_4Label = uilabel(app.GeometryeditUIFigure);
            app.yEditField_4Label.Text = 'y';
            app.yEditField_4 = uieditfield(app.GeometryeditUIFigure, 'text');
            app.yEditField_4.ValueChangedFcn = createCallbackFcn(app, @yEditField_4ValueChanged, true);
            app.zEditField_4Label = uilabel(app.GeometryeditUIFigure);
            app.zEditField_4Label.Text = 'z';
            app.zEditField_4 = uieditfield(app.GeometryeditUIFigure, 'text');
            app.zEditField_4.ValueChangedFcn = createCallbackFcn(app, @zEditField_4ValueChanged, true);
            app.REFxEditFieldLabel = uilabel(app.GeometryeditUIFigure);
            app.REFxEditFieldLabel.Text = 'REF x';
            app.REFxEditField = uieditfield(app.GeometryeditUIFigure, 'text');
            app.REFxEditField.ValueChangedFcn = createCallbackFcn(app, @REFxEditFieldValueChanged, true);
            app.yEditField_5Label = uilabel(app.GeometryeditUIFigure);
            app.yEditField_5Label.Text = 'y';
            app.yEditField_5 = uieditfield(app.GeometryeditUIFigure, 'text');
            app.yEditField_5.ValueChangedFcn = createCallbackFcn(app, @yEditField_5ValueChanged, true);
            app.zEditField_5Label = uilabel(app.GeometryeditUIFigure);
            app.zEditField_5Label.Text = 'z';
            app.zEditField_5 = uieditfield(app.GeometryeditUIFigure, 'text');
            app.zEditField_5.ValueChangedFcn = createCallbackFcn(app, @zEditField_5ValueChanged, true);
            app.Foil2Panel = uipanel(app.GeometryeditUIFigure);
            app.Foil2Panel.Title = 'Foil 2';
            app.Foil2Panel.Visible = 'off';
            app.MirroredCheckBox_2 = uicheckbox(app.Foil2Panel);
            app.MirroredCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @MirroredCheckBox_2ValueChanged, true);
            app.MirroredCheckBox_2.Text = 'Mirrored';
            app.partition1Panel_2 = uipanel(app.Foil2Panel);
            app.partition1Panel_2.Title = 'partition 1';
            app.RootchordEditField_4Label = uilabel(app.partition1Panel_2);
            app.RootchordEditField_4Label.Text = 'Root chord';
            app.RootchordEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.RootchordEditField_4.ValueChangedFcn = createCallbackFcn(app, @RootchordEditField_4ValueChanged, true);
            app.BasechordairfoilEditField_4Label = uilabel(app.partition1Panel_2);
            app.BasechordairfoilEditField_4Label.Text = 'Base chord airfoil';
            app.BasechordairfoilEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.BasechordairfoilEditField_4.ValueChangedFcn = createCallbackFcn(app, @BasechordairfoilEditField_4ValueChanged, true);
            app.noofpanelschordwiseEditField_4Label = uilabel(app.partition1Panel_2);
            app.noofpanelschordwiseEditField_4Label.Text = 'no. of panels chord wise';
            app.noofpanelschordwiseEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.noofpanelschordwiseEditField_4.ValueChangedFcn = createCallbackFcn(app, @noofpanelschordwiseEditField_4ValueChanged, true);
            app.dihedralEditField_4Label = uilabel(app.partition1Panel_2);
            app.dihedralEditField_4Label.Text = 'dihedral';
            app.dihedralEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.dihedralEditField_4.ValueChangedFcn = createCallbackFcn(app, @dihedralEditField_4ValueChanged, true);
            app.pitchEditField_4Label = uilabel(app.partition1Panel_2);
            app.pitchEditField_4Label.Text = 'pitch';
            app.pitchEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.pitchEditField_4.ValueChangedFcn = createCallbackFcn(app, @pitchEditField_4ValueChanged, true);
            app.basechordtwistEditField_4Label = uilabel(app.partition1Panel_2);
            app.basechordtwistEditField_4Label.Text = 'base chord twist';
            app.basechordtwistEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.basechordtwistEditField_4.ValueChangedFcn = createCallbackFcn(app, @basechordtwistEditField_4ValueChanged, true);
            app.noofpanelssemispanwiseEditField_4Label = uilabel(app.partition1Panel_2);
            app.noofpanelssemispanwiseEditField_4Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.noofpanelssemispanwiseEditField_4.ValueChangedFcn = createCallbackFcn(app, @noofpanelssemispanwiseEditField_4ValueChanged, true);
            app.spanEditField_4Label = uilabel(app.partition1Panel_2);
            app.spanEditField_4Label.Text = 'span';
            app.spanEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.spanEditField_4.ValueChangedFcn = createCallbackFcn(app, @spanEditField_4ValueChanged, true);
            app.taperEditField_4Label = uilabel(app.partition1Panel_2);
            app.taperEditField_4Label.Text = 'taper';
            app.taperEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.taperEditField_4.ValueChangingFcn = createCallbackFcn(app, @taperEditField_4ValueChanging, true);
            app.tipchordairfoilEditField_4Label = uilabel(app.partition1Panel_2);
            app.tipchordairfoilEditField_4Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.tipchordairfoilEditField_4.ValueChangedFcn = createCallbackFcn(app, @tipchordairfoilEditField_4ValueChanged, true);
            app.sweepEditField_4Label = uilabel(app.partition1Panel_2);
            app.sweepEditField_4Label.Text = 'sweep';
            app.sweepEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.sweepEditField_4.ValueChangedFcn = createCallbackFcn(app, @sweepEditField_4ValueChanged, true);
            app.outboardtwistEditField_4Label = uilabel(app.partition1Panel_2);
            app.outboardtwistEditField_4Label.Text = 'outboard twist';
            app.outboardtwistEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.outboardtwistEditField_4.ValueChangedFcn = createCallbackFcn(app, @outboardtwistEditField_4ValueChanged, true);
            app.meshtypeDropDown_4Label = uilabel(app.partition1Panel_2);
            app.meshtypeDropDown_4Label.Text = 'mesh type';
            app.meshtypeDropDown_4 = uidropdown(app.partition1Panel_2);
            app.meshtypeDropDown_4.Items = {'1', '2', '3', '4', '5'};
            app.meshtypeDropDown_4.ValueChangedFcn = createCallbackFcn(app, @meshtypeDropDown_4ValueChanged, true);
            app.meshtypeDropDown_4.Value = '1';
            app.radiusofcurvatureEditField_4Label = uilabel(app.partition1Panel_2);
            app.radiusofcurvatureEditField_4Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_4 = uieditfield(app.partition1Panel_2, 'text');
            app.radiusofcurvatureEditField_4.ValueChangedFcn = createCallbackFcn(app, @radiusofcurvatureEditField_4ValueChanged, true);
            app.curvedDropDown_4Label = uilabel(app.partition1Panel_2);
            app.curvedDropDown_4Label.Text = 'curved';
            app.curvedDropDown_4 = uidropdown(app.partition1Panel_2);
            app.curvedDropDown_4.Items = {'no', 'yes'};
            app.curvedDropDown_4.ValueChangedFcn = createCallbackFcn(app, @curvedDropDown_4ValueChanged, true);
            app.curvedDropDown_4.Value = 'no';
            app.partition2Panel_2 = uipanel(app.Foil2Panel);
            app.partition2Panel_2.Title = 'partition 2';
            app.partition2Panel_2.Visible = 'off';
            app.RootchordEditField_5Label = uilabel(app.partition2Panel_2);
            app.RootchordEditField_5Label.Text = 'Root chord';
            app.RootchordEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.RootchordEditField_5.Visible = 'off';
            app.BasechordairfoilEditField_5Label = uilabel(app.partition2Panel_2);
            app.BasechordairfoilEditField_5Label.Text = 'Base chord airfoil';
            app.BasechordairfoilEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.BasechordairfoilEditField_5.Visible = 'off';
            app.noofpanelschordwiseEditField_5Label = uilabel(app.partition2Panel_2);
            app.noofpanelschordwiseEditField_5Label.Text = 'no. of panels chord wise';
            app.noofpanelschordwiseEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.noofpanelschordwiseEditField_5.Visible = 'off';
            app.dihedralEditField_5Label = uilabel(app.partition2Panel_2);
            app.dihedralEditField_5Label.Text = 'dihedral';
            app.dihedralEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.dihedralEditField_5.ValueChangedFcn = createCallbackFcn(app, @dihedralEditField_5ValueChanged, true);
            app.pitchEditField_5Label = uilabel(app.partition2Panel_2);
            app.pitchEditField_5Label.Text = 'pitch';
            app.pitchEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.pitchEditField_5.Visible = 'off';
            app.basechordtwistEditField_5Label = uilabel(app.partition2Panel_2);
            app.basechordtwistEditField_5Label.Text = 'base chord twist';
            app.basechordtwistEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.basechordtwistEditField_5.Visible = 'off';
            app.noofpanelssemispanwiseEditField_5Label = uilabel(app.partition2Panel_2);
            app.noofpanelssemispanwiseEditField_5Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.noofpanelssemispanwiseEditField_5.ValueChangedFcn = createCallbackFcn(app, @noofpanelssemispanwiseEditField_5ValueChanged, true);
            app.spanEditField_5Label = uilabel(app.partition2Panel_2);
            app.spanEditField_5Label.Text = 'span';
            app.spanEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.spanEditField_5.ValueChangedFcn = createCallbackFcn(app, @spanEditField_5ValueChanged, true);
            app.taperEditField_5Label = uilabel(app.partition2Panel_2);
            app.taperEditField_5Label.Text = 'taper';
            app.taperEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.taperEditField_5.ValueChangedFcn = createCallbackFcn(app, @taperEditField_5ValueChanged, true);
            app.tipchordairfoilEditField_5Label = uilabel(app.partition2Panel_2);
            app.tipchordairfoilEditField_5Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.tipchordairfoilEditField_5.ValueChangedFcn = createCallbackFcn(app, @tipchordairfoilEditField_5ValueChanged, true);
            app.sweepEditField_5Label = uilabel(app.partition2Panel_2);
            app.sweepEditField_5Label.Text = 'sweep';
            app.sweepEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.sweepEditField_5.ValueChangedFcn = createCallbackFcn(app, @sweepEditField_5ValueChanged, true);
            app.outboardtwistEditField_5Label = uilabel(app.partition2Panel_2);
            app.outboardtwistEditField_5Label.Text = 'outboard twist';
            app.outboardtwistEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.outboardtwistEditField_5.ValueChangedFcn = createCallbackFcn(app, @outboardtwistEditField_5ValueChanged, true);
            app.meshtypeDropDown_5Label = uilabel(app.partition2Panel_2);
            app.meshtypeDropDown_5Label.Text = 'mesh type';
            app.meshtypeDropDown_5 = uidropdown(app.partition2Panel_2);
            app.meshtypeDropDown_5.Items = {'1', '2', '3', '4', '5'};
            app.meshtypeDropDown_5.ValueChangedFcn = createCallbackFcn(app, @meshtypeDropDown_5ValueChanged, true);
            app.meshtypeDropDown_5.Value = '1';
            app.radiusofcurvatureEditField_5Label = uilabel(app.partition2Panel_2);
            app.radiusofcurvatureEditField_5Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_5 = uieditfield(app.partition2Panel_2, 'text');
            app.radiusofcurvatureEditField_5.ValueChangedFcn = createCallbackFcn(app, @radiusofcurvatureEditField_5ValueChanged, true);
            app.curvedDropDown_5Label = uilabel(app.partition2Panel_2);
            app.curvedDropDown_5Label.Text = 'curved';
            app.curvedDropDown_5 = uidropdown(app.partition2Panel_2);
            app.curvedDropDown_5.Items = {'no', 'yes'};
            app.curvedDropDown_5.ValueChangedFcn = createCallbackFcn(app, @curvedDropDown_5ValueChanged, true);
            app.curvedDropDown_5.Value = 'no';
            app.partition3Panel_2 = uipanel(app.Foil2Panel);
            app.partition3Panel_2.Title = 'partition 3';
            app.partition3Panel_2.Visible = 'off';
            app.RootchordEditField_6Label = uilabel(app.partition3Panel_2);
            app.RootchordEditField_6Label.Text = 'Root chord';
            app.RootchordEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.RootchordEditField_6.Visible = 'off';
            app.BasechordairfoilEditField_6Label = uilabel(app.partition3Panel_2);
            app.BasechordairfoilEditField_6Label.Text = 'Base chord airfoil';
            app.BasechordairfoilEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.BasechordairfoilEditField_6.Visible = 'off';
            app.noofpanelschordwiseEditField_6Label = uilabel(app.partition3Panel_2);
            app.noofpanelschordwiseEditField_6Label.Text = 'no. of panels chord wise';
            app.noofpanelschordwiseEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.noofpanelschordwiseEditField_6.Visible = 'off';
            app.dihedralEditField_6Label = uilabel(app.partition3Panel_2);
            app.dihedralEditField_6Label.Text = 'dihedral';
            app.dihedralEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.dihedralEditField_6.ValueChangedFcn = createCallbackFcn(app, @dihedralEditField_6ValueChanged, true);
            app.pitchEditField_6Label = uilabel(app.partition3Panel_2);
            app.pitchEditField_6Label.Text = 'pitch';
            app.pitchEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.pitchEditField_6.Visible = 'off';
            app.basechordtwistEditField_6Label = uilabel(app.partition3Panel_2);
            app.basechordtwistEditField_6Label.Text = 'base chord twist';
            app.basechordtwistEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.basechordtwistEditField_6.Visible = 'off';
            app.noofpanelssemispanwiseEditField_6Label = uilabel(app.partition3Panel_2);
            app.noofpanelssemispanwiseEditField_6Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.noofpanelssemispanwiseEditField_6.ValueChangedFcn = createCallbackFcn(app, @noofpanelssemispanwiseEditField_6ValueChanged, true);
            app.spanEditField_6Label = uilabel(app.partition3Panel_2);
            app.spanEditField_6Label.Text = 'span';
            app.spanEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.spanEditField_6.ValueChangedFcn = createCallbackFcn(app, @spanEditField_6ValueChanged, true);
            app.taperEditField_6Label = uilabel(app.partition3Panel_2);
            app.taperEditField_6Label.Text = 'taper';
            app.taperEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.taperEditField_6.ValueChangedFcn = createCallbackFcn(app, @taperEditField_6ValueChanged, true);
            app.tipchordairfoilEditField_6Label = uilabel(app.partition3Panel_2);
            app.tipchordairfoilEditField_6Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.tipchordairfoilEditField_6.ValueChangedFcn = createCallbackFcn(app, @tipchordairfoilEditField_6ValueChanged, true);
            app.sweepEditField_6Label = uilabel(app.partition3Panel_2);
            app.sweepEditField_6Label.Text = 'sweep';
            app.sweepEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.sweepEditField_6.ValueChangedFcn = createCallbackFcn(app, @sweepEditField_6ValueChanged, true);
            app.outboardtwistEditField_6Label = uilabel(app.partition3Panel_2);
            app.outboardtwistEditField_6Label.Text = 'outboard twist';
            app.outboardtwistEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.outboardtwistEditField_6.ValueChangedFcn = createCallbackFcn(app, @outboardtwistEditField_6ValueChanged, true);
            app.meshtypeDropDown_6Label = uilabel(app.partition3Panel_2);
            app.meshtypeDropDown_6Label.Text = 'mesh type';
            app.meshtypeDropDown_6 = uidropdown(app.partition3Panel_2);
            app.meshtypeDropDown_6.Items = {'1', '2', '3', '4', '5'};
            app.meshtypeDropDown_6.ValueChangedFcn = createCallbackFcn(app, @meshtypeDropDown_6ValueChanged, true);
            app.meshtypeDropDown_6.Value = '1';
            app.radiusofcurvatureEditField_6Label = uilabel(app.partition3Panel_2);
            app.radiusofcurvatureEditField_6Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_6 = uieditfield(app.partition3Panel_2, 'text');
            app.radiusofcurvatureEditField_6.ValueChangedFcn = createCallbackFcn(app, @radiusofcurvatureEditField_6ValueChanged, true);
            app.curvedDropDown_6Label = uilabel(app.partition3Panel_2);
            app.curvedDropDown_6Label.Text = 'curved';
            app.curvedDropDown_6 = uidropdown(app.partition3Panel_2);
            app.curvedDropDown_6.Items = {'no', 'yes'};
            app.curvedDropDown_6.ValueChangedFcn = createCallbackFcn(app, @curvedDropDown_6ValueChanged, true);
            app.curvedDropDown_6.Value = 'no';
            app.APEXxEditField_2Label = uilabel(app.Foil2Panel);
            app.APEXxEditField_2Label.Text = 'APEX x';
            app.APEXxEditField_2 = uieditfield(app.Foil2Panel, 'numeric');
            app.APEXxEditField_2.ValueChangedFcn = createCallbackFcn(app, @APEXxEditField_2ValueChanged, true);
            app.APEXxEditField_2.Value = 999;
            app.yEditField_6Label = uilabel(app.Foil2Panel);
            app.yEditField_6Label.Text = 'y';
            app.yEditField_6 = uieditfield(app.Foil2Panel, 'numeric');
            app.yEditField_6.ValueChangedFcn = createCallbackFcn(app, @yEditField_6ValueChanged, true);
            app.yEditField_6.Value = 999;
            app.zEditField_6Label = uilabel(app.Foil2Panel);
            app.zEditField_6Label.Text = 'z';
            app.zEditField_6 = uieditfield(app.Foil2Panel, 'numeric');
            app.zEditField_6.ValueChangedFcn = createCallbackFcn(app, @zEditField_6ValueChanged, true);
            app.zEditField_6.Value = 999;
            app.noofpartitionsDropDown_2Label = uilabel(app.Foil2Panel);
            app.noofpartitionsDropDown_2Label.Text = 'no. of partitions';
            app.noofpartitionsDropDown_2 = uidropdown(app.Foil2Panel);
            app.noofpartitionsDropDown_2.Items = {'- choose an option -', '1', '2', '3'};
            app.noofpartitionsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @noofpartitionsDropDown_2ValueChanged, true);
            app.noofpartitionsDropDown_2.Value = '- choose an option -';
            app.Foil3Panel = uipanel(app.GeometryeditUIFigure);
            app.Foil3Panel.Title = 'Foil 3';
            app.Foil3Panel.Visible = 'off';
            app.MirroredCheckBox_3 = uicheckbox(app.Foil3Panel);
            app.MirroredCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @MirroredCheckBox_3ValueChanged, true);
            app.MirroredCheckBox_3.Text = 'Mirrored';
            app.partition1Panel_3 = uipanel(app.Foil3Panel);
            app.partition1Panel_3.Title = 'partition 1';
            app.RootchordEditField_7Label = uilabel(app.partition1Panel_3);
            app.RootchordEditField_7Label.Text = 'Root chord';
            app.RootchordEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.RootchordEditField_7.ValueChangedFcn = createCallbackFcn(app, @RootchordEditField_7ValueChanged, true);
            app.BasechordairfoilEditField_7Label = uilabel(app.partition1Panel_3);
            app.BasechordairfoilEditField_7Label.Text = 'Base chord airfoil';
            app.BasechordairfoilEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.BasechordairfoilEditField_7.ValueChangedFcn = createCallbackFcn(app, @BasechordairfoilEditField_7ValueChanged, true);
            app.noofpanelschordwiseEditField_7Label = uilabel(app.partition1Panel_3);
            app.noofpanelschordwiseEditField_7Label.Text = 'no. of panels chord wise';
            app.noofpanelschordwiseEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.noofpanelschordwiseEditField_7.ValueChangedFcn = createCallbackFcn(app, @noofpanelschordwiseEditField_7ValueChanged, true);
            app.dihedralEditField_7Label = uilabel(app.partition1Panel_3);
            app.dihedralEditField_7Label.Text = 'dihedral';
            app.dihedralEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.dihedralEditField_7.ValueChangedFcn = createCallbackFcn(app, @dihedralEditField_7ValueChanged, true);
            app.pitchEditField_7Label = uilabel(app.partition1Panel_3);
            app.pitchEditField_7Label.Text = 'pitch';
            app.pitchEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.pitchEditField_7.ValueChangedFcn = createCallbackFcn(app, @pitchEditField_7ValueChanged, true);
            app.basechordtwistEditField_7Label = uilabel(app.partition1Panel_3);
            app.basechordtwistEditField_7Label.Text = 'base chord twist';
            app.basechordtwistEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.basechordtwistEditField_7.ValueChangedFcn = createCallbackFcn(app, @basechordtwistEditField_7ValueChanged, true);
            app.noofpanelssemispanwiseEditField_7Label = uilabel(app.partition1Panel_3);
            app.noofpanelssemispanwiseEditField_7Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.noofpanelssemispanwiseEditField_7.ValueChangedFcn = createCallbackFcn(app, @noofpanelssemispanwiseEditField_7ValueChanged, true);
            app.spanEditField_7Label = uilabel(app.partition1Panel_3);
            app.spanEditField_7Label.Text = 'span';
            app.spanEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.spanEditField_7.ValueChangedFcn = createCallbackFcn(app, @spanEditField_7ValueChanged, true);
            app.taperEditField_7Label = uilabel(app.partition1Panel_3);
            app.taperEditField_7Label.Text = 'taper';
            app.taperEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.taperEditField_7.ValueChangedFcn = createCallbackFcn(app, @taperEditField_7ValueChanged, true);
            app.tipchordairfoilEditField_7Label = uilabel(app.partition1Panel_3);
            app.tipchordairfoilEditField_7Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.tipchordairfoilEditField_7.ValueChangedFcn = createCallbackFcn(app, @tipchordairfoilEditField_7ValueChanged, true);
            app.sweepEditField_7Label = uilabel(app.partition1Panel_3);
            app.sweepEditField_7Label.Text = 'sweep';
            app.sweepEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.sweepEditField_7.ValueChangedFcn = createCallbackFcn(app, @sweepEditField_7ValueChanged, true);
            app.outboardtwistEditField_7Label = uilabel(app.partition1Panel_3);
            app.outboardtwistEditField_7Label.Text = 'outboard twist';
            app.outboardtwistEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.outboardtwistEditField_7.ValueChangedFcn = createCallbackFcn(app, @outboardtwistEditField_7ValueChanged, true);
            app.meshtypeDropDown_7Label = uilabel(app.partition1Panel_3);
            app.meshtypeDropDown_7Label.Text = 'mesh type';
            app.meshtypeDropDown_7 = uidropdown(app.partition1Panel_3);
            app.meshtypeDropDown_7.Items = {'1', '2', '3', '4', '5'};
            app.meshtypeDropDown_7.ValueChangedFcn = createCallbackFcn(app, @meshtypeDropDown_7ValueChanged, true);
            app.meshtypeDropDown_7.Value = '1';
            app.radiusofcurvatureEditField_7Label = uilabel(app.partition1Panel_3);
            app.radiusofcurvatureEditField_7Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_7 = uieditfield(app.partition1Panel_3, 'text');
            app.radiusofcurvatureEditField_7.ValueChangedFcn = createCallbackFcn(app, @radiusofcurvatureEditField_7ValueChanged, true);
            app.curvedDropDown_7Label = uilabel(app.partition1Panel_3);
            app.curvedDropDown_7Label.Text = 'curved';
            app.curvedDropDown_7 = uidropdown(app.partition1Panel_3);
            app.curvedDropDown_7.Items = {'no', 'yes'};
            app.curvedDropDown_7.ValueChangedFcn = createCallbackFcn(app, @curvedDropDown_7ValueChanged, true);
            app.curvedDropDown_7.Value = 'no';
            app.partition2Panel_3 = uipanel(app.Foil3Panel);
            app.partition2Panel_3.Title = 'partition 2';
            app.partition2Panel_3.Visible = 'off';
            app.RootchordEditField_8Label = uilabel(app.partition2Panel_3);
            app.RootchordEditField_8Label.Text = 'Root chord';
            app.RootchordEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.RootchordEditField_8.Visible = 'off';
            app.BasechordairfoilEditField_8Label = uilabel(app.partition2Panel_3);
            app.BasechordairfoilEditField_8Label.Text = 'Base chord airfoil';
            app.BasechordairfoilEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.BasechordairfoilEditField_8.Visible = 'off';
            app.noofpanelschordwiseEditField_8Label = uilabel(app.partition2Panel_3);
            app.noofpanelschordwiseEditField_8Label.Text = 'no. of panels chord wise';
            app.noofpanelschordwiseEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.noofpanelschordwiseEditField_8.Editable = 'off';
            app.noofpanelschordwiseEditField_8.Enable = 'off';
            app.noofpanelschordwiseEditField_8.Visible = 'off';
            app.dihedralEditField_8Label = uilabel(app.partition2Panel_3);
            app.dihedralEditField_8Label.Text = 'dihedral';
            app.dihedralEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.dihedralEditField_8.ValueChangedFcn = createCallbackFcn(app, @dihedralEditField_8ValueChanged, true);
            app.pitchEditField_8Label = uilabel(app.partition2Panel_3);
            app.pitchEditField_8Label.Text = 'pitch';
            app.pitchEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.pitchEditField_8.Visible = 'off';
            app.basechordtwistEditField_8Label = uilabel(app.partition2Panel_3);
            app.basechordtwistEditField_8Label.Text = 'base chord twist';
            app.basechordtwistEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.basechordtwistEditField_8.Visible = 'off';
            app.noofpanelssemispanwiseEditField_8Label = uilabel(app.partition2Panel_3);
            app.noofpanelssemispanwiseEditField_8Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.noofpanelssemispanwiseEditField_8.ValueChangedFcn = createCallbackFcn(app, @noofpanelssemispanwiseEditField_8ValueChanged, true);
            app.spanEditField_8Label = uilabel(app.partition2Panel_3);
            app.spanEditField_8Label.Text = 'span';
            app.spanEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.spanEditField_8.ValueChangedFcn = createCallbackFcn(app, @spanEditField_8ValueChanged, true);
            app.taperEditField_8Label = uilabel(app.partition2Panel_3);
            app.taperEditField_8Label.Text = 'taper';
            app.taperEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.taperEditField_8.ValueChangedFcn = createCallbackFcn(app, @taperEditField_8ValueChanged, true);
            app.tipchordairfoilEditField_8Label = uilabel(app.partition2Panel_3);
            app.tipchordairfoilEditField_8Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.tipchordairfoilEditField_8.ValueChangedFcn = createCallbackFcn(app, @tipchordairfoilEditField_8ValueChanged, true);
            app.sweepEditField_8Label = uilabel(app.partition2Panel_3);
            app.sweepEditField_8Label.Text = 'sweep';
            app.sweepEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.sweepEditField_8.ValueChangedFcn = createCallbackFcn(app, @sweepEditField_8ValueChanged, true);
            app.outboardtwistEditField_8Label = uilabel(app.partition2Panel_3);
            app.outboardtwistEditField_8Label.Text = 'outboard twist';
            app.outboardtwistEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.outboardtwistEditField_8.ValueChangedFcn = createCallbackFcn(app, @outboardtwistEditField_8ValueChanged, true);
            app.meshtypeDropDown_8Label = uilabel(app.partition2Panel_3);
            app.meshtypeDropDown_8Label.Text = 'mesh type';
            app.meshtypeDropDown_8 = uidropdown(app.partition2Panel_3);
            app.meshtypeDropDown_8.Items = {'1', '2', '3', '4', '5'};
            app.meshtypeDropDown_8.ValueChangedFcn = createCallbackFcn(app, @meshtypeDropDown_8ValueChanged, true);
            app.meshtypeDropDown_8.Value = '1';
            app.radiusofcurvatureEditField_8Label = uilabel(app.partition2Panel_3);
            app.radiusofcurvatureEditField_8Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_8 = uieditfield(app.partition2Panel_3, 'text');
            app.radiusofcurvatureEditField_8.ValueChangedFcn = createCallbackFcn(app, @radiusofcurvatureEditField_8ValueChanged, true);
            app.curvedDropDown_8Label = uilabel(app.partition2Panel_3);
            app.curvedDropDown_8Label.Text = 'curved';
            app.curvedDropDown_8 = uidropdown(app.partition2Panel_3);
            app.curvedDropDown_8.Items = {'no', 'yes'};
            app.curvedDropDown_8.ValueChangedFcn = createCallbackFcn(app, @curvedDropDown_8ValueChanged, true);
            app.curvedDropDown_8.Value = 'no';
            app.partition3Panel_3 = uipanel(app.Foil3Panel);
            app.partition3Panel_3.Title = 'partition 3';
            app.partition3Panel_3.Visible = 'off';
            app.RootchordEditField_9Label = uilabel(app.partition3Panel_3);
            app.RootchordEditField_9Label.Text = 'Root chord';
            app.RootchordEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.RootchordEditField_9.Visible = 'off';
            app.BasechordairfoilEditField_9Label = uilabel(app.partition3Panel_3);
            app.BasechordairfoilEditField_9Label.Text = 'Base chord airfoil';
            app.BasechordairfoilEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.BasechordairfoilEditField_9.Visible = 'off';
            app.noofpanelschordwiseEditField_9Label = uilabel(app.partition3Panel_3);
            app.noofpanelschordwiseEditField_9Label.Text = 'no. of panels chord wise';
            app.noofpanelschordwiseEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.noofpanelschordwiseEditField_9.Visible = 'off';
            app.dihedralEditField_9Label = uilabel(app.partition3Panel_3);
            app.dihedralEditField_9Label.Text = 'dihedral';
            app.dihedralEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.dihedralEditField_9.ValueChangedFcn = createCallbackFcn(app, @dihedralEditField_9ValueChanged, true);
            app.pitchEditField_9Label = uilabel(app.partition3Panel_3);
            app.pitchEditField_9Label.Text = 'pitch';
            app.pitchEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.pitchEditField_9.Visible = 'off';
            app.basechordtwistEditField_9Label = uilabel(app.partition3Panel_3);
            app.basechordtwistEditField_9Label.Text = 'base chord twist';
            app.basechordtwistEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.basechordtwistEditField_9.Visible = 'off';
            app.noofpanelssemispanwiseEditField_9Label = uilabel(app.partition3Panel_3);
            app.noofpanelssemispanwiseEditField_9Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.noofpanelssemispanwiseEditField_9.ValueChangedFcn = createCallbackFcn(app, @noofpanelssemispanwiseEditField_9ValueChanged, true);
            app.spanEditField_9Label = uilabel(app.partition3Panel_3);
            app.spanEditField_9Label.Text = 'span';
            app.spanEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.spanEditField_9.ValueChangedFcn = createCallbackFcn(app, @spanEditField_9ValueChanged, true);
            app.taperEditField_9Label = uilabel(app.partition3Panel_3);
            app.taperEditField_9Label.Text = 'taper';
            app.taperEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.taperEditField_9.ValueChangedFcn = createCallbackFcn(app, @taperEditField_9ValueChanged, true);
            app.tipchordairfoilEditField_9Label = uilabel(app.partition3Panel_3);
            app.tipchordairfoilEditField_9Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.tipchordairfoilEditField_9.ValueChangedFcn = createCallbackFcn(app, @tipchordairfoilEditField_9ValueChanged, true);
            app.sweepEditField_9Label = uilabel(app.partition3Panel_3);
            app.sweepEditField_9Label.Text = 'sweep';
            app.sweepEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.sweepEditField_9.ValueChangedFcn = createCallbackFcn(app, @sweepEditField_9ValueChanged, true);
            app.outboardtwistEditField_9Label = uilabel(app.partition3Panel_3);
            app.outboardtwistEditField_9Label.Text = 'outboard twist';
            app.outboardtwistEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.outboardtwistEditField_9.ValueChangedFcn = createCallbackFcn(app, @outboardtwistEditField_9ValueChanged, true);
            app.meshtypeDropDown_9Label = uilabel(app.partition3Panel_3);
            app.meshtypeDropDown_9Label.Text = 'mesh type';
            app.meshtypeDropDown_9 = uidropdown(app.partition3Panel_3);
            app.meshtypeDropDown_9.Items = {'1', '2', '3', '4', '5'};
            app.meshtypeDropDown_9.ValueChangedFcn = createCallbackFcn(app, @meshtypeDropDown_9ValueChanged, true);
            app.meshtypeDropDown_9.Value = '1';
            app.radiusofcurvatureEditField_9Label = uilabel(app.partition3Panel_3);
            app.radiusofcurvatureEditField_9Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_9 = uieditfield(app.partition3Panel_3, 'text');
            app.radiusofcurvatureEditField_9.ValueChangedFcn = createCallbackFcn(app, @radiusofcurvatureEditField_9ValueChanged, true);
            app.curvedDropDown_9Label = uilabel(app.partition3Panel_3);
            app.curvedDropDown_9Label.Text = 'curved';
            app.curvedDropDown_9 = uidropdown(app.partition3Panel_3);
            app.curvedDropDown_9.Items = {'no', 'yes'};
            app.curvedDropDown_9.ValueChangedFcn = createCallbackFcn(app, @curvedDropDown_9ValueChanged, true);
            app.curvedDropDown_9.Value = 'no';
            app.APEXxEditField_3Label = uilabel(app.Foil3Panel);
            app.APEXxEditField_3Label.Text = 'APEX x';
            app.APEXxEditField_3 = uieditfield(app.Foil3Panel, 'numeric');
            app.APEXxEditField_3.ValueChangedFcn = createCallbackFcn(app, @APEXxEditField_3ValueChanged, true);
            app.yEditField_7Label = uilabel(app.Foil3Panel);
            app.yEditField_7Label.Text = 'y';
            app.yEditField_7 = uieditfield(app.Foil3Panel, 'numeric');
            app.yEditField_7.ValueChangedFcn = createCallbackFcn(app, @yEditField_7ValueChanged, true);
            app.zEditField_7Label = uilabel(app.Foil3Panel);
            app.zEditField_7Label.Text = 'z';
            app.zEditField_7 = uieditfield(app.Foil3Panel, 'numeric');
            app.zEditField_7.ValueChangedFcn = createCallbackFcn(app, @zEditField_7ValueChanged, true);
            app.noofpartitionsDropDown_3Label = uilabel(app.Foil3Panel);
            app.noofpartitionsDropDown_3Label.Text = 'no. of partitions';
            app.noofpartitionsDropDown_3 = uidropdown(app.Foil3Panel);
            app.noofpartitionsDropDown_3.Items = {'- choose an option -', '1', '2', '3'};
            app.noofpartitionsDropDown_3.ValueChangedFcn = createCallbackFcn(app, @noofpartitionsDropDown_3ValueChanged, true);
            app.noofpartitionsDropDown_3.Value = '- choose an option -';
            app.SaveasEditFieldLabel = uilabel(app.GeometryeditUIFigure);
            app.SaveasEditFieldLabel.Text = 'Save as:';
            app.SaveasEditField = uieditfield(app.GeometryeditUIFigure, 'text');
            app.SaveButton = uibutton(app.GeometryeditUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save!';
            app.Label = uilabel(app.GeometryeditUIFigure);
            app.Label.Text = '';
            app.Foil4Panel = uipanel(app.GeometryeditUIFigure);
            app.Foil4Panel.Title = 'Foil 4';
            app.Foil4Panel.Visible = 'off';
            app.MirroredCheckBox_4 = uicheckbox(app.Foil4Panel);
            app.MirroredCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @MirroredCheckBox_4ValueChanged, true);
            app.MirroredCheckBox_4.Text = 'Mirrored';
            app.partition1Panel_4 = uipanel(app.Foil4Panel);
            app.partition1Panel_4.Title = 'partition 1';
            app.RootchordEditField_10Label = uilabel(app.partition1Panel_4);
            app.RootchordEditField_10Label.Text = 'Root chord';
            app.RootchordEditField_10 = uieditfield(app.partition1Panel_4, 'text');
            app.RootchordEditField_10.ValueChangedFcn = createCallbackFcn(app, @RootchordEditField_10ValueChanged, true);
            app.BasechordairfoilEditField_10Label = uilabel(app.partition1Panel_4);
            app.BasechordairfoilEditField_10Label.Text = 'Base chord airfoil';
            app.BasechordairfoilEditField_10 = uieditfield(app.partition1Panel_4, 'text');
            app.BasechordairfoilEditField_10.ValueChangedFcn = createCallbackFcn(app, @BasechordairfoilEditField_10ValueChanged, true);
            app.noofpanelschordwiseEditField_10Label = uilabel(app.partition1Panel_4);
            app.noofpanelschordwiseEditField_10Label.Text = 'no. of panels chord wise';
            app.noofpanelschordwiseEditField_10 = uieditfield(app.partition1Panel_4, 'text');
            app.noofpanelschordwiseEditField_10.ValueChangedFcn = createCallbackFcn(app, @noofpanelschordwiseEditField_10ValueChanged, true);
            app.dihedralEditField_10Label = uilabel(app.partition1Panel_4);
            app.dihedralEditField_10Label.Text = 'dihedral';
            app.dihedralEditField_10 = uieditfield(app.partition1Panel_4, 'text');
            app.dihedralEditField_10.ValueChangedFcn = createCallbackFcn(app, @dihedralEditField_10ValueChanged, true);
            app.pitchEditField_10Label = uilabel(app.partition1Panel_4);
            app.pitchEditField_10Label.Text = 'pitch';
            app.pitchEditField_10 = uieditfield(app.partition1Panel_4, 'text');
            app.pitchEditField_10.ValueChangedFcn = createCallbackFcn(app, @pitchEditField_10ValueChanged, true);
            app.basechordtwistEditField_10Label = uilabel(app.partition1Panel_4);
            app.basechordtwistEditField_10Label.Text = 'base chord twist';
            app.basechordtwistEditField_10 = uieditfield(app.partition1Panel_4, 'text');
            app.basechordtwistEditField_10.ValueChangedFcn = createCallbackFcn(app, @basechordtwistEditField_10ValueChanged, true);
            app.noofpanelssemispanwiseEditField_10Label = uilabel(app.partition1Panel_4);
            app.noofpanelssemispanwiseEditField_10Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_10 = uieditfield(app.partition1Panel_4, 'text');
            app.noofpanelssemispanwiseEditField_10.ValueChangedFcn = createCallbackFcn(app, @noofpanelssemispanwiseEditField_10ValueChanged, true);
            app.spanEditField_10Label = uilabel(app.partition1Panel_4);
            app.spanEditField_10Label.Text = 'span';
            app.spanEditField_10 = uieditfield(app.partition1Panel_4, 'text');
            app.spanEditField_10.ValueChangedFcn = createCallbackFcn(app, @spanEditField_10ValueChanged, true);
            app.taperEditField_10Label = uilabel(app.partition1Panel_4);
            app.taperEditField_10Label.Text = 'taper';
            app.taperEditField_10 = uieditfield(app.partition1Panel_4, 'text');
            app.taperEditField_10.ValueChangedFcn = createCallbackFcn(app, @taperEditField_10ValueChanged, true);
            app.tipchordairfoilEditField_10Label = uilabel(app.partition1Panel_4);
            app.tipchordairfoilEditField_10Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_10 = uieditfield(app.partition1Panel_4, 'text');
            app.tipchordairfoilEditField_10.ValueChangedFcn = createCallbackFcn(app, @tipchordairfoilEditField_10ValueChanged, true);
            app.sweepEditField_10Label = uilabel(app.partition1Panel_4);
            app.sweepEditField_10Label.Text = 'sweep';
            app.sweepEditField_10 = uieditfield(app.partition1Panel_4, 'text');
            app.sweepEditField_10.ValueChangedFcn = createCallbackFcn(app, @sweepEditField_10ValueChanged, true);
            app.outboardtwistEditField_10Label = uilabel(app.partition1Panel_4);
            app.outboardtwistEditField_10Label.Text = 'outboard twist';
            app.outboardtwistEditField_10 = uieditfield(app.partition1Panel_4, 'text');
            app.outboardtwistEditField_10.ValueChangedFcn = createCallbackFcn(app, @outboardtwistEditField_10ValueChanged, true);
            app.meshtypeDropDown_10Label = uilabel(app.partition1Panel_4);
            app.meshtypeDropDown_10Label.Text = 'mesh type';
            app.meshtypeDropDown_10 = uidropdown(app.partition1Panel_4);
            app.meshtypeDropDown_10.Items = {'1', '2', '3', '4', '5'};
            app.meshtypeDropDown_10.ValueChangedFcn = createCallbackFcn(app, @meshtypeDropDown_10ValueChanged, true);
            app.meshtypeDropDown_10.Value = '1';
            app.radiusofcurvatureEditField_10Label = uilabel(app.partition1Panel_4);
            app.radiusofcurvatureEditField_10Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_10 = uieditfield(app.partition1Panel_4, 'text');
            app.radiusofcurvatureEditField_10.Visible = 'off';
            app.curvedDropDown_10Label = uilabel(app.partition1Panel_4);
            app.curvedDropDown_10Label.Text = 'curved';
            app.curvedDropDown_10 = uidropdown(app.partition1Panel_4);
            app.curvedDropDown_10.Items = {'no', 'yes'};
            app.curvedDropDown_10.ValueChangedFcn = createCallbackFcn(app, @curvedDropDown_10ValueChanged, true);
            app.curvedDropDown_10.Value = 'no';
            app.partition2Panel_4 = uipanel(app.Foil4Panel);
            app.partition2Panel_4.Title = 'partition 2';
            app.partition2Panel_4.Visible = 'off';
            app.dihedralEditField_11Label = uilabel(app.partition2Panel_4);
            app.dihedralEditField_11Label.Text = 'dihedral';
            app.dihedralEditField_11 = uieditfield(app.partition2Panel_4, 'text');
            app.dihedralEditField_11.ValueChangedFcn = createCallbackFcn(app, @dihedralEditField_11ValueChanged, true);
            app.noofpanelssemispanwiseEditField_11Label = uilabel(app.partition2Panel_4);
            app.noofpanelssemispanwiseEditField_11Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_11 = uieditfield(app.partition2Panel_4, 'text');
            app.noofpanelssemispanwiseEditField_11.ValueChangedFcn = createCallbackFcn(app, @noofpanelssemispanwiseEditField_11ValueChanged, true);
            app.spanEditField_11Label = uilabel(app.partition2Panel_4);
            app.spanEditField_11Label.Text = 'span';
            app.spanEditField_11 = uieditfield(app.partition2Panel_4, 'text');
            app.spanEditField_11.ValueChangedFcn = createCallbackFcn(app, @spanEditField_11ValueChanged, true);
            app.taperEditField_11Label = uilabel(app.partition2Panel_4);
            app.taperEditField_11Label.Text = 'taper';
            app.taperEditField_11 = uieditfield(app.partition2Panel_4, 'text');
            app.taperEditField_11.ValueChangedFcn = createCallbackFcn(app, @taperEditField_11ValueChanged, true);
            app.tipchordairfoilEditField_11Label = uilabel(app.partition2Panel_4);
            app.tipchordairfoilEditField_11Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_11 = uieditfield(app.partition2Panel_4, 'text');
            app.tipchordairfoilEditField_11.ValueChangedFcn = createCallbackFcn(app, @tipchordairfoilEditField_11ValueChanged, true);
            app.sweepEditField_11Label = uilabel(app.partition2Panel_4);
            app.sweepEditField_11Label.Text = 'sweep';
            app.sweepEditField_11 = uieditfield(app.partition2Panel_4, 'text');
            app.sweepEditField_11.ValueChangedFcn = createCallbackFcn(app, @sweepEditField_11ValueChanged, true);
            app.outboardtwistEditField_11Label = uilabel(app.partition2Panel_4);
            app.outboardtwistEditField_11Label.Text = 'outboard twist';
            app.outboardtwistEditField_11 = uieditfield(app.partition2Panel_4, 'text');
            app.outboardtwistEditField_11.ValueChangedFcn = createCallbackFcn(app, @outboardtwistEditField_11ValueChanged, true);
            app.meshtypeDropDown_11Label = uilabel(app.partition2Panel_4);
            app.meshtypeDropDown_11Label.Text = 'mesh type';
            app.meshtypeDropDown_11 = uidropdown(app.partition2Panel_4);
            app.meshtypeDropDown_11.Items = {'1', '2', '3', '4', '5'};
            app.meshtypeDropDown_11.ValueChangedFcn = createCallbackFcn(app, @meshtypeDropDown_11ValueChanged, true);
            app.meshtypeDropDown_11.Value = '1';
            app.radiusofcurvatureEditField_11Label = uilabel(app.partition2Panel_4);
            app.radiusofcurvatureEditField_11Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_11 = uieditfield(app.partition2Panel_4, 'text');
            app.radiusofcurvatureEditField_11.ValueChangedFcn = createCallbackFcn(app, @radiusofcurvatureEditField_11ValueChanged, true);
            app.radiusofcurvatureEditField_11.Visible = 'off';
            app.curvedDropDown_11Label = uilabel(app.partition2Panel_4);
            app.curvedDropDown_11Label.Text = 'curved';
            app.curvedDropDown_11 = uidropdown(app.partition2Panel_4);
            app.curvedDropDown_11.Items = {'no', 'yes'};
            app.curvedDropDown_11.ValueChangedFcn = createCallbackFcn(app, @curvedDropDown_11ValueChanged, true);
            app.curvedDropDown_11.Value = 'no';
            app.partition3Panel_4 = uipanel(app.Foil4Panel);
            app.partition3Panel_4.Title = 'partition 3';
            app.partition3Panel_4.Visible = 'off';
            app.dihedralEditField_12Label = uilabel(app.partition3Panel_4);
            app.dihedralEditField_12Label.Text = 'dihedral';
            app.dihedralEditField_12 = uieditfield(app.partition3Panel_4, 'text');
            app.dihedralEditField_12.ValueChangedFcn = createCallbackFcn(app, @dihedralEditField_12ValueChanged, true);
            app.noofpanelssemispanwiseEditField_12Label = uilabel(app.partition3Panel_4);
            app.noofpanelssemispanwiseEditField_12Label.Text = 'no. of panels semi-span wise';
            app.noofpanelssemispanwiseEditField_12 = uieditfield(app.partition3Panel_4, 'text');
            app.noofpanelssemispanwiseEditField_12.ValueChangedFcn = createCallbackFcn(app, @noofpanelssemispanwiseEditField_12ValueChanged, true);
            app.spanEditField_12Label = uilabel(app.partition3Panel_4);
            app.spanEditField_12Label.Text = 'span';
            app.spanEditField_12 = uieditfield(app.partition3Panel_4, 'text');
            app.spanEditField_12.ValueChangedFcn = createCallbackFcn(app, @spanEditField_12ValueChanged, true);
            app.taperEditField_12Label = uilabel(app.partition3Panel_4);
            app.taperEditField_12Label.Text = 'taper';
            app.taperEditField_12 = uieditfield(app.partition3Panel_4, 'text');
            app.taperEditField_12.ValueChangedFcn = createCallbackFcn(app, @taperEditField_12ValueChanged, true);
            app.tipchordairfoilEditField_12Label = uilabel(app.partition3Panel_4);
            app.tipchordairfoilEditField_12Label.Text = 'tip chord airfoil';
            app.tipchordairfoilEditField_12 = uieditfield(app.partition3Panel_4, 'text');
            app.tipchordairfoilEditField_12.ValueChangedFcn = createCallbackFcn(app, @tipchordairfoilEditField_12ValueChanged, true);
            app.sweepEditField_12Label = uilabel(app.partition3Panel_4);
            app.sweepEditField_12Label.Text = 'sweep';
            app.sweepEditField_12 = uieditfield(app.partition3Panel_4, 'text');
            app.sweepEditField_12.ValueChangedFcn = createCallbackFcn(app, @sweepEditField_12ValueChanged, true);
            app.outboardtwistEditField_12Label = uilabel(app.partition3Panel_4);
            app.outboardtwistEditField_12Label.Text = 'outboard twist';
            app.outboardtwistEditField_12 = uieditfield(app.partition3Panel_4, 'text');
            app.outboardtwistEditField_12.ValueChangedFcn = createCallbackFcn(app, @outboardtwistEditField_12ValueChanged, true);
            app.meshtypeDropDown_12Label = uilabel(app.partition3Panel_4);
            app.meshtypeDropDown_12Label.Text = 'mesh type';
            app.meshtypeDropDown_12 = uidropdown(app.partition3Panel_4);
            app.meshtypeDropDown_12.Items = {'1', '2', '3', '4', '5', '- make a choice -'};
            app.meshtypeDropDown_12.ValueChangedFcn = createCallbackFcn(app, @meshtypeDropDown_12ValueChanged, true);
            app.meshtypeDropDown_12.Value = '- make a choice -';
            app.radiusofcurvatureEditField_12Label = uilabel(app.partition3Panel_4);
            app.radiusofcurvatureEditField_12Label.Text = 'radius of curvature';
            app.radiusofcurvatureEditField_12 = uieditfield(app.partition3Panel_4, 'text');
            app.radiusofcurvatureEditField_12.ValueChangedFcn = createCallbackFcn(app, @radiusofcurvatureEditField_12ValueChanged, true);
            app.curvedDropDown_12Label = uilabel(app.partition3Panel_4);
            app.curvedDropDown_12Label.Text = 'curved';
            app.curvedDropDown_12 = uidropdown(app.partition3Panel_4);
            app.curvedDropDown_12.Items = {'no', 'yes'};
            app.curvedDropDown_12.ValueChangedFcn = createCallbackFcn(app, @curvedDropDown_12ValueChanged, true);
            app.curvedDropDown_12.Value = 'no';
            app.APEXxEditField_4Label = uilabel(app.Foil4Panel);
            app.APEXxEditField_4Label.Text = 'APEX x';
            app.APEXxEditField_4 = uieditfield(app.Foil4Panel, 'numeric');
            app.APEXxEditField_4.ValueChangedFcn = createCallbackFcn(app, @APEXxEditField_4ValueChanged, true);
            app.APEXxEditField_4.Value = 999;
            app.yEditField_8Label = uilabel(app.Foil4Panel);
            app.yEditField_8Label.Text = 'y';
            app.yEditField_8 = uieditfield(app.Foil4Panel, 'numeric');
            app.yEditField_8.ValueChangedFcn = createCallbackFcn(app, @yEditField_8ValueChanged, true);
            app.yEditField_8.Value = 999;
            app.zEditField_8Label = uilabel(app.Foil4Panel);
            app.zEditField_8Label.Text = 'z';
            app.zEditField_8 = uieditfield(app.Foil4Panel, 'numeric');
            app.zEditField_8.ValueChangedFcn = createCallbackFcn(app, @zEditField_8ValueChanged, true);
            app.zEditField_8.Value = 999;
            app.noofpartitionsDropDown_4Label = uilabel(app.Foil4Panel);
            app.noofpartitionsDropDown_4Label.Text = 'no. of partitions';
            app.noofpartitionsDropDown_4 = uidropdown(app.Foil4Panel);
            app.noofpartitionsDropDown_4.Items = {'- choose an option -', '1', '2', '3'};
            app.noofpartitionsDropDown_4.ValueChangedFcn = createCallbackFcn(app, @noofpartitionsDropDown_4ValueChanged, true);
            app.noofpartitionsDropDown_4.Value = '- choose an option -';
            app.kgLabel = uilabel(app.GeometryeditUIFigure);
            app.kgLabel.Text = '[kg]';
            app.kgm2Label = uilabel(app.GeometryeditUIFigure);
            app.kgm2Label.Text = '[kg m^2]';
            app.mLabel = uilabel(app.GeometryeditUIFigure);
            app.mLabel.Text = '[m]';
            app.mLabel_2 = uilabel(app.GeometryeditUIFigure);
            app.mLabel_2.Text = '[m]';
        end
    end

    methods (Access = public)

        % Construct app
        function app = geometry_edit3(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeometryeditUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeometryeditUIFigure)
        end
    end
end